classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaterChannelControl             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ControlTab                      matlab.ui.container.Tab
        PDControllerGainsPanel          matlab.ui.container.Panel
        RollControlLabel                matlab.ui.control.Label
        PitchControlLabel               matlab.ui.control.Label
        ProportionalGainEditFieldLabel  matlab.ui.control.Label
        ProportionalGainEditFieldRoll   matlab.ui.control.NumericEditField
        TimeConstantsLabel              matlab.ui.control.Label
        TimeConstantEditFieldRoll       matlab.ui.control.NumericEditField
        DerivativeGainEditFieldLabel    matlab.ui.control.Label
        DerivativeGainEditFieldRoll     matlab.ui.control.NumericEditField
        AltitudeControlLabel            matlab.ui.control.Label
        TimeConstantsLabel_2            matlab.ui.control.Label
        TimeConstantEditFieldPitch      matlab.ui.control.NumericEditField
        DerivativeGainEditField_2Label  matlab.ui.control.Label
        DerivativeGainEditFieldPitch    matlab.ui.control.NumericEditField
        ProportionalGainEditField_2Label  matlab.ui.control.Label
        ProportionalGainEditFieldPitch  matlab.ui.control.NumericEditField
        TimeConstantsLabel_3            matlab.ui.control.Label
        TimeConstantEditFieldAltitude   matlab.ui.control.NumericEditField
        DerivativeGainEditField_3Label  matlab.ui.control.Label
        DerivativeGainEditFieldAltitude  matlab.ui.control.NumericEditField
        ProportionalGainEditField_3Label  matlab.ui.control.Label
        ProportionalGainEditFieldAltitude  matlab.ui.control.NumericEditField
        MotorControlPanel               matlab.ui.container.Panel
        AllSliderLabel                  matlab.ui.control.Label
        AllSlider                       matlab.ui.control.Slider
        Motor3SliderLabel               matlab.ui.control.Label
        Motor3Slider                    matlab.ui.control.Slider
        Motor1SliderLabel               matlab.ui.control.Label
        Motor1Slider                    matlab.ui.control.Slider
        Motor2SliderLabel               matlab.ui.control.Label
        Motor2Slider                    matlab.ui.control.Slider
        MotorConfigurationPanel         matlab.ui.container.Panel
        MaxMotorVoltageVEditFieldLabel  matlab.ui.control.Label
        MaxMotorVoltageVEditField       matlab.ui.control.NumericEditField
        InitialCenterofMassPositionPanel  matlab.ui.container.Panel
        CoMPositioncmLabel              matlab.ui.control.Label
        CoMPositionEditFieldX           matlab.ui.control.NumericEditField
        CoMPositionEditFieldY           matlab.ui.control.NumericEditField
        CoMPositionEditFieldZ           matlab.ui.control.NumericEditField
        OperationModePanel              matlab.ui.container.Panel
        OperationModeSwitch             matlab.ui.control.ToggleSwitch
        CameraControlTab                matlab.ui.container.Tab
        SideCameraPanel                 matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldSideRow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldSideCol  matlab.ui.control.NumericEditField
        ROISizepxLabel                  matlab.ui.control.Label
        ROISizeEditFieldSideRow         matlab.ui.control.NumericEditField
        ROISizeEditFieldSideCol         matlab.ui.control.NumericEditField
        ImageDimensionspxLabel          matlab.ui.control.Label
        ImageDimensionsEditFieldSideRow  matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldSideCol  matlab.ui.control.NumericEditField
        CrosshairWidthpxLabel           matlab.ui.control.Label
        CrosshairWidthEditFieldSide     matlab.ui.control.NumericEditField
        VideoDecimationEditFieldLabel   matlab.ui.control.Label
        VideoDecimationEditFieldSide    matlab.ui.control.NumericEditField
        ImageWidthpxLabel               matlab.ui.control.Label
        ImageWidthEditFieldSide         matlab.ui.control.NumericEditField
        SwitchSide                      matlab.ui.control.Switch
        BottomCameraPanel               matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel_2  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldBottomARow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldBottomACol  matlab.ui.control.NumericEditField
        ROISizepxLabel_3                matlab.ui.control.Label
        ROISizeEditFieldBottomARow      matlab.ui.control.NumericEditField
        ROISizeEditFieldBottomACol      matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldBottomCol  matlab.ui.control.NumericEditField
        CrosshairWidthpxLabel_3         matlab.ui.control.Label
        CrosshairWidthEditFieldBottomA  matlab.ui.control.NumericEditField
        VideoDecimationEditField_2Label  matlab.ui.control.Label
        VideoDecimationEditFieldBottom  matlab.ui.control.NumericEditField
        ImageWidthpxLabel_3             matlab.ui.control.Label
        ImageWidthEditFieldBottom       matlab.ui.control.NumericEditField
        BottomCameraALabel              matlab.ui.control.Label
        BottomCameraBLabel              matlab.ui.control.Label
        ROIHoldPositionpxEditFieldBottomBCol  matlab.ui.control.NumericEditField
        ROISizeEditFieldBottomBCol      matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldLabel_4  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldBottomBRow  matlab.ui.control.NumericEditField
        ROISizepxLabel_4                matlab.ui.control.Label
        ROISizeEditFieldBottomBRow      matlab.ui.control.NumericEditField
        CrosshairWidthpxLabel_4         matlab.ui.control.Label
        CrosshairWidthEditFieldBottomB  matlab.ui.control.NumericEditField
        BothCamerasLabel                matlab.ui.control.Label
        ImageDimensionspxLabel_3        matlab.ui.control.Label
        ImageDimensionsEditFieldBottomRow  matlab.ui.control.NumericEditField
        SwitchBottomA                   matlab.ui.control.Switch
        SwitchBottomB                   matlab.ui.control.Switch
        SlantCameraPanel                matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel_3  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldSlantRow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldSlantCol  matlab.ui.control.NumericEditField
        ROISizepxLabel_2                matlab.ui.control.Label
        ROISizeEditFieldSlantRow        matlab.ui.control.NumericEditField
        ROISizeEditFieldSlantCol        matlab.ui.control.NumericEditField
        ImageDimensionspxLabel_2        matlab.ui.control.Label
        ImageDimensionsEditFieldSlantRow  matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldSlantCol  matlab.ui.control.NumericEditField
        CrosshairWidthpxLabel_2         matlab.ui.control.Label
        CrosshairWidthEditFieldSlant    matlab.ui.control.NumericEditField
        VideoDecimationEditField_3Label  matlab.ui.control.Label
        VideoDecimationEditFieldSlant   matlab.ui.control.NumericEditField
        ImageWidthpxLabel_2             matlab.ui.control.Label
        ImageWidthEditFieldSlant        matlab.ui.control.NumericEditField
        SwitchSlant                     matlab.ui.control.Switch
        CameraSelectionPanel            matlab.ui.container.Panel
        BottomCameraSwitchLabel         matlab.ui.control.Label
        BottomCameraSwitch              matlab.ui.control.ToggleSwitch
        CameraSettingsTab               matlab.ui.container.Tab
        DotPropertiesPanel              matlab.ui.container.Panel
        SideDotSeparationDistancecmEditFieldLabel  matlab.ui.control.Label
        SideDotSeparationDistancecmEditField  matlab.ui.control.NumericEditField
        SideDotPositionVectorcmLabel    matlab.ui.control.Label
        SideDotPositionVectorEditFieldX  matlab.ui.control.NumericEditField
        BottomADotSeparationDistancecmEditFieldLabel  matlab.ui.control.Label
        BottomADotSeparationDistancecmEditField  matlab.ui.control.NumericEditField
        BottomADotPositionVectorcmLabel  matlab.ui.control.Label
        BottomADotPositionVectorEditFieldX  matlab.ui.control.NumericEditField
        BottomBDotSeparationDistancecmEditFieldLabel  matlab.ui.control.Label
        BottomBDotSeparationDistancecmEditField  matlab.ui.control.NumericEditField
        BottomBDotPositionVectorcmLabel  matlab.ui.control.Label
        BottomBDotPositionValueEditFieldX  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldY  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldZ  matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldY  matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldZ  matlab.ui.control.NumericEditField
        BottomBDotPositionVectorEditFieldY  matlab.ui.control.NumericEditField
        BottomBDotPositionVectorEditFieldZ  matlab.ui.control.NumericEditField
        CameraSpecificationsPanel       matlab.ui.container.Panel
        CameraFOVdegLabel               matlab.ui.control.Label
        CameraFOVEditFieldHor           matlab.ui.control.NumericEditField
        CameraFOVEditFieldVer           matlab.ui.control.NumericEditField
        ImageErrorSettingsPanel         matlab.ui.container.Panel
        ThresholdLabel                  matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        DebounceTimesEditFieldLabel     matlab.ui.control.Label
        DebounceTimesEditField          matlab.ui.control.NumericEditField
        CameraOrientationPanel          matlab.ui.container.Panel
        SideCameraLabel2                matlab.ui.control.Label
        EulerAngledegLabel              matlab.ui.control.Label
        EulerAngleEditFieldSideRoll     matlab.ui.control.NumericEditField
        BottomCameraLabel               matlab.ui.control.Label
        EulerAngledegLabel_2            matlab.ui.control.Label
        EulerAngleEditFieldBottomRoll   matlab.ui.control.NumericEditField
        EulerAngleEditFieldBottomPitch  matlab.ui.control.NumericEditField
        EulerAngleEditFieldBottomYaw    matlab.ui.control.NumericEditField
        PositioncmLabel_2               matlab.ui.control.Label
        PositionEditFieldBottomX        matlab.ui.control.NumericEditField
        SlantCameraLabel2               matlab.ui.control.Label
        EulerAngledegLabel_3            matlab.ui.control.Label
        EulerAngleEditFieldSlantRoll    matlab.ui.control.NumericEditField
        PositioncmLabel_3               matlab.ui.control.Label
        PositionEditFieldSlantX         matlab.ui.control.NumericEditField
        PositionEditFieldSlantY         matlab.ui.control.NumericEditField
        EulerAngleEditFieldSlantPitch   matlab.ui.control.NumericEditField
        EulerAngleEditFieldSlantYaw     matlab.ui.control.NumericEditField
        PositionEditFieldSlantZ         matlab.ui.control.NumericEditField
        PositioncmLabel                 matlab.ui.control.Label
        PositionEditFieldSideX          matlab.ui.control.NumericEditField
        PositionEditFieldSideZ          matlab.ui.control.NumericEditField
        PositionEditFieldBottomY        matlab.ui.control.NumericEditField
        PositionEditFieldBottomZ        matlab.ui.control.NumericEditField
        EulerAngleEditFieldSidePitch    matlab.ui.control.NumericEditField
        PositionEditFieldSideY          matlab.ui.control.NumericEditField
        EulerAngleEditFieldSideYaw      matlab.ui.control.NumericEditField
        CommunicationTab                matlab.ui.container.Tab
        UDPPTPConfigurationPanel        matlab.ui.container.Panel
        PTPOffsetEditFieldLabel         matlab.ui.control.Label
        PTPOffsetEditField              matlab.ui.control.NumericEditField
        SyncIntervalsEditFieldLabel     matlab.ui.control.Label
        SyncIntervalsEditField          matlab.ui.control.NumericEditField
        Machine3Panel                   matlab.ui.container.Panel
        PortNumberEditFieldLabel        matlab.ui.control.Label
        PortNumberEditFieldMachine3     matlab.ui.control.NumericEditField
        MinDelaysLabel                  matlab.ui.control.Label
        MinDelayEditFieldMachine3       matlab.ui.control.NumericEditField
        IPAddressEditFieldLabel         matlab.ui.control.Label
        IPAddressEditFieldMachine3      matlab.ui.control.EditField
        Machine4Panel                   matlab.ui.container.Panel
        PortNumberEditField_2Label      matlab.ui.control.Label
        PortNumberEditFieldMachine4     matlab.ui.control.NumericEditField
        MinDelaysLabel_2                matlab.ui.control.Label
        MinDelayEditFieldMachine4       matlab.ui.control.NumericEditField
        IPAddressEditField_2Label       matlab.ui.control.Label
        IPAddressEditFieldMachine4      matlab.ui.control.EditField
        Machine5Panel                   matlab.ui.container.Panel
        PortNumberEditField_3Label      matlab.ui.control.Label
        PortNumberEditFieldMachine5     matlab.ui.control.NumericEditField
        MinDelaysLabel_3                matlab.ui.control.Label
        MinDelayEditFieldMachine5       matlab.ui.control.NumericEditField
        IPAddressEditField_3Label       matlab.ui.control.Label
        IPAddressEditFieldMachine5      matlab.ui.control.EditField
        Machine2Panel                   matlab.ui.container.Panel
        AnnounceIntervalsEditFieldLabel  matlab.ui.control.Label
        AnnounceIntervalsEditField      matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dict = Simulink.data.dictionary.open('paramsDataDictionary.sldd');
            sect = getSection(dict,'Design Data');
            
            % Roll Controller Gains
            if app.ProportionalGainEditFieldRoll.Value == 0
                val = getValue(getEntry(sect,'rollCtrlkp_Prad'));
                app.ProportionalGainEditFieldRoll.Value = val.Value;
                app.ProportionalGainEditFieldRoll.Limits = findLimits('rollCtrlkp_Prad');
            end
            if app.DerivativeGainEditFieldRoll.Value == 0
                val = getValue(getEntry(sect,'rollCtrlkd_sPrad'));
                app.DerivativeGainEditFieldRoll.Value = val.Value;
                app.DerivativeGainEditFieldRoll.Limits = findLimits('rollCtrlkd_sPrad');
            end
            if app.TimeConstantEditFieldRoll.Value == 0
                val = getValue(getEntry(sect,'rollCtrltau_s'));
                app.TimeConstantEditFieldRoll.Value = val.Value;
                app.TimeConstantEditFieldRoll.Limits = findLimits('rollCtrltau_s');
            end
            
            % Pitch Controller Gains
            if app.ProportionalGainEditFieldPitch.Value == 0
                val = getValue(getEntry(sect,'pitchCtrlkp_Prad'));
                app.ProportionalGainEditFieldPitch.Value = val.Value;
                app.ProportionalGainEditFieldPitch.Limits = findLimits('pitchCtrlkp_Prad');
            end
            if app.DerivativeGainEditFieldPitch.Value == 0
                val = getValue(getEntry(sect,'pitchCtrlkd_sPrad'));
                app.DerivativeGainEditFieldPitch.Value = val.Value;
                app.DerivativeGainEditFieldPitch.Limits = findLimits('pitchCtrlkd_sPrad');
            end
            if app.TimeConstantEditFieldPitch.Value == 0
                val = getValue(getEntry(sect,'pitchCtrltau_s'));
                app.TimeConstantEditFieldPitch.Value = val.Value;
                app.TimeConstantEditFieldPitch.Limits = findLimits('pitchCtrltau_s');
            end
            
            % Altitude Controller Gains
            if app.ProportionalGainEditFieldAltitude.Value == 0
                val = getValue(getEntry(sect,'altCtrlkp_Prad'));
                app.ProportionalGainEditFieldAltitude.Value = val.Value;
                app.ProportionalGainEditFieldAltitude.Limits = findLimits('altCtrlkp_Prad');
            end
            if app.DerivativeGainEditFieldAltitude.Value == 0
                val = getValue(getEntry(sect,'altCtrlkd_sPrad'));
                app.DerivativeGainEditFieldAltitude.Value = val.Value;
                app.DerivativeGainEditFieldAltitude.Limits = findLimits('altCtrlkd_sPrad');
            end
            if app.TimeConstantEditFieldAltitude.Value == 0
                val = getValue(getEntry(sect,'altCtrltau_s'));
                app.TimeConstantEditFieldAltitude.Value = val.Value;
                app.TimeConstantEditFieldAltitude.Limits = findLimits('altCtrltau_s');
            end
            
            % Max Motor Voltage
            if app.MaxMotorVoltageVEditField.Value == 0
                val = getValue(getEntry(sect,'maxOutVltg_vlt'));
                app.MaxMotorVoltageVEditField.Value = val.Value;
                app.MaxMotorVoltageVEditField.Limits = findLimits('maxOutVltg_vlt');
            end
            
            % Initial COM Position
            if app.CoMPositionEditFieldX.Value == 0
                val = getValue(getEntry(sect,'initCoMPosVec_cm'));
                app.CoMPositionEditFieldX.Value = val.Value(1);
                app.CoMPositionEditFieldX.Limits = findLimits('initCoMPosVec_cm');
            end
            if app.CoMPositionEditFieldY.Value == 0
                val = getValue(getEntry(sect,'initCoMPosVec_cm'));
                app.CoMPositionEditFieldY.Value = val.Value(2);
                app.CoMPositionEditFieldY.Limits = findLimits('initCoMPosVec_cm');
            end
            if app.CoMPositionEditFieldZ.Value == 0
                val = getValue(getEntry(sect,'initCoMPosVec_cm'));
                app.CoMPositionEditFieldZ.Value = val.Value(3);
                app.CoMPositionEditFieldZ.Limits = findLimits('initCoMPosVec_cm');
            end
            
            % Camera FOV
            if app.CameraFOVEditFieldHor.Value == 0
                val = getValue(getEntry(sect,'cameraFOV_deg'));
                app.CameraFOVEditFieldHor.Value = val.Value(1);
                app.CameraFOVEditFieldHor.Limits = findLimits('cameraFOV_deg');
            end
            if app.CameraFOVEditFieldVer.Value == 0
                val = getValue(getEntry(sect,'cameraFOV_deg'));
                app.CameraFOVEditFieldVer.Value = val.Value(2);
                app.CameraFOVEditFieldVer.Limits = findLimits('cameraFOV_deg');
            end
            
            % Side Camera
            if app.PositionEditFieldSideX.Value == 0
                val = getValue(getEntry(sect,'sideCamPosVec_cm'));
                app.PositionEditFieldSideX.Value = val.Value(1);
                app.PositionEditFieldSideX.Limits = findLimits('sideCamPosVec_cm');
            end
            if app.PositionEditFieldSideY.Value == 0
                val = getValue(getEntry(sect,'sideCamPosVec_cm'));
                app.PositionEditFieldSideY.Value = val.Value(2);
                app.PositionEditFieldSideY.Limits = findLimits('sideCamPosVec_cm');
            end
            if app.PositionEditFieldSideZ.Value == 0
                val = getValue(getEntry(sect,'sideCamPosVec_cm'));
                app.PositionEditFieldSideZ.Value = val.Value(3);
                app.PositionEditFieldSideZ.Limits = findLimits('sideCamPosVec_cm');
            end
            if app.EulerAngleEditFieldSideRoll.Value == 0
                val = getValue(getEntry(sect,'sideCamEulAng_deg'));
                app.EulerAngleEditFieldSideRoll.Value = val.Value(1);
                app.EulerAngleEditFieldSideRoll.Limits = findLimits('sideCamEulAng_deg');
            end
            if app.EulerAngleEditFieldSidePitch.Value == 0
                val = getValue(getEntry(sect,'sideCamEulAng_deg'));
                app.EulerAngleEditFieldSidePitch.Value = val.Value(2);
                app.EulerAngleEditFieldSidePitch.Limits = findLimits('sideCamEulAng_deg');
            end
            if app.EulerAngleEditFieldSideYaw.Value == 0
                val = getValue(getEntry(sect,'sideCamEulAng_deg'));
                app.EulerAngleEditFieldSideYaw.Value = val.Value(3);
                app.EulerAngleEditFieldSideYaw.Limits = findLimits('sideCamEulAng_deg');
            end
            
            % Bottom Camera
            if app.PositionEditFieldBottomX.Value == 0
                val = getValue(getEntry(sect,'botCamPosVec_cm'));
                app.PositionEditFieldBottomX.Value = val.Value(1);
                app.PositionEditFieldBottomX.Limits = findLimits('botCamPosVec_cm');
            end
            if app.PositionEditFieldBottomY.Value == 0
                val = getValue(getEntry(sect,'botCamPosVec_cm'));
                app.PositionEditFieldBottomY.Value = val.Value(2);
                app.PositionEditFieldBottomY.Limits = findLimits('botCamPosVec_cm');
            end
            if app.PositionEditFieldBottomZ.Value == 0
                val = getValue(getEntry(sect,'botCamPosVec_cm'));
                app.PositionEditFieldBottomZ.Value = val.Value(3);
                app.PositionEditFieldBottomZ.Limits = findLimits('botCamPosVec_cm');
            end
            if app.EulerAngleEditFieldBottomRoll.Value == 0
                val = getValue(getEntry(sect,'botCamEulAng_deg'));
                app.EulerAngleEditFieldBottomRoll.Value = val.Value(1);
                app.EulerAngleEditFieldBottomRoll.Limits = findLimits('botCamEulAng_deg');
            end
            if app.EulerAngleEditFieldBottomPitch.Value == 0
                val = getValue(getEntry(sect,'botCamEulAng_deg'));
                app.EulerAngleEditFieldBottomPitch.Value = val.Value(2);
                app.EulerAngleEditFieldBottomPitch.Limits = findLimits('botCamEulAng_deg');
            end
            if app.EulerAngleEditFieldBottomYaw.Value == 0
                val = getValue(getEntry(sect,'botCamEulAng_deg'));
                app.EulerAngleEditFieldBottomYaw.Value = val.Value(3);
                app.EulerAngleEditFieldBottomYaw.Limits = findLimits('botCamEulAng_deg');
            end
            
            % Slant Camera
            if app.PositionEditFieldSlantX.Value == 0
                val = getValue(getEntry(sect,'slntCamPosVec_cm'));
                app.PositionEditFieldSlantX.Value = val.Value(1);
                app.PositionEditFieldSlantX.Limits = findLimits('slntCamPosVec_cm');
            end
            if app.PositionEditFieldSlantY.Value == 0
                val = getValue(getEntry(sect,'slntCamPosVec_cm'));
                app.PositionEditFieldSlantY.Value = val.Value(2);
                app.PositionEditFieldSlantY.Limits = findLimits('slntCamPosVec_cm');
            end
            if app.PositionEditFieldSlantZ.Value == 0
                val = getValue(getEntry(sect,'slntCamPosVec_cm'));
                app.PositionEditFieldSlantZ.Value = val.Value(3);
                 app.PositionEditFieldSlantZ.Limits = findLimits('slntCamPosVec_cm');
            end
            if app.EulerAngleEditFieldSlantRoll.Value == 0
                val = getValue(getEntry(sect,'slntCamEulAng_deg'));
                app.EulerAngleEditFieldSlantRoll.Value = val.Value(1);
                app.EulerAngleEditFieldSlantRoll.Limits = findLimits('slntCamEulAng_deg');
            end
            if app.EulerAngleEditFieldSlantPitch.Value == 0
                val = getValue(getEntry(sect,'slntCamEulAng_deg'));
                app.EulerAngleEditFieldSlantPitch.Value = val.Value(2);
                app.EulerAngleEditFieldSlantPitch.Limits = findLimits('slntCamEulAng_deg');
            end
            if app.EulerAngleEditFieldSlantYaw.Value == 0
                val = getValue(getEntry(sect,'slntCamEulAng_deg'));
                app.EulerAngleEditFieldSlantYaw.Value = val.Value(3);
                app.EulerAngleEditFieldSlantYaw.Limits = findLimits('slntCamEulAng_deg');
            end
            
            % Dot Separation
            if app.SideDotSeparationDistancecmEditField.Value == 0
                val = getValue(getEntry(sect,'sideDotSepDist_cm'));
                app.SideDotSeparationDistancecmEditField.Value = val.Value;
                app.SideDotSeparationDistancecmEditField.Limits = findLimits('sideDotSepDist_cm');
            end
            if app.SideDotPositionVectorEditFieldX.Value == 0
                val = getValue(getEntry(sect,'sideDotPosVec_cm'));
                app.SideDotPositionVectorEditFieldX.Value = val.Value(1);
                app.SideDotPositionVectorEditFieldX.Limits = findLimits('sideDotPosVec_cm');
            end
            if app.SideDotPositionVectorEditFieldY.Value == 0
                val = getValue(getEntry(sect,'sideDotPosVec_cm'));
                app.SideDotPositionVectorEditFieldY.Value = val.Value(2);
                app.SideDotPositionVectorEditFieldY.Limits = findLimits('sideDotPosVec_cm');
            end
            if app.SideDotPositionVectorEditFieldZ.Value == 0
                val = getValue(getEntry(sect,'sideDotPosVec_cm'));
                app.SideDotPositionVectorEditFieldZ.Value = val.Value(3);
                app.SideDotPositionVectorEditFieldZ.Limits = findLimits('sideDotPosVec_cm');
            end
            if app.BottomADotSeparationDistancecmEditField.Value == 0
                val = getValue(getEntry(sect,'botADotSepDist_cm'));
                app.BottomADotSeparationDistancecmEditField.Value = val.Value;
                app.BottomADotSeparationDistancecmEditField.Limits = findLimits('botADotSepDist_cm');
            end
            if app.BottomADotPositionVectorEditFieldX.Value == 0
                val = getValue(getEntry(sect,'botADotPosVec_cm'));
                app.BottomADotPositionVectorEditFieldX.Value = val.Value(1);
                app.BottomADotPositionVectorEditFieldX.Limits = findLimits('botADotPosVec_cm');
            end
            if app.BottomADotPositionVectorEditFieldY.Value == 0
                val = getValue(getEntry(sect,'botADotPosVec_cm'));
                app.BottomADotPositionVectorEditFieldY.Value = val.Value(2);
                app.BottomADotPositionVectorEditFieldY.Limits = findLimits('botADotPosVec_cm');
            end
            if app.BottomADotPositionVectorEditFieldZ.Value == 0
                val = getValue(getEntry(sect,'botADotPosVec_cm'));
                app.BottomADotPositionVectorEditFieldZ.Value = val.Value(3);
                app.BottomADotPositionVectorEditFieldZ.Limits = findLimits('botADotPosVec_cm');
            end
            if app.BottomBDotSeparationDistancecmEditField.Value == 0
                val = getValue(getEntry(sect,'botBDotSepDist_cm'));
                app.BottomBDotSeparationDistancecmEditField.Value = val.Value;
                app.BottomBDotSeparationDistancecmEditField.Limits = findLimits('botBDotSepDist_cm');
            end
            if app.BottomBDotPositionValueEditFieldX.Value == 0
                val = getValue(getEntry(sect,'botBDotPosVec_cm'));
                app.BottomBDotPositionValueEditFieldX.Value = val.Value(1);
                app.BottomBDotPositionValueEditFieldX.Limits = findLimits('botBDotPosVec_cm');
            end
            if app.BottomBDotPositionVectorEditFieldY.Value == 0
                val = getValue(getEntry(sect,'botBDotPosVec_cm'));
                app.BottomBDotPositionVectorEditFieldY.Value = val.Value(2);
                app.BottomBDotPositionVectorEditFieldY.Limits = findLimits('botBDotPosVec_cm');
            end
            if app.BottomBDotPositionVectorEditFieldZ.Value == 0
                val = getValue(getEntry(sect,'botBDotPosVec_cm'));
                app.BottomBDotPositionVectorEditFieldZ.Value = val.Value(3);
                app.BottomBDotPositionVectorEditFieldZ.Limits = findLimits('botBDotPosVec_cm');
            end
            
            % Image Error
            if app.ThresholdEditField.Value == 0
                val = getValue(getEntry(sect,'imagProcErrThrsh_na'));
                app.ThresholdEditField.Value = val.Value;
                app.ThresholdEditField.Limits = findLimits('imagProcErrThrsh_na');
            end
            if app.DebounceTimesEditField.Value == 0
                val = getValue(getEntry(sect,'imagProcErrDbncTm_s'));
                app.DebounceTimesEditField.Value = val.Value;
                app.DebounceTimesEditField.Limits = findLimits('imagProcErrDbncTm_s');
            end
            
            % Image Side Camera
            if app.ROIHoldPositionpxEditFieldSideRow.Value == 0
                val = getValue(getEntry(sect,'roiSideHldPos_px'));
                app.ROIHoldPositionpxEditFieldSideRow.Value = val.Value(1);
                app.ROIHoldPositionpxEditFieldSideRow.Limits = findLimits('roiSideHldPos_px');
            end
            if app.ROIHoldPositionpxEditFieldSideCol.Value == 0
                val = getValue(getEntry(sect,'roiSideHldPos_px'));
                app.ROIHoldPositionpxEditFieldSideCol.Value = val.Value(2);
                app.ROIHoldPositionpxEditFieldSideCol.Limits = findLimits('roiSideHldPos_px');
            end
            if app.ROISizeEditFieldSideRow.Value == 0
                val = getValue(getEntry(sect,'roiSideSize_px'));
                app.ROISizeEditFieldSideRow.Value = val.Value(1);
                app.ROISizeEditFieldSideRow.Limits = findLimits('roiSideSize_px');
            end
            if app.ROISizeEditFieldSideCol.Value == 0
                val = getValue(getEntry(sect,'roiSideSize_px'));
                app.ROISizeEditFieldSideCol.Value = val.Value(2);
                app.ROISizeEditFieldSideCol.Limits = findLimits('roiSideSize_px');
            end
            if app.ImageDimensionsEditFieldSideRow.Value == 0
                val = getValue(getEntry(sect,'sideImageSize_px'));
                app.ImageDimensionsEditFieldSideRow.Value = val.Value(1);
                app.ImageDimensionsEditFieldSideRow.Limits = findLimits('sideImageSize_px');
            end
            if app.ImageDimensionsEditFieldSideCol.Value == 0
                val = getValue(getEntry(sect,'sideImageSize_px'));
                app.ImageDimensionsEditFieldSideCol.Value = val.Value(2);
                app.ImageDimensionsEditFieldSideCol.Limits = findLimits('sideImageSize_px');
            end
            if app.CrosshairWidthEditFieldSide.Value == 0
                val = getValue(getEntry(sect,'sideCrsHrWdth_px'));
                app.CrosshairWidthEditFieldSide.Value = val.Value;
                app.CrosshairWidthEditFieldSide.Limits = findLimits('sideCrsHrWdth_px');
            end
            if app.VideoDecimationEditFieldSide.Value == 0
                val = getValue(getEntry(sect,'sideVidStrmDec_na'));
                app.VideoDecimationEditFieldSide.Value = val.Value;
                app.VideoDecimationEditFieldSide.Limits = findLimits('sideVidStrmDec_na');
            end
            if app.ImageWidthEditFieldSide.Value == 0
                val = getValue(getEntry(sect,'sideVidStrmImgWdth_px'));
                app.ImageWidthEditFieldSide.Value = val.Value;
                app.ImageWidthEditFieldSide.Limits = findLimits('sideVidStrmImgWdth_px');
            end
            
            % Image Bottom Camera
            if app.ROIHoldPositionpxEditFieldBottomARow.Value == 0
                val = getValue(getEntry(sect,'roiBotAHldPos_px'));
                app.ROIHoldPositionpxEditFieldBottomARow.Value = val.Value(1);
                app.ROIHoldPositionpxEditFieldBottomARow.Limits = findLimits('roiBotAHldPos_px');
            end
            if app.ROIHoldPositionpxEditFieldBottomACol.Value == 0
                val = getValue(getEntry(sect,'roiBotAHldPos_px'));
                app.ROIHoldPositionpxEditFieldBottomACol.Value = val.Value(2);
                app.ROIHoldPositionpxEditFieldBottomACol.Limits = findLimits('roiBotAHldPos_px');
            end
            if app.ROIHoldPositionpxEditFieldBottomBRow.Value == 0
                val = getValue(getEntry(sect,'roiBotBHldPos_px'));
                app.ROIHoldPositionpxEditFieldBottomBRow.Value = val.Value(1);
                app.ROIHoldPositionpxEditFieldBottomBRow.Limits = findLimits('roiBotBHldPos_px');
            end
            if app.ROIHoldPositionpxEditFieldBottomBCol.Value == 0
                val = getValue(getEntry(sect,'roiBotBHldPos_px'));
                app.ROIHoldPositionpxEditFieldBottomBCol.Value = val.Value(2);
                app.ROIHoldPositionpxEditFieldBottomBCol.Limits = findLimits('roiBotBHldPos_px');
            end
            if app.ROISizeEditFieldBottomARow.Value == 0
                val = getValue(getEntry(sect,'roiBotASize_px'));
                app.ROISizeEditFieldBottomARow.Value = val.Value(1);
                app.ROISizeEditFieldBottomARow.Limits = findLimits('roiBotASize_px');
            end
            if app.ROISizeEditFieldBottomACol.Value == 0
                val = getValue(getEntry(sect,'roiBotASize_px'));
                app.ROISizeEditFieldBottomACol.Value = val.Value(2);
                app.ROISizeEditFieldBottomACol.Limits = findLimits('roiBotASize_px');
            end
            if app.ROISizeEditFieldBottomBRow.Value == 0
                val = getValue(getEntry(sect,'roiBotBSize_px'));
                app.ROISizeEditFieldBottomBRow.Value = val.Value(1);
                app.ROISizeEditFieldBottomBRow.Limits = findLimits('roiBotBSize_px');
            end
            if app.ROISizeEditFieldBottomBCol.Value == 0
                val = getValue(getEntry(sect,'roiBotBSize_px'));
                app.ROISizeEditFieldBottomBCol.Value = val.Value(2);
                app.ROISizeEditFieldBottomBCol.Limits = findLimits('roiBotBSize_px');
            end
            if app.ImageDimensionsEditFieldBottomRow.Value == 0
                val = getValue(getEntry(sect,'botImageSize_px'));
                app.ImageDimensionsEditFieldBottomRow.Value = val.Value(1);
                app.ImageDimensionsEditFieldBottomRow.Limits = findLimits('botImageSize_px');
            end
            if app.ImageDimensionsEditFieldBottomCol.Value == 0
                val = getValue(getEntry(sect,'botImageSize_px'));
                app.ImageDimensionsEditFieldBottomCol.Value = val.Value(2);
                app.ImageDimensionsEditFieldBottomCol.Limits = findLimits('botImageSize_px');
            end
            if app.CrosshairWidthEditFieldBottomA.Value == 0
                val = getValue(getEntry(sect,'botACrsHrWdth_px'));
                app.CrosshairWidthEditFieldBottomA.Value = val.Value;
                app.CrosshairWidthEditFieldBottomA.Limits = findLimits('botACrsHrWdth_px');
            end
            if app.CrosshairWidthEditFieldBottomB.Value == 0
                val = getValue(getEntry(sect,'botBCrsHrWdth_px'));
                app.CrosshairWidthEditFieldBottomB.Value = val.Value;
                app.CrosshairWidthEditFieldBottomB.Limits = findLimits('botBCrsHrWdth_px');
            end
            if app.VideoDecimationEditFieldBottom.Value == 0
                val = getValue(getEntry(sect,'botVidStrmDec_na'));
                app.VideoDecimationEditFieldBottom.Value = val.Value;
                app.VideoDecimationEditFieldBottom.Limits = findLimits('botVidStrmDec_na');
            end
            if app.ImageWidthEditFieldBottom.Value == 0
                val = getValue(getEntry(sect,'botVidStrmImgWdth_px'));
                app.ImageWidthEditFieldBottom.Value = val.Value;
                app.ImageWidthEditFieldBottom.Limits = findLimits('botVidStrmImgWdth_px');
            end
            
            % Slant Camera
            if app.ROIHoldPositionpxEditFieldSlantRow.Value == 0
                val = getValue(getEntry(sect,'roiSlntHldPos_px'));
                app.ROIHoldPositionpxEditFieldSlantRow.Value = val.Value(1);
                app.ROIHoldPositionpxEditFieldSlantRow.Limits = findLimits('roiSlntHldPos_px');
            end
            if app.ROIHoldPositionpxEditFieldSlantCol.Value == 0
                val = getValue(getEntry(sect,'roiSlntHldPos_px'));
                app.ROIHoldPositionpxEditFieldSlantCol.Value = val.Value(2);
                app.ROIHoldPositionpxEditFieldSlantCol.Limits = findLimits('roiSlntHldPos_px');
            end
            if app.ROISizeEditFieldSlantRow.Value == 0
                val = getValue(getEntry(sect,'roiSlntSize_px'));
                app.ROISizeEditFieldSlantRow.Value = val.Value(1);
                app.ROISizeEditFieldSlantRow.Limits = findLimits('roiSlntSize_px');
            end
            if app.ROISizeEditFieldSlantCol.Value == 0
                val = getValue(getEntry(sect,'roiSlntSize_px'));
                app.ROISizeEditFieldSlantCol.Value = val.Value(2);
                app.ROISizeEditFieldSlantCol.Limits = findLimits('roiSlntSize_px');
            end
            if app.ImageDimensionsEditFieldSlantRow.Value == 0
                val = getValue(getEntry(sect,'slntImageSize_px'));
                app.ImageDimensionsEditFieldSlantRow.Value = val.Value(1);
                app.ImageDimensionsEditFieldSlantRow.Limits = findLimits('slntImageSize_px');
            end
            if app.ImageDimensionsEditFieldSlantCol.Value == 0
                val = getValue(getEntry(sect,'slntImageSize_px'));
                app.ImageDimensionsEditFieldSlantCol.Value =val.Value(2);
                app.ImageDimensionsEditFieldSlantCol.Limits = findLimits('slntImageSize_px');
            end
            if app.CrosshairWidthEditFieldSlant.Value == 0
                val = getValue(getEntry(sect,'slntCrsHrWdth_px'));
                app.CrosshairWidthEditFieldSlant.Value = val.Value;
                app.CrosshairWidthEditFieldSlant.Limits = findLimits('slntCrsHrWdth_px');
            end
            if app.VideoDecimationEditFieldSlant.Value == 0
                val = getValue(getEntry(sect,'slntVidStrmDec_na'));
                app.VideoDecimationEditFieldSlant.Value = val.Value;
                app.VideoDecimationEditFieldSlant.Limits = findLimits('slntVidStrmDec_na');
            end
            if app.ImageWidthEditFieldSlant.Value == 0
                val = getValue(getEntry(sect,'slntVidStrmImgWdth_px'));
                app.ImageWidthEditFieldSlant.Value = val.Value;
                app.ImageWidthEditFieldSlant.Limits = findLimits('slntVidStrmImgWdth_px');
            end
            
            % UDP Setup
            if app.PTPOffsetEditField.Value == 0
                val = getValue(getEntry(sect,'offsetThreshold_s'));
                app.PTPOffsetEditField.Value = val.Value;
                app.PTPOffsetEditField.Limits = findLimits('offsetThreshold_s');
            end
            if app.SyncIntervalsEditField.Value == 0
                val = getValue(getEntry(sect,'syncInterval_s'));
                app.SyncIntervalsEditField.Value = val.Value;
                app.SyncIntervalsEditField.Limits = findLimits('syncInterval_s');
            end
            
            % Machine 2
            if app.AnnounceIntervalsEditField.Value == 0
                val = getValue(getEntry(sect,'announceInterval_s'));
                app.AnnounceIntervalsEditField.Value = val.Value;
                app.AnnounceIntervalsEditField.Limits = findLimits('announceInterval_s');
            end
            
            % Machine 3
            if app.PortNumberEditFieldMachine3.Value == 0
                val = getValue(getEntry(sect,'sideToPort_na'));
                app.PortNumberEditFieldMachine3.Value = val.Value;
                app.PortNumberEditFieldMachine3.Limits = findLimits('sideToPort_na');
            end
            if app.MinDelayEditFieldMachine3.Value == 0
                val = getValue(getEntry(sect,'sideMinDelay_s'));
                app.MinDelayEditFieldMachine3.Value = val.Value;
                app.MinDelayEditFieldMachine3.Limits = findLimits('sideMinDelay_s');
            end
            if isempty(app.IPAddressEditFieldMachine3.Value)
                val = getValue(getEntry(sect,'sideIPAddress_na'));
                val = val.Value;
                str = strjoin(split(num2str(val)),'.');
                app.IPAddressEditFieldMachine3.Value = str;
            end
            
            % Machine 4
            if app.PortNumberEditFieldMachine4.Value == 0
                val = getValue(getEntry(sect,'bottomToPort_na'));
                app.PortNumberEditFieldMachine4.Value = val.Value;
                app.PortNumberEditFieldMachine4.Limits = findLimits('bottomToPort_na');
            end
            if app.MinDelayEditFieldMachine4.Value == 0
                val = getValue(getEntry(sect,'bottomMinDelay_s'));
                app.MinDelayEditFieldMachine4.Value = val.Value;
                app.MinDelayEditFieldMachine4.Limits = findLimits('bottomMinDelay_s');
            end
            if isempty(app.IPAddressEditFieldMachine4.Value)
                val = getValue(getEntry(sect,'bottomIPAddress_na'));
                val = val.Value;
                str = strjoin(split(num2str(val)),'.');
                app.IPAddressEditFieldMachine4.Value = str;
            end
            
            % Machine 5
            if app.PortNumberEditFieldMachine5.Value == 0
                val = getValue(getEntry(sect,'slantToPort_na'));
                app.PortNumberEditFieldMachine5.Value = val.Value;
                app.PortNumberEditFieldMachine5.Limits = findLimits('slantToPort_na');
            end
            if app.MinDelayEditFieldMachine5.Value == 0
                val = getValue(getEntry(sect,'slantMinDelay_s'));
                app.MinDelayEditFieldMachine5.Value = val.Value;
                app.MinDelayEditFieldMachine5.Limits = findLimits('slantMinDelay_s');
            end
            if isempty(app.IPAddressEditFieldMachine5.Value)
                val = getValue(getEntry(sect,'slantIPAddress_na'));
                val = val.Value;
                str = strjoin(split(num2str(val)),'.');
                app.IPAddressEditFieldMachine5.Value = str;
            end
        end

        % Value changed function: ProportionalGainEditFieldRoll
        function ProportionalGainEditFieldRollValueChanged(app, event)
            value = app.ProportionalGainEditFieldRoll.Value;
            setEntry('rollCtrlkp_Prad',value);
        end

        % Value changed function: DerivativeGainEditFieldRoll
        function DerivativeGainEditFieldRollValueChanged(app, event)
            value = app.DerivativeGainEditFieldRoll.Value;
            setEntry('rollCtrlkd_sPrad',value);
        end

        % Value changed function: TimeConstantEditFieldRoll
        function TimeConstantEditFieldRollValueChanged(app, event)
            value = app.TimeConstantEditFieldRoll.Value;
            setEntry('rollCtrltau_s',value);
        end

        % Value changed function: ProportionalGainEditFieldPitch
        function ProportionalGainEditFieldPitchValueChanged(app, event)
            value = app.ProportionalGainEditFieldPitch.Value;
            setEntry('pitchCtrlkp_Prad',value);
        end

        % Value changed function: DerivativeGainEditFieldPitch
        function DerivativeGainEditFieldPitchValueChanged(app, event)
            value = app.DerivativeGainEditFieldPitch.Value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: TimeConstantEditFieldPitch
        function TimeConstantEditFieldPitchValueChanged(app, event)
            value = app.TimeConstantEditFieldPitch.Value;
            global params;
            params.pitchCtrltau_s.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ProportionalGainEditFieldAltitude
        function ProportionalGainEditFieldAltitudeValueChanged(app, event)
            value = app.ProportionalGainEditFieldAltitude.Value;
            global params;
            params.altCtrlkp_Prad.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: DerivativeGainEditFieldAltitude
        function DerivativeGainEditFieldAltitudeValueChanged(app, event)
            value = app.DerivativeGainEditFieldAltitude.Value;
            global params;
            params.altCtrlkd_sPrad.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: TimeConstantEditFieldAltitude
        function TimeConstantEditFieldAltitudeValueChanged(app, event)
            value = app.TimeConstantEditFieldAltitude.Value;
            global params;
            params.altCtrltau_s.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: Motor1Slider
        function Motor1SliderValueChanged(app, event)
            value = app.Motor1Slider.Value;
            global params;
            params.mtr1ManulaSpdCmd_na.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: Motor2Slider
        function Motor2SliderValueChanged(app, event)
            value = app.Motor2Slider.Value;
            global params;
            params.mtr2ManulaSpdCmd_na.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: Motor3Slider
        function Motor3SliderValueChanged(app, event)
            value = app.Motor3Slider.Value;
            global params;
            params.mtr3ManulaSpdCmd_na.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: MaxMotorVoltageVEditField
        function MaxMotorVoltageVEditFieldValueChanged(app, event)
            value = app.MaxMotorVoltageVEditField.Value;
            global params;
            params.maxOutVltg_vlt.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: CoMPositionEditFieldX
        function CoMPositionEditFieldXValueChanged(app, event)
            value = app.CoMPositionEditFieldX.Value;
            global params;
            params.initCoMPosVec_cm.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: AllSlider
        function AllSliderValueChanged(app, event)
            value = app.AllSlider.Value;
            global params;
            params.mtr1ManulaSpdCmd_na.Value = value;
            params.mtr2ManulaSpdCmd_na.Value = value;
            params.mtr3ManulaSpdCmd_na.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: CoMPositionEditFieldY
        function CoMPositionEditFieldYValueChanged(app, event)
            value = app.CoMPositionEditFieldY.Value;
            global params;
            params.initCoMPosVec_cm.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: CoMPositionEditFieldZ
        function CoMPositionEditFieldZValueChanged(app, event)
            value = app.CoMPositionEditFieldZ.Value;
            global params;
            params.initCoMPosVec_cm.Value(3) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: CameraFOVEditFieldHor
        function CameraFOVEditFieldHorValueChanged(app, event)
            value = app.CameraFOVEditFieldHor.Value;
            global params;
            params.cameraFOV_deg.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: CameraFOVEditFieldVer
        function CameraFOVEditFieldVerValueChanged(app, event)
            value = app.CameraFOVEditFieldVer.Value;
            global params;
            params.cameraFOV_deg.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: PositionEditFieldSideX
        function PositionEditFieldSideXValueChanged(app, event)
            value = app.PositionEditFieldSideX.Value;
            global params;
            params.sideCamPosVec_cm.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: PositionEditFieldSideY
        function PositionEditFieldSideYValueChanged(app, event)
            value = app.PositionEditFieldSideY.Value;
            global params;
            params.sideCamPosVec_cm.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: PositionEditFieldSideZ
        function PositionEditFieldSideZValueChanged(app, event)
            value = app.PositionEditFieldSideZ.Value;
            global params;
            params.sideCamPosVec_cm.Value(3) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: EulerAngleEditFieldSideRoll
        function EulerAngleEditFieldSideRollValueChanged(app, event)
            value = app.EulerAngleEditFieldSideRoll.Value;
            global params;
            params.sideCamEulAng_deg.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: EulerAngleEditFieldSidePitch
        function EulerAngleEditFieldSidePitchValueChanged(app, event)
            value = app.EulerAngleEditFieldSidePitch.Value;
            global params;
            params.sideCamEulAng_deg.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: EulerAngleEditFieldSideYaw
        function EulerAngleEditFieldSideYawValueChanged(app, event)
            value = app.EulerAngleEditFieldSideYaw.Value;
            global params;
            params.sideCamEulAng_deg.Value(3) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: PositionEditFieldBottomX
        function PositionEditFieldBottomXValueChanged(app, event)
            value = app.PositionEditFieldBottomX.Value;
            global params;
            params.botCamPosVec_cm.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: PositionEditFieldBottomY
        function PositionEditFieldBottomYValueChanged(app, event)
            value = app.PositionEditFieldBottomY.Value;
            global params;
            params.botCamPosVec_cm.Value(2) = value;
            v
        end

        % Value changed function: PositionEditFieldBottomZ
        function PositionEditFieldBottomZValueChanged(app, event)
            value = app.PositionEditFieldBottomZ.Value;
            global params;
            params.botCamPosVec_cm.Value(3) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: EulerAngleEditFieldBottomRoll
        function EulerAngleEditFieldBottomRollValueChanged(app, event)
            value = app.EulerAngleEditFieldBottomRoll.Value;
            global params;
            params.botCamEulAng_deg.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: EulerAngleEditFieldBottomPitch
        function EulerAngleEditFieldBottomPitchValueChanged(app, event)
            value = app.EulerAngleEditFieldBottomPitch.Value;
            global params;
            params.botCamEulAng_deg.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: EulerAngleEditFieldBottomYaw
        function EulerAngleEditFieldBottomYawValueChanged(app, event)
            value = app.EulerAngleEditFieldBottomYaw.Value;
            global params;
            params.botCamEulAng_deg.Value(3) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: PositionEditFieldSlantX
        function PositionEditFieldSlantXValueChanged(app, event)
            value = app.PositionEditFieldSlantX.Value;
            global params;
            params.slntCamPosVec_cm.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: PositionEditFieldSlantY
        function PositionEditFieldSlantYValueChanged(app, event)
            value = app.PositionEditFieldSlantY.Value;
            global params;
            params.slntCamPosVec_cm.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: PositionEditFieldSlantZ
        function PositionEditFieldSlantZValueChanged(app, event)
            value = app.PositionEditFieldSlantZ.Value;
            global params;
            params.slntCamPosVec_cm.Value(3) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: EulerAngleEditFieldSlantRoll
        function EulerAngleEditFieldSlantRollValueChanged(app, event)
            value = app.EulerAngleEditFieldSlantRoll.Value;
            global params;
            params.slntCamEulAng_deg.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: EulerAngleEditFieldSlantPitch
        function EulerAngleEditFieldSlantPitchValueChanged(app, event)
            value = app.EulerAngleEditFieldSlantPitch.Value;
            global params;
            params.slntCamEulAng_deg.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: EulerAngleEditFieldSlantYaw
        function EulerAngleEditFieldSlantYawValueChanged(app, event)
            value = app.EulerAngleEditFieldSlantYaw.Value;
            global params;
            params.slntCamEulAng_deg.Value(3) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: SideDotSeparationDistancecmEditField
        function SideDotSeparationDistancecmEditFieldValueChanged(app, event)
            value = app.SideDotSeparationDistancecmEditField.Value;
            global params;
            params.sideDotSepDist_cm.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: SideDotPositionVectorEditFieldX
        function SideDotPositionVectorEditFieldXValueChanged(app, event)
            value = app.SideDotPositionVectorEditFieldX.Value;
            global params;
            params.sideDotPosVec_cm.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: SideDotPositionVectorEditFieldY
        function SideDotPositionVectorEditFieldYValueChanged(app, event)
            value = app.SideDotPositionVectorEditFieldY.Value;
            global params;
            params.sideDotPosVec_cm.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: SideDotPositionVectorEditFieldZ
        function SideDotPositionVectorEditFieldZValueChanged(app, event)
            value = app.SideDotPositionVectorEditFieldZ.Value;
            global params;
            params.sideDotPosVec_cm.Value(3) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: 
        % BottomADotSeparationDistancecmEditField
        function BottomADotSeparationDistancecmEditFieldValueChanged(app, event)
            value = app.BottomADotSeparationDistancecmEditField.Value;
            global params;
            params.botADotSepDist_cm.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: BottomADotPositionVectorEditFieldX
        function BottomADotPositionVectorEditFieldXValueChanged(app, event)
            value = app.BottomADotPositionVectorEditFieldX.Value;
            global params;
            params.botADotPosVec_cm.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: BottomADotPositionVectorEditFieldY
        function BottomADotPositionVectorEditFieldYValueChanged(app, event)
            value = app.BottomADotPositionVectorEditFieldY.Value;
            global params;
            params.botADotPosVec_cm.Value(2) = value;
            sesetEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: BottomADotPositionVectorEditFieldZ
        function BottomADotPositionVectorEditFieldZValueChanged(app, event)
            value = app.BottomADotPositionVectorEditFieldZ.Value;
            global params;
            params.botADotPosVec_cm.Value(3) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: 
        % BottomBDotSeparationDistancecmEditField
        function BottomBDotSeparationDistancecmEditFieldValueChanged(app, event)
            value = app.BottomBDotSeparationDistancecmEditField.Value;
            global params;
            params.botBDotSepDist_cm.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: BottomBDotPositionValueEditFieldX
        function BottomBDotPositionValueEditFieldXValueChanged(app, event)
            value = app.BottomBDotPositionValueEditFieldX.Value;
            global params;
            params.botBDotPosVec_cm.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: BottomBDotPositionVectorEditFieldY
        function BottomBDotPositionVectorEditFieldYValueChanged(app, event)
            value = app.BottomBDotPositionVectorEditFieldY.Value;
            global params;
            params.botBDotPosVec_cm.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: BottomBDotPositionVectorEditFieldZ
        function BottomBDotPositionVectorEditFieldZValueChanged(app, event)
            value = app.BottomBDotPositionVectorEditFieldZ.Value;
            global params;
            params.botBDotPosVec_cm.Value(3) = value;
           setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            global params;
            params.imagProcErrThrsh_na.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: DebounceTimesEditField
        function DebounceTimesEditFieldValueChanged(app, event)
            value = app.DebounceTimesEditField.Value;
            global params;
            params.imagProcErrDbncTm_s.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROIHoldPositionpxEditFieldSideRow
        function ROIHoldPositionpxEditFieldSideRowValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldSideRow.Value;
            global params;
            params.roiSideHldPos_px.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROIHoldPositionpxEditFieldSideCol
        function ROIHoldPositionpxEditFieldSideColValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldSideCol.Value;
            global params;
            params.roiSideHldPos_px.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROISizeEditFieldSideRow
        function ROISizeEditFieldSideRowValueChanged(app, event)
            value = app.ROISizeEditFieldSideRow.Value;
            global params;
            params.roiSideSize_px.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROISizeEditFieldSideCol
        function ROISizeEditFieldSideColValueChanged(app, event)
            value = app.ROISizeEditFieldSideCol.Value;
            global params;
            params.roiSideSize_px.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ImageDimensionsEditFieldSideRow
        function ImageDimensionsEditFieldSideRowValueChanged(app, event)
            value = app.ImageDimensionsEditFieldSideRow.Value;
            global params;
            params.sideImageSize_px.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ImageDimensionsEditFieldSideCol
        function ImageDimensionsEditFieldSideColValueChanged(app, event)
            value = app.ImageDimensionsEditFieldSideCol.Value;
            global params;
            params.sideImageSize_px.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: CrosshairWidthEditFieldSide
        function CrosshairWidthEditFieldSideValueChanged(app, event)
            value = app.CrosshairWidthEditFieldSide.Value;
            global params;
            params.sideCrsHrWdth_px.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: VideoDecimationEditFieldSide
        function VideoDecimationEditFieldSideValueChanged(app, event)
            value = app.VideoDecimationEditFieldSide.Value;
            global params;
            params.sideVidStrmDec_na.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ImageWidthEditFieldSide
        function ImageWidthEditFieldSideValueChanged(app, event)
            value = app.ImageWidthEditFieldSide.Value;
            global params;
            params.sideVidStrmImgWdth_px.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROIHoldPositionpxEditFieldBottomARow
        function ROIHoldPositionpxEditFieldBottomARowValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldBottomARow.Value;
            global params;
            params.roiBotAHldPos_px.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROIHoldPositionpxEditFieldBottomACol
        function ROIHoldPositionpxEditFieldBottomAColValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldBottomACol.Value;
            global params;
            params.roiBotAHldPos_px.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROIHoldPositionpxEditFieldBottomBRow
        function ROIHoldPositionpxEditFieldBottomBRowValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldBottomBRow.Value;
            global params;
            params.roiBotBHldPos_px.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROIHoldPositionpxEditFieldBottomBCol
        function ROIHoldPositionpxEditFieldBottomBColValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldBottomBCol.Value;
            global params;
            params.roiBotBHldPos_px.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROISizeEditFieldBottomARow
        function ROISizeEditFieldBottomARowValueChanged(app, event)
            value = app.ROISizeEditFieldBottomARow.Value;
            global params;
            params.roiBotASize_px.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROISizeEditFieldBottomACol
        function ROISizeEditFieldBottomAColValueChanged(app, event)
            value = app.ROISizeEditFieldBottomACol.Value;
            global params;
            params.roiBotASize_px.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROISizeEditFieldBottomBRow
        function ROISizeEditFieldBottomBRowValueChanged(app, event)
            value = app.ROISizeEditFieldBottomBRow.Value;
            global params;
            params.roiBotBSize_px.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROISizeEditFieldBottomBCol
        function ROISizeEditFieldBottomBColValueChanged(app, event)
            value = app.ROISizeEditFieldBottomBCol.Value;
            global params;
            params.roiBotBSize_px.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ImageDimensionsEditFieldBottomRow
        function ImageDimensionsEditFieldBottomRowValueChanged(app, event)
            value = app.ImageDimensionsEditFieldBottomRow.Value;
            global params;
            params.botImageSize_px.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ImageDimensionsEditFieldBottomCol
        function ImageDimensionsEditFieldBottomColValueChanged(app, event)
            value = app.ImageDimensionsEditFieldBottomCol.Value;
            global params;
            params.botImageSize_px.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: CrosshairWidthEditFieldBottomA
        function CrosshairWidthEditFieldBottomAValueChanged(app, event)
            value = app.CrosshairWidthEditFieldBottomA.Value;
            global params;
            params.botACrsHrWdth_px.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: CrosshairWidthEditFieldBottomB
        function CrosshairWidthEditFieldBottomBValueChanged(app, event)
            value = app.CrosshairWidthEditFieldBottomB.Value;
            global params;
            params.botBCrsHrWdth_px.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: VideoDecimationEditFieldBottom
        function VideoDecimationEditFieldBottomValueChanged(app, event)
            value = app.VideoDecimationEditFieldBottom.Value;
            global params;
            params.botVidStrmDec_na.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ImageWidthEditFieldBottom
        function ImageWidthEditFieldBottomValueChanged(app, event)
            value = app.ImageWidthEditFieldBottom.Value;
            global params;
            params.botVidStrmImgWdth_px.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROIHoldPositionpxEditFieldSlantRow
        function ROIHoldPositionpxEditFieldSlantRowValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldSlantRow.Value;
            global params;
            params.roiSlntHldPos_px.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROIHoldPositionpxEditFieldSlantCol
        function ROIHoldPositionpxEditFieldSlantColValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldSlantCol.Value;
            global params;
            params.roiSlntHldPos_px.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROISizeEditFieldSlantRow
        function ROISizeEditFieldSlantRowValueChanged(app, event)
            value = app.ROISizeEditFieldSlantRow.Value;
            global params;
            params.roiSlntSize_px.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ROISizeEditFieldSlantCol
        function ROISizeEditFieldSlantColValueChanged(app, event)
            value = app.ROISizeEditFieldSlantCol.Value;
            global params;
            params.roiSlntSize_px.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ImageDimensionsEditFieldSlantRow
        function ImageDimensionsEditFieldSlantRowValueChanged(app, event)
            value = app.ImageDimensionsEditFieldSlantRow.Value;
            global params;
            params.slntImageSize_px.Value(1) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ImageDimensionsEditFieldSlantCol
        function ImageDimensionsEditFieldSlantColValueChanged(app, event)
            value = app.ImageDimensionsEditFieldSlantCol.Value;
            global params;
            params.slntImageSize_px.Value(2) = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: CrosshairWidthEditFieldSlant
        function CrosshairWidthEditFieldSlantValueChanged(app, event)
            value = app.CrosshairWidthEditFieldSlant.Value;
            global params;
            params.slntCrsHrWdth_px.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: VideoDecimationEditFieldSlant
        function VideoDecimationEditFieldSlantValueChanged(app, event)
            value = app.VideoDecimationEditFieldSlant.Value;
            global params;
            params.slntVidStrmDec_na.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: ImageWidthEditFieldSlant
        function ImageWidthEditFieldSlantValueChanged(app, event)
            value = app.ImageWidthEditFieldSlant.Value;
            global params;
            params.slntVidStrmImgWdth_px.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: PTPOffsetEditField
        function PTPOffsetEditFieldValueChanged(app, event)
            value = app.PTPOffsetEditField.Value;
            global params;
            params.offsetThreshold_s.Value = value;
            ssetEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: SyncIntervalsEditField
        function SyncIntervalsEditFieldValueChanged(app, event)
            value = app.SyncIntervalsEditField.Value;
            global params;
            params.syncInterval_s.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: AnnounceIntervalsEditField
        function AnnounceIntervalsEditFieldValueChanged(app, event)
            value = app.AnnounceIntervalsEditField.Value;
            global params;
            params.announceInterval_s.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: PortNumberEditFieldMachine3
        function PortNumberEditFieldMachine3ValueChanged(app, event)
            value = app.PortNumberEditFieldMachine3.Value;
            global params;
            params.sideToPort_na.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: MinDelayEditFieldMachine3
        function MinDelayEditFieldMachine3ValueChanged(app, event)
            value = app.MinDelayEditFieldMachine3.Value;
            global params;
            params.sideMinDelay_s.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: PortNumberEditFieldMachine4
        function PortNumberEditFieldMachine4ValueChanged(app, event)
            value = app.PortNumberEditFieldMachine4.Value;
            global params;
            params.bottomToPort_na.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: MinDelayEditFieldMachine4
        function MinDelayEditFieldMachine4ValueChanged(app, event)
            value = app.MinDelayEditFieldMachine4.Value;
            global params;
            params.bottomMinDelay_s.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: PortNumberEditFieldMachine5
        function PortNumberEditFieldMachine5ValueChanged(app, event)
            value = app.PortNumberEditFieldMachine5.Value;
            global params;
            params.slantToPort_na.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: MinDelayEditFieldMachine5
        function MinDelayEditFieldMachine5ValueChanged(app, event)
            value = app.MinDelayEditFieldMachine5.Value;
            global params;
            params.slantMinDelay_s.Value = value;
            setEntry('pitchCtrlkd_sPrad',value);
        end

        % Value changed function: IPAddressEditFieldMachine3
        function IPAddressEditFieldMachine3ValueChanged(app, event)
            value = app.IPAddressEditFieldMachine3.Value;
            str = str2num(strrep(value,'.',' '));
            global params;
            params.sideIPAddress_na.Value(1) = str(1);
            params.sideIPAddress_na.Value(2) = str(2);
            params.sideIPAddress_na.Value(3) = str(3);
            params.sideIPAddress_na.Value(4) = str(4);
            set_param('model3_cm','SimulationCommand','Update');
        end

        % Value changed function: IPAddressEditFieldMachine4
        function IPAddressEditFieldMachine4ValueChanged(app, event)
            value = app.IPAddressEditFieldMachine4.Value;
            str = str2num(strrep(value,'.',' '));
            global params;
            params.bottomIPAddress_na.Value(1) = str(1);
            params.bottomIPAddress_na.Value(2) = str(2);
            params.bottomIPAddress_na.Value(3) = str(3);
            params.bottomIPAddress_na.Value(4) = str(4);
            set_param('model4_cm','SimulationCommand','Update');
        end

        % Value changed function: IPAddressEditFieldMachine5
        function IPAddressEditFieldMachine5ValueChanged(app, event)
            value = app.IPAddressEditFieldMachine5.Value;
            str = str2num(strrep(value,'.',' '));
            global params;
            params.slantIPAddress_na.Value(1) = str(1);
            params.slantIPAddress_na.Value(2) = str(2);
            params.slantIPAddress_na.Value(3) = str(3);
            params.slantIPAddress_na.Value(4) = str(4);
            set_param('model5_cm','SimulationCommand','Update');
        end

        % Value changed function: BottomCameraSwitch
        function BottomCameraSwitchValueChanged(app, event)
            value = app.BottomCameraSwitch.Value;
            global params;
            if strcmp(value, 'Bottom Camera A')
                params.botVidStrmsrcSwtch_na.Value = 1;
            elseif strcmp(value, 'Bottom Camera B')
                params.botVidStrmsrcSwtch_na.Value = 2;
            end
            set_param('model4_cm','SimulationCommand','Update');
        end

        % Value changed function: SwitchSide
        function SwitchSideValueChanged(app, event)
            value = app.SwitchSide.Value;
            if strcmp(value, 'Hold')
                set_param('model3_cm/DotFinder','ROIPositionHoldEnable','on');
            elseif strcmp(value, 'Release')
                set_param('model3_cm/DotFinder','ROIPositionHoldEnable','off');
            end
            set_param('model3_cm','SimulationCommand','Update');
        end

        % Value changed function: SwitchBottomA
        function SwitchBottomAValueChanged(app, event)
            value = app.SwitchBottomA.Value;
            if strcmp(value, 'Hold')
                set_param('model4_cm/DotFinderA','ROIPositionHoldEnable','on');
            elseif strcmp(value, 'Release')
                set_param('model4_cm/DotFinderA','ROIPositionHoldEnable','off');
            end
            set_param('model4_cm','SimulationCommand','Update');
        end

        % Value changed function: SwitchBottomB
        function SwitchBottomBValueChanged(app, event)
            value = app.SwitchBottomB.Value;
            if strcmp(value, 'Hold')
                set_param('model4_cm/DotFinderB','ROIPositionHoldEnable','on');
            elseif strcmp(value, 'Release')
                set_param('model4_cm/DotFinderB','ROIPositionHoldEnable','off');
            end
            set_param('model4_cm','SimulationCommand','Update');
        end

        % Value changed function: SwitchSlant
        function SwitchSlantValueChanged(app, event)
            value = app.SwitchSlant.Value;
            if strcmp(value, 'Hold')
                set_param('model5_cm/DotFinder','ROIPositionHoldEnable','on');
            elseif strcmp(value, 'Release')
                set_param('model5_cm/DotFinder','ROIPositionHoldEnable','off');
            end
            set_param('model5_cm','SimulationCommand','Update');
        end

        % Value changed function: OperationModeSwitch
        function OperationModeSwitchValueChanged(app, event)
            value = app.OperationModeSwitch.Value;
            if strcmp(value, 'Hold')
                set_param('model2_cm/manualOverride','enbl','on');
            elseif strcmp(value, 'Release')
                set_param('model2_cm/manualOverride','enbl','off');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaterChannelControl = uifigure;
            app.WaterChannelControl.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.WaterChannelControl);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.PDControllerGainsPanel = uipanel(app.ControlTab);
            app.PDControllerGainsPanel.Title = 'PD Controller Gains';
            app.RollControlLabel = uilabel(app.PDControllerGainsPanel);
            app.RollControlLabel.Text = 'Roll Control';
            app.PitchControlLabel = uilabel(app.PDControllerGainsPanel);
            app.PitchControlLabel.Text = 'Pitch Control';
            app.ProportionalGainEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditFieldLabel.Text = 'Proportional Gain';
            app.ProportionalGainEditFieldRoll = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditFieldRoll.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldRollValueChanged, true);
            app.TimeConstantsLabel = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantsLabel.Text = 'Time Constant (s)';
            app.TimeConstantEditFieldRoll = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditFieldRoll.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldRollValueChanged, true);
            app.DerivativeGainEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditFieldLabel.Text = 'Derivative Gain';
            app.DerivativeGainEditFieldRoll = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditFieldRoll.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldRollValueChanged, true);
            app.AltitudeControlLabel = uilabel(app.PDControllerGainsPanel);
            app.AltitudeControlLabel.Text = 'Altitude Control';
            app.TimeConstantsLabel_2 = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantsLabel_2.Text = 'Time Constant (s)';
            app.TimeConstantEditFieldPitch = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditFieldPitch.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldPitchValueChanged, true);
            app.DerivativeGainEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditField_2Label.Text = 'Derivative Gain';
            app.DerivativeGainEditFieldPitch = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditFieldPitch.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldPitchValueChanged, true);
            app.ProportionalGainEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditField_2Label.Text = 'Proportional Gain';
            app.ProportionalGainEditFieldPitch = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditFieldPitch.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldPitchValueChanged, true);
            app.TimeConstantsLabel_3 = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantsLabel_3.Text = 'Time Constant (s)';
            app.TimeConstantEditFieldAltitude = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditFieldAltitude.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldAltitudeValueChanged, true);
            app.DerivativeGainEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditField_3Label.Text = 'Derivative Gain';
            app.DerivativeGainEditFieldAltitude = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditFieldAltitude.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldAltitudeValueChanged, true);
            app.ProportionalGainEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditField_3Label.Text = 'Proportional Gain';
            app.ProportionalGainEditFieldAltitude = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditFieldAltitude.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldAltitudeValueChanged, true);
            app.MotorControlPanel = uipanel(app.ControlTab);
            app.MotorControlPanel.Title = 'Motor Control';
            app.AllSliderLabel = uilabel(app.MotorControlPanel);
            app.AllSliderLabel.Text = 'All';
            app.AllSlider = uislider(app.MotorControlPanel);
            app.AllSlider.Limits = [-1 1];
            app.AllSlider.ValueChangedFcn = createCallbackFcn(app, @AllSliderValueChanged, true);
            app.Motor3SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor3SliderLabel.Text = 'Motor 3';
            app.Motor3Slider = uislider(app.MotorControlPanel);
            app.Motor3Slider.Limits = [-1 1];
            app.Motor3Slider.ValueChangedFcn = createCallbackFcn(app, @Motor3SliderValueChanged, true);
            app.Motor1SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor1SliderLabel.Text = 'Motor 1';
            app.Motor1Slider = uislider(app.MotorControlPanel);
            app.Motor1Slider.Limits = [-1 1];
            app.Motor1Slider.ValueChangedFcn = createCallbackFcn(app, @Motor1SliderValueChanged, true);
            app.Motor2SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor2SliderLabel.Text = 'Motor 2';
            app.Motor2Slider = uislider(app.MotorControlPanel);
            app.Motor2Slider.Limits = [-1 1];
            app.Motor2Slider.ValueChangedFcn = createCallbackFcn(app, @Motor2SliderValueChanged, true);
            app.MotorConfigurationPanel = uipanel(app.ControlTab);
            app.MotorConfigurationPanel.Title = 'Motor Configuration';
            app.MaxMotorVoltageVEditFieldLabel = uilabel(app.MotorConfigurationPanel);
            app.MaxMotorVoltageVEditFieldLabel.Text = 'Max Motor Voltage (V)';
            app.MaxMotorVoltageVEditField = uieditfield(app.MotorConfigurationPanel, 'numeric');
            app.MaxMotorVoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @MaxMotorVoltageVEditFieldValueChanged, true);
            app.InitialCenterofMassPositionPanel = uipanel(app.ControlTab);
            app.InitialCenterofMassPositionPanel.Title = 'Initial Center of Mass Position';
            app.CoMPositioncmLabel = uilabel(app.InitialCenterofMassPositionPanel);
            app.CoMPositioncmLabel.Text = 'CoM Position (cm)';
            app.CoMPositionEditFieldX = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldX.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldXValueChanged, true);
            app.CoMPositionEditFieldY = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldY.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldYValueChanged, true);
            app.CoMPositionEditFieldZ = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldZValueChanged, true);
            app.OperationModePanel = uipanel(app.ControlTab);
            app.OperationModePanel.Title = 'Operation Mode';
            app.OperationModeSwitch = uiswitch(app.OperationModePanel, 'toggle');
            app.OperationModeSwitch.Items = {'Manual', 'Automatic'};
            app.OperationModeSwitch.ValueChangedFcn = createCallbackFcn(app, @OperationModeSwitchValueChanged, true);
            app.OperationModeSwitch.Value = 'Automatic';
            app.CameraControlTab = uitab(app.TabGroup);
            app.CameraControlTab.Title = 'Camera Control';
            app.SideCameraPanel = uipanel(app.CameraControlTab);
            app.SideCameraPanel.Title = 'Side Camera';
            app.ROIHoldPositionpxEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSideRow.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldSideRowValueChanged, true);
            app.ROIHoldPositionpxEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSideCol.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldSideColValueChanged, true);
            app.ROISizepxLabel = uilabel(app.SideCameraPanel);
            app.ROISizepxLabel.Text = 'ROI Size (px)';
            app.ROISizeEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROISizeEditFieldSideRow.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldSideRowValueChanged, true);
            app.ROISizeEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROISizeEditFieldSideCol.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldSideColValueChanged, true);
            app.ImageDimensionspxLabel = uilabel(app.SideCameraPanel);
            app.ImageDimensionspxLabel.Text = 'Image Dimensions (px)';
            app.ImageDimensionsEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSideRow.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldSideRowValueChanged, true);
            app.ImageDimensionsEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSideCol.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldSideColValueChanged, true);
            app.CrosshairWidthpxLabel = uilabel(app.SideCameraPanel);
            app.CrosshairWidthpxLabel.Text = 'Crosshair Width (px)';
            app.CrosshairWidthEditFieldSide = uieditfield(app.SideCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldSide.ValueChangedFcn = createCallbackFcn(app, @CrosshairWidthEditFieldSideValueChanged, true);
            app.VideoDecimationEditFieldLabel = uilabel(app.SideCameraPanel);
            app.VideoDecimationEditFieldLabel.Text = 'Video Decimation';
            app.VideoDecimationEditFieldSide = uieditfield(app.SideCameraPanel, 'numeric');
            app.VideoDecimationEditFieldSide.ValueChangedFcn = createCallbackFcn(app, @VideoDecimationEditFieldSideValueChanged, true);
            app.ImageWidthpxLabel = uilabel(app.SideCameraPanel);
            app.ImageWidthpxLabel.Text = 'Image Width (px)';
            app.ImageWidthEditFieldSide = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageWidthEditFieldSide.ValueChangedFcn = createCallbackFcn(app, @ImageWidthEditFieldSideValueChanged, true);
            app.SwitchSide = uiswitch(app.SideCameraPanel, 'slider');
            app.SwitchSide.Items = {'Hold', 'Release'};
            app.SwitchSide.ValueChangedFcn = createCallbackFcn(app, @SwitchSideValueChanged, true);
            app.SwitchSide.Value = 'Hold';
            app.BottomCameraPanel = uipanel(app.CameraControlTab);
            app.BottomCameraPanel.Title = 'Bottom Camera';
            app.ROIHoldPositionpxEditFieldLabel_2 = uilabel(app.BottomCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel_2.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldBottomARow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomARow.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldBottomARowValueChanged, true);
            app.ROIHoldPositionpxEditFieldBottomACol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomACol.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldBottomAColValueChanged, true);
            app.ROISizepxLabel_3 = uilabel(app.BottomCameraPanel);
            app.ROISizepxLabel_3.Text = 'ROI Size (px)';
            app.ROISizeEditFieldBottomARow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomARow.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldBottomARowValueChanged, true);
            app.ROISizeEditFieldBottomACol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomACol.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldBottomAColValueChanged, true);
            app.ImageDimensionsEditFieldBottomCol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldBottomCol.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldBottomColValueChanged, true);
            app.CrosshairWidthpxLabel_3 = uilabel(app.BottomCameraPanel);
            app.CrosshairWidthpxLabel_3.Text = 'Crosshair Width (px)';
            app.CrosshairWidthEditFieldBottomA = uieditfield(app.BottomCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldBottomA.ValueChangedFcn = createCallbackFcn(app, @CrosshairWidthEditFieldBottomAValueChanged, true);
            app.VideoDecimationEditField_2Label = uilabel(app.BottomCameraPanel);
            app.VideoDecimationEditField_2Label.Text = 'Video Decimation';
            app.VideoDecimationEditFieldBottom = uieditfield(app.BottomCameraPanel, 'numeric');
            app.VideoDecimationEditFieldBottom.ValueChangedFcn = createCallbackFcn(app, @VideoDecimationEditFieldBottomValueChanged, true);
            app.ImageWidthpxLabel_3 = uilabel(app.BottomCameraPanel);
            app.ImageWidthpxLabel_3.Text = 'Image Width (px)';
            app.ImageWidthEditFieldBottom = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ImageWidthEditFieldBottom.ValueChangedFcn = createCallbackFcn(app, @ImageWidthEditFieldBottomValueChanged, true);
            app.BottomCameraALabel = uilabel(app.BottomCameraPanel);
            app.BottomCameraALabel.Text = 'Bottom Camera A';
            app.BottomCameraBLabel = uilabel(app.BottomCameraPanel);
            app.BottomCameraBLabel.Text = 'Bottom Camera B';
            app.ROIHoldPositionpxEditFieldBottomBCol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomBCol.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldBottomBColValueChanged, true);
            app.ROISizeEditFieldBottomBCol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomBCol.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldBottomBColValueChanged, true);
            app.ROIHoldPositionpxEditFieldLabel_4 = uilabel(app.BottomCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel_4.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldBottomBRow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomBRow.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldBottomBRowValueChanged, true);
            app.ROISizepxLabel_4 = uilabel(app.BottomCameraPanel);
            app.ROISizepxLabel_4.Text = 'ROI Size (px)';
            app.ROISizeEditFieldBottomBRow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomBRow.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldBottomBRowValueChanged, true);
            app.CrosshairWidthpxLabel_4 = uilabel(app.BottomCameraPanel);
            app.CrosshairWidthpxLabel_4.Text = 'Crosshair Width (px)';
            app.CrosshairWidthEditFieldBottomB = uieditfield(app.BottomCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldBottomB.ValueChangedFcn = createCallbackFcn(app, @CrosshairWidthEditFieldBottomBValueChanged, true);
            app.BothCamerasLabel = uilabel(app.BottomCameraPanel);
            app.BothCamerasLabel.Text = 'Both Cameras';
            app.ImageDimensionspxLabel_3 = uilabel(app.BottomCameraPanel);
            app.ImageDimensionspxLabel_3.Text = 'Image Dimensions (px)';
            app.ImageDimensionsEditFieldBottomRow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldBottomRow.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldBottomRowValueChanged, true);
            app.SwitchBottomA = uiswitch(app.BottomCameraPanel, 'slider');
            app.SwitchBottomA.Items = {'Hold', 'Release'};
            app.SwitchBottomA.ValueChangedFcn = createCallbackFcn(app, @SwitchBottomAValueChanged, true);
            app.SwitchBottomA.Value = 'Hold';
            app.SwitchBottomB = uiswitch(app.BottomCameraPanel, 'slider');
            app.SwitchBottomB.Items = {'Hold', 'Release'};
            app.SwitchBottomB.ValueChangedFcn = createCallbackFcn(app, @SwitchBottomBValueChanged, true);
            app.SwitchBottomB.Value = 'Hold';
            app.SlantCameraPanel = uipanel(app.CameraControlTab);
            app.SlantCameraPanel.Title = 'Slant Camera';
            app.ROIHoldPositionpxEditFieldLabel_3 = uilabel(app.SlantCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel_3.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldSlantRow = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSlantRow.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldSlantRowValueChanged, true);
            app.ROIHoldPositionpxEditFieldSlantCol = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSlantCol.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldSlantColValueChanged, true);
            app.ROISizepxLabel_2 = uilabel(app.SlantCameraPanel);
            app.ROISizepxLabel_2.Text = 'ROI Size (px)';
            app.ROISizeEditFieldSlantRow = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROISizeEditFieldSlantRow.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldSlantRowValueChanged, true);
            app.ROISizeEditFieldSlantCol = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROISizeEditFieldSlantCol.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldSlantColValueChanged, true);
            app.ImageDimensionspxLabel_2 = uilabel(app.SlantCameraPanel);
            app.ImageDimensionspxLabel_2.Text = 'Image Dimensions (px)';
            app.ImageDimensionsEditFieldSlantRow = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSlantRow.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldSlantRowValueChanged, true);
            app.ImageDimensionsEditFieldSlantCol = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSlantCol.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldSlantColValueChanged, true);
            app.CrosshairWidthpxLabel_2 = uilabel(app.SlantCameraPanel);
            app.CrosshairWidthpxLabel_2.Text = 'Crosshair Width (px)';
            app.CrosshairWidthEditFieldSlant = uieditfield(app.SlantCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldSlant.ValueChangedFcn = createCallbackFcn(app, @CrosshairWidthEditFieldSlantValueChanged, true);
            app.VideoDecimationEditField_3Label = uilabel(app.SlantCameraPanel);
            app.VideoDecimationEditField_3Label.Text = 'Video Decimation';
            app.VideoDecimationEditFieldSlant = uieditfield(app.SlantCameraPanel, 'numeric');
            app.VideoDecimationEditFieldSlant.ValueChangedFcn = createCallbackFcn(app, @VideoDecimationEditFieldSlantValueChanged, true);
            app.ImageWidthpxLabel_2 = uilabel(app.SlantCameraPanel);
            app.ImageWidthpxLabel_2.Text = 'Image Width (px)';
            app.ImageWidthEditFieldSlant = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ImageWidthEditFieldSlant.ValueChangedFcn = createCallbackFcn(app, @ImageWidthEditFieldSlantValueChanged, true);
            app.SwitchSlant = uiswitch(app.SlantCameraPanel, 'slider');
            app.SwitchSlant.Items = {'Hold', 'Release'};
            app.SwitchSlant.ValueChangedFcn = createCallbackFcn(app, @SwitchSlantValueChanged, true);
            app.SwitchSlant.Value = 'Hold';
            app.CameraSelectionPanel = uipanel(app.CameraControlTab);
            app.CameraSelectionPanel.Title = 'Camera Selection';
            app.BottomCameraSwitchLabel = uilabel(app.CameraSelectionPanel);
            app.BottomCameraSwitchLabel.Text = {'Bottom Camera'; 'Switch'};
            app.BottomCameraSwitch = uiswitch(app.CameraSelectionPanel, 'toggle');
            app.BottomCameraSwitch.Items = {'Bottom Camera B', 'Bottom Camera A'};
            app.BottomCameraSwitch.ValueChangedFcn = createCallbackFcn(app, @BottomCameraSwitchValueChanged, true);
            app.BottomCameraSwitch.Value = 'Bottom Camera A';
            app.CameraSettingsTab = uitab(app.TabGroup);
            app.CameraSettingsTab.Title = 'Camera Settings';
            app.DotPropertiesPanel = uipanel(app.CameraSettingsTab);
            app.DotPropertiesPanel.Title = 'Dot Properties';
            app.SideDotSeparationDistancecmEditFieldLabel = uilabel(app.DotPropertiesPanel);
            app.SideDotSeparationDistancecmEditFieldLabel.Text = 'Side Dot Separation Distance (cm)';
            app.SideDotSeparationDistancecmEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotSeparationDistancecmEditField.ValueChangedFcn = createCallbackFcn(app, @SideDotSeparationDistancecmEditFieldValueChanged, true);
            app.SideDotPositionVectorcmLabel = uilabel(app.DotPropertiesPanel);
            app.SideDotPositionVectorcmLabel.Text = 'Side Dot Position Vector (cm)';
            app.SideDotPositionVectorEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldX.ValueChangedFcn = createCallbackFcn(app, @SideDotPositionVectorEditFieldXValueChanged, true);
            app.BottomADotSeparationDistancecmEditFieldLabel = uilabel(app.DotPropertiesPanel);
            app.BottomADotSeparationDistancecmEditFieldLabel.Text = 'Bottom A Dot Separation Distance (cm)';
            app.BottomADotSeparationDistancecmEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotSeparationDistancecmEditField.ValueChangedFcn = createCallbackFcn(app, @BottomADotSeparationDistancecmEditFieldValueChanged, true);
            app.BottomADotPositionVectorcmLabel = uilabel(app.DotPropertiesPanel);
            app.BottomADotPositionVectorcmLabel.Text = 'Bottom A Dot Position Vector (cm)';
            app.BottomADotPositionVectorEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldX.ValueChangedFcn = createCallbackFcn(app, @BottomADotPositionVectorEditFieldXValueChanged, true);
            app.BottomBDotSeparationDistancecmEditFieldLabel = uilabel(app.DotPropertiesPanel);
            app.BottomBDotSeparationDistancecmEditFieldLabel.Text = 'Bottom B Dot Separation Distance (cm)';
            app.BottomBDotSeparationDistancecmEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotSeparationDistancecmEditField.ValueChangedFcn = createCallbackFcn(app, @BottomBDotSeparationDistancecmEditFieldValueChanged, true);
            app.BottomBDotPositionVectorcmLabel = uilabel(app.DotPropertiesPanel);
            app.BottomBDotPositionVectorcmLabel.Text = 'Bottom B Dot Position Vector (cm)';
            app.BottomBDotPositionValueEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionValueEditFieldX.ValueChangedFcn = createCallbackFcn(app, @BottomBDotPositionValueEditFieldXValueChanged, true);
            app.SideDotPositionVectorEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldY.ValueChangedFcn = createCallbackFcn(app, @SideDotPositionVectorEditFieldYValueChanged, true);
            app.SideDotPositionVectorEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @SideDotPositionVectorEditFieldZValueChanged, true);
            app.BottomADotPositionVectorEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldY.ValueChangedFcn = createCallbackFcn(app, @BottomADotPositionVectorEditFieldYValueChanged, true);
            app.BottomADotPositionVectorEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @BottomADotPositionVectorEditFieldZValueChanged, true);
            app.BottomBDotPositionVectorEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionVectorEditFieldY.ValueChangedFcn = createCallbackFcn(app, @BottomBDotPositionVectorEditFieldYValueChanged, true);
            app.BottomBDotPositionVectorEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionVectorEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @BottomBDotPositionVectorEditFieldZValueChanged, true);
            app.CameraSpecificationsPanel = uipanel(app.CameraSettingsTab);
            app.CameraSpecificationsPanel.Title = 'Camera Specifications';
            app.CameraFOVdegLabel = uilabel(app.CameraSpecificationsPanel);
            app.CameraFOVdegLabel.Text = 'Camera FOV (deg)';
            app.CameraFOVEditFieldHor = uieditfield(app.CameraSpecificationsPanel, 'numeric');
            app.CameraFOVEditFieldHor.ValueChangedFcn = createCallbackFcn(app, @CameraFOVEditFieldHorValueChanged, true);
            app.CameraFOVEditFieldVer = uieditfield(app.CameraSpecificationsPanel, 'numeric');
            app.CameraFOVEditFieldVer.ValueChangedFcn = createCallbackFcn(app, @CameraFOVEditFieldVerValueChanged, true);
            app.ImageErrorSettingsPanel = uipanel(app.CameraSettingsTab);
            app.ImageErrorSettingsPanel.Title = 'Image Error Settings';
            app.ThresholdLabel = uilabel(app.ImageErrorSettingsPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ImageErrorSettingsPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.DebounceTimesEditFieldLabel = uilabel(app.ImageErrorSettingsPanel);
            app.DebounceTimesEditFieldLabel.Text = 'Debounce Time (s)';
            app.DebounceTimesEditField = uieditfield(app.ImageErrorSettingsPanel, 'numeric');
            app.DebounceTimesEditField.ValueChangedFcn = createCallbackFcn(app, @DebounceTimesEditFieldValueChanged, true);
            app.CameraOrientationPanel = uipanel(app.CameraSettingsTab);
            app.CameraOrientationPanel.Title = 'Camera Orientation';
            app.SideCameraLabel2 = uilabel(app.CameraOrientationPanel);
            app.SideCameraLabel2.Text = 'Side Camera';
            app.EulerAngledegLabel = uilabel(app.CameraOrientationPanel);
            app.EulerAngledegLabel.Text = 'Euler Angle (deg)';
            app.EulerAngleEditFieldSideRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSideRoll.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSideRollValueChanged, true);
            app.BottomCameraLabel = uilabel(app.CameraOrientationPanel);
            app.BottomCameraLabel.Text = 'Bottom Camera';
            app.EulerAngledegLabel_2 = uilabel(app.CameraOrientationPanel);
            app.EulerAngledegLabel_2.Text = 'Euler Angle (deg)';
            app.EulerAngleEditFieldBottomRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomRoll.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldBottomRollValueChanged, true);
            app.EulerAngleEditFieldBottomPitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomPitch.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldBottomPitchValueChanged, true);
            app.EulerAngleEditFieldBottomYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomYaw.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldBottomYawValueChanged, true);
            app.PositioncmLabel_2 = uilabel(app.CameraOrientationPanel);
            app.PositioncmLabel_2.Text = 'Position (cm)';
            app.PositionEditFieldBottomX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomX.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldBottomXValueChanged, true);
            app.SlantCameraLabel2 = uilabel(app.CameraOrientationPanel);
            app.SlantCameraLabel2.Text = 'Slant Camera';
            app.EulerAngledegLabel_3 = uilabel(app.CameraOrientationPanel);
            app.EulerAngledegLabel_3.Text = 'Euler Angle (deg)';
            app.EulerAngleEditFieldSlantRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantRoll.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSlantRollValueChanged, true);
            app.PositioncmLabel_3 = uilabel(app.CameraOrientationPanel);
            app.PositioncmLabel_3.Text = 'Position (cm)';
            app.PositionEditFieldSlantX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantX.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSlantXValueChanged, true);
            app.PositionEditFieldSlantY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantY.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSlantYValueChanged, true);
            app.EulerAngleEditFieldSlantPitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantPitch.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSlantPitchValueChanged, true);
            app.EulerAngleEditFieldSlantYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantYaw.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSlantYawValueChanged, true);
            app.PositionEditFieldSlantZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantZ.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSlantZValueChanged, true);
            app.PositioncmLabel = uilabel(app.CameraOrientationPanel);
            app.PositioncmLabel.Text = 'Position (cm)';
            app.PositionEditFieldSideX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideX.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSideXValueChanged, true);
            app.PositionEditFieldSideZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideZ.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSideZValueChanged, true);
            app.PositionEditFieldBottomY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomY.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldBottomYValueChanged, true);
            app.PositionEditFieldBottomZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomZ.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldBottomZValueChanged, true);
            app.EulerAngleEditFieldSidePitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSidePitch.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSidePitchValueChanged, true);
            app.PositionEditFieldSideY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideY.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSideYValueChanged, true);
            app.EulerAngleEditFieldSideYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSideYaw.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSideYawValueChanged, true);
            app.CommunicationTab = uitab(app.TabGroup);
            app.CommunicationTab.Title = 'Communication';
            app.UDPPTPConfigurationPanel = uipanel(app.CommunicationTab);
            app.UDPPTPConfigurationPanel.Title = 'UDP/PTP Configuration';
            app.PTPOffsetEditFieldLabel = uilabel(app.UDPPTPConfigurationPanel);
            app.PTPOffsetEditFieldLabel.Text = 'PTP Offset';
            app.PTPOffsetEditField = uieditfield(app.UDPPTPConfigurationPanel, 'numeric');
            app.PTPOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @PTPOffsetEditFieldValueChanged, true);
            app.SyncIntervalsEditFieldLabel = uilabel(app.UDPPTPConfigurationPanel);
            app.SyncIntervalsEditFieldLabel.Text = 'Sync Interval (s)';
            app.SyncIntervalsEditField = uieditfield(app.UDPPTPConfigurationPanel, 'numeric');
            app.SyncIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @SyncIntervalsEditFieldValueChanged, true);
            app.Machine3Panel = uipanel(app.CommunicationTab);
            app.Machine3Panel.Title = 'Machine 3';
            app.PortNumberEditFieldLabel = uilabel(app.Machine3Panel);
            app.PortNumberEditFieldLabel.Text = 'Port Number';
            app.PortNumberEditFieldMachine3 = uieditfield(app.Machine3Panel, 'numeric');
            app.PortNumberEditFieldMachine3.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditFieldMachine3ValueChanged, true);
            app.MinDelaysLabel = uilabel(app.Machine3Panel);
            app.MinDelaysLabel.Text = 'Min Delay (s)';
            app.MinDelayEditFieldMachine3 = uieditfield(app.Machine3Panel, 'numeric');
            app.MinDelayEditFieldMachine3.ValueChangedFcn = createCallbackFcn(app, @MinDelayEditFieldMachine3ValueChanged, true);
            app.IPAddressEditFieldLabel = uilabel(app.Machine3Panel);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditFieldMachine3 = uieditfield(app.Machine3Panel, 'text');
            app.IPAddressEditFieldMachine3.ValueChangedFcn = createCallbackFcn(app, @IPAddressEditFieldMachine3ValueChanged, true);
            app.Machine4Panel = uipanel(app.CommunicationTab);
            app.Machine4Panel.Title = 'Machine 4';
            app.PortNumberEditField_2Label = uilabel(app.Machine4Panel);
            app.PortNumberEditField_2Label.Text = 'Port Number';
            app.PortNumberEditFieldMachine4 = uieditfield(app.Machine4Panel, 'numeric');
            app.PortNumberEditFieldMachine4.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditFieldMachine4ValueChanged, true);
            app.MinDelaysLabel_2 = uilabel(app.Machine4Panel);
            app.MinDelaysLabel_2.Text = 'Min Delay (s)';
            app.MinDelayEditFieldMachine4 = uieditfield(app.Machine4Panel, 'numeric');
            app.MinDelayEditFieldMachine4.ValueChangedFcn = createCallbackFcn(app, @MinDelayEditFieldMachine4ValueChanged, true);
            app.IPAddressEditField_2Label = uilabel(app.Machine4Panel);
            app.IPAddressEditField_2Label.Text = 'IP Address';
            app.IPAddressEditFieldMachine4 = uieditfield(app.Machine4Panel, 'text');
            app.IPAddressEditFieldMachine4.ValueChangedFcn = createCallbackFcn(app, @IPAddressEditFieldMachine4ValueChanged, true);
            app.Machine5Panel = uipanel(app.CommunicationTab);
            app.Machine5Panel.Title = 'Machine 5';
            app.PortNumberEditField_3Label = uilabel(app.Machine5Panel);
            app.PortNumberEditField_3Label.Text = 'Port Number';
            app.PortNumberEditFieldMachine5 = uieditfield(app.Machine5Panel, 'numeric');
            app.PortNumberEditFieldMachine5.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditFieldMachine5ValueChanged, true);
            app.MinDelaysLabel_3 = uilabel(app.Machine5Panel);
            app.MinDelaysLabel_3.Text = 'Min Delay (s)';
            app.MinDelayEditFieldMachine5 = uieditfield(app.Machine5Panel, 'numeric');
            app.MinDelayEditFieldMachine5.ValueChangedFcn = createCallbackFcn(app, @MinDelayEditFieldMachine5ValueChanged, true);
            app.IPAddressEditField_3Label = uilabel(app.Machine5Panel);
            app.IPAddressEditField_3Label.Text = 'IP Address';
            app.IPAddressEditFieldMachine5 = uieditfield(app.Machine5Panel, 'text');
            app.IPAddressEditFieldMachine5.ValueChangedFcn = createCallbackFcn(app, @IPAddressEditFieldMachine5ValueChanged, true);
            app.Machine2Panel = uipanel(app.CommunicationTab);
            app.Machine2Panel.Title = 'Machine 2';
            app.AnnounceIntervalsEditFieldLabel = uilabel(app.Machine2Panel);
            app.AnnounceIntervalsEditFieldLabel.Text = 'Announce Interval (s)';
            app.AnnounceIntervalsEditField = uieditfield(app.Machine2Panel, 'numeric');
            app.AnnounceIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @AnnounceIntervalsEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaterChannelControl)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaterChannelControl)
        end
    end
end