classdef constantSetpointsControllerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        pitchSetpointEditField      matlab.ui.control.NumericEditField
        rollSetpointEditField       matlab.ui.control.NumericEditField
        altitudeSetpointEditField   matlab.ui.control.NumericEditField
        pitchPropGainEditField      matlab.ui.control.NumericEditField
        rollPropGainEditField       matlab.ui.control.NumericEditField
        altitudePropGainEditField   matlab.ui.control.NumericEditField
        pitchDerivGainEditField     matlab.ui.control.NumericEditField
        rollDerivGainEditField      matlab.ui.control.NumericEditField
        altitudeDerivGainEditField  matlab.ui.control.NumericEditField
        pitchTimeConstEditField     matlab.ui.control.NumericEditField
        rollTimeConstEditField      matlab.ui.control.NumericEditField
        altitudeTimeConstEditField  matlab.ui.control.NumericEditField
        PitchLabel                  matlab.ui.control.Label
        RollLabel                   matlab.ui.control.Label
        AltitudeLabel               matlab.ui.control.Label
        SetpointdegcmLabel          matlab.ui.control.Label
        K_pLabel                    matlab.ui.control.Label
        K_dLabel                    matlab.ui.control.Label
        tausLabel                   matlab.ui.control.Label
        pitchCheckBox               matlab.ui.control.CheckBox
        rollCheckBox                matlab.ui.control.CheckBox
        altitudeCheckBox            matlab.ui.control.CheckBox
        EnableLabel                 matlab.ui.control.Label
        SaveSettingsButton          matlab.ui.control.Button
        LoadSettingsButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        pitchCtrlBlock = 'model2_cm/Controllers/ConstantSetpoints/PitchController';
        pitchSPBlock   = 'model2_cm/Controllers/ConstantSetpoints/PitchSetpoint_deg';
        rollCtrlBlock  = 'model2_cm/Controllers/ConstantSetpoints/RollController';
        rollSPBlock    = 'model2_cm/Controllers/ConstantSetpoints/RollSetpoint_deg';
        altCtrlBlock   = 'model2_cm/Controllers/ConstantSetpoints/AltitudeController';
        altSPBlock     = 'model2_cm/Controllers/ConstantSetpoints/AltitudeSetpoint_cm';
    end
    
    
    methods (Access = private)
        
        % Function to get all properties of the app that match a specific class
        function results = filterByClass(app,cls)
            props = properties(app);
            results = {};
            for ii = 1:length(props)
                if contains(class(app.(props{ii})),cls)
                    results{end+1} = props{ii};
                end
                
            end
        end
        
        
        function loadController(app,path,file)
            load(fullfile(path,file),'ctrl');
            props = fields(ctrl);
            runSetParam = bdIsLoaded('model2_cm');
            for ii = 1:numel(props)
                app.(props{ii}).Value = ctrl.(props{ii});
                if runSetParam
                    app.(props{ii}).ValueChangedFcn(app,[]) % Run the value changed callback to set the value in the model.
                end
            end
        end
        
        function saveController(app)
            [fileOut,outPath] = uigetfile;
            varToSave = filterByClass(app,{'NumericEditField','CheckBox'});
            for ii = 1:length(varToSave)
                ctrl.(varToSave{ii}) = app.(varToSave{ii}).Value;
            end
            save(fullfile(outPath,fileOut),'ctrl') % Save things
            winopen(fullfile(outPath)) % Open the folder containing the output
            uialert(app.UIFigure,{'Controller saved to:',fullfile(outPath,fileOut)},'Controller structure saved','Icon',''); % Alert used to path
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [500 500 402 182];
            
            % Suppress warning about changing values in library link.
            warning('off','Simulink:Commands:SetParamLinkChangeWarn')
            
            % Check if model 2 is open, if not, then open it
            if ~bdIsLoaded('model2_cm')
                open_system('model2_cm')
            end
            
            % Load controller if it exists
            filePath = fullfile(fileparts(which('constantSetpoints_cl')),'defaultController.mat');
            if exist(filePath,'file')==2
                loadController(app,fileparts(filePath),'defaultController.mat')
            end
        end

        % Value changed function: pitchSetpointEditField
        function pitchSetpointEditFieldValueChanged(app, event)
            set_param(app.pitchSPBlock,'Value',num2str(app.pitchSetpointEditField.Value));
        end

        % Value changed function: rollSetpointEditField
        function rollSetpointEditFieldValueChanged(app, event)
            set_param(app.rollSPBlock,'Value',num2str(app.rollSetpointEditField.Value));
        end

        % Value changed function: altitudeSetpointEditField
        function altitudeSetpointEditFieldValueChanged(app, event)
            set_param(app.altSPBlock,'Value',num2str(app.altitudeSetpointEditField.Value));
        end

        % Value changed function: pitchPropGainEditField
        function pitchPropGainEditFieldValueChanged(app, event)
            set_param(app.pitchCtrlBlock,'kp',num2str(app.pitchPropGainEditField.Value));
        end

        % Value changed function: rollPropGainEditField
        function rollPropGainEditFieldValueChanged(app, event)
            set_param(app.rollCtrlBlock,'kp',num2str(app.rollPropGainEditField.Value));
        end

        % Value changed function: altitudePropGainEditField
        function altitudePropGainEditFieldValueChanged(app, event)
            set_param(app.altCtrlBlock,'kp',num2str(app.altitudePropGainEditField.Value));
        end

        % Value changed function: pitchDerivGainEditField
        function pitchDerivGainEditFieldValueChanged(app, event)
            set_param(app.pitchCtrlBlock,'kd',num2str(app.pitchDerivGainEditField.Value));
        end

        % Value changed function: rollDerivGainEditField
        function rollDerivGainEditFieldValueChanged(app, event)
            set_param(app.rollCtrlBlock,'kd',num2str(app.rollDerivGainEditField.Value));
        end

        % Value changed function: altitudeDerivGainEditField
        function altitudeDerivGainEditFieldValueChanged(app, event)
            set_param(app.altCtrlBlock,'kd',num2str(app.altitudeDerivGainEditField.Value));
        end

        % Value changed function: pitchTimeConstEditField
        function pitchTimeConstEditFieldValueChanged(app, event)
            set_param(app.pitchCtrlBlock,'timeConstant_s',num2str(app.pitchTimeConstEditField.Value));
        end

        % Value changed function: rollTimeConstEditField
        function rollTimeConstEditFieldValueChanged(app, event)
            set_param(app.rollCtrlBlock,'timeConstant_s',num2str(app.rollTimeConstEditField.Value));
        end

        % Value changed function: altitudeTimeConstEditField
        function altitudeTimeConstEditFieldValueChanged(app, event)
            set_param(app.altCtrlBlock,'timeConstant_s',num2str(app.altitudeTimeConstEditField.Value));
        end

        % Value changed function: pitchCheckBox
        function pitchCheckBoxValueChanged(app, event)
            if app.pitchCheckBox.Value
                set_param(app.pitchCtrlBlock,'enable','on')
            else
                set_param(app.pitchCtrlBlock,'enable','off')
            end
        end

        % Value changed function: rollCheckBox
        function rollCheckBoxValueChanged(app, event)
            if app.rollCheckBox.Value
                set_param(app.rollCtrlBlock,'enable','on')
            else
                set_param(app.rollCtrlBlock,'enable','off')
            end
        end

        % Value changed function: altitudeCheckBox
        function altitudeCheckBoxValueChanged(app, event)
            if app.altitudeCheckBox.Value
                set_param(app.altCtrlBlock,'enable','on')
            else
                set_param(app.altCtrlBlock,'enable','off')
            end
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            saveController(app);
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            [file,path] = uigetfile(fullfile(fileparts(which('WaterChannelControl.prj')),'compositions','controllers','constantSetpoints'));
            if path~=0
                loadController(app,path,file)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.pitchSetpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.pitchSetpointEditField.ValueChangedFcn = createCallbackFcn(app, @pitchSetpointEditFieldValueChanged, true);
            app.rollSetpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.rollSetpointEditField.ValueChangedFcn = createCallbackFcn(app, @rollSetpointEditFieldValueChanged, true);
            app.altitudeSetpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.altitudeSetpointEditField.ValueChangedFcn = createCallbackFcn(app, @altitudeSetpointEditFieldValueChanged, true);
            app.pitchPropGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.pitchPropGainEditField.ValueChangedFcn = createCallbackFcn(app, @pitchPropGainEditFieldValueChanged, true);
            app.rollPropGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.rollPropGainEditField.ValueChangedFcn = createCallbackFcn(app, @rollPropGainEditFieldValueChanged, true);
            app.altitudePropGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.altitudePropGainEditField.ValueChangedFcn = createCallbackFcn(app, @altitudePropGainEditFieldValueChanged, true);
            app.pitchDerivGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.pitchDerivGainEditField.ValueChangedFcn = createCallbackFcn(app, @pitchDerivGainEditFieldValueChanged, true);
            app.rollDerivGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.rollDerivGainEditField.ValueChangedFcn = createCallbackFcn(app, @rollDerivGainEditFieldValueChanged, true);
            app.altitudeDerivGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.altitudeDerivGainEditField.ValueChangedFcn = createCallbackFcn(app, @altitudeDerivGainEditFieldValueChanged, true);
            app.pitchTimeConstEditField = uieditfield(app.UIFigure, 'numeric');
            app.pitchTimeConstEditField.ValueChangedFcn = createCallbackFcn(app, @pitchTimeConstEditFieldValueChanged, true);
            app.rollTimeConstEditField = uieditfield(app.UIFigure, 'numeric');
            app.rollTimeConstEditField.ValueChangedFcn = createCallbackFcn(app, @rollTimeConstEditFieldValueChanged, true);
            app.altitudeTimeConstEditField = uieditfield(app.UIFigure, 'numeric');
            app.altitudeTimeConstEditField.ValueChangedFcn = createCallbackFcn(app, @altitudeTimeConstEditFieldValueChanged, true);
            app.PitchLabel = uilabel(app.UIFigure);
            app.PitchLabel.Text = 'Pitch';
            app.RollLabel = uilabel(app.UIFigure);
            app.RollLabel.Text = 'Roll';
            app.AltitudeLabel = uilabel(app.UIFigure);
            app.AltitudeLabel.Text = 'Altitude';
            app.SetpointdegcmLabel = uilabel(app.UIFigure);
            app.SetpointdegcmLabel.Text = {'Setpoint'; '[deg cm]'; ''};
            app.K_pLabel = uilabel(app.UIFigure);
            app.K_pLabel.Text = 'K_p';
            app.K_dLabel = uilabel(app.UIFigure);
            app.K_dLabel.Text = 'K_d';
            app.tausLabel = uilabel(app.UIFigure);
            app.tausLabel.Text = {'tau'; '[s]'};
            app.pitchCheckBox = uicheckbox(app.UIFigure);
            app.pitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @pitchCheckBoxValueChanged, true);
            app.pitchCheckBox.Text = '';
            app.rollCheckBox = uicheckbox(app.UIFigure);
            app.rollCheckBox.ValueChangedFcn = createCallbackFcn(app, @rollCheckBoxValueChanged, true);
            app.rollCheckBox.Text = '';
            app.altitudeCheckBox = uicheckbox(app.UIFigure);
            app.altitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @altitudeCheckBoxValueChanged, true);
            app.altitudeCheckBox.Text = '';
            app.EnableLabel = uilabel(app.UIFigure);
            app.EnableLabel.Text = 'Enable';
            app.SaveSettingsButton = uibutton(app.UIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
        end
    end

    methods (Access = public)

        % Construct app
        function app = constantSetpointsControllerGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end