classdef waterChannelConfigGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaterChannelControl             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ControlTab                      matlab.ui.container.Tab
        MotorControlPanel               matlab.ui.container.Panel
        AllSliderLabel                  matlab.ui.control.Label
        AllSlider                       matlab.ui.control.Slider
        Motor3SliderLabel               matlab.ui.control.Label
        Motor3Slider                    matlab.ui.control.Slider
        Motor1SliderLabel               matlab.ui.control.Label
        Motor1Slider                    matlab.ui.control.Slider
        Motor2SliderLabel               matlab.ui.control.Label
        Motor2Slider                    matlab.ui.control.Slider
        MotorConfigurationPanel         matlab.ui.container.Panel
        MaxMotorVoltageVEditFieldLabel  matlab.ui.control.Label
        MaxMotorVoltageVEditField       matlab.ui.control.NumericEditField
        InitialCenterofMassPositionPanel  matlab.ui.container.Panel
        CoMPositioncmLabel              matlab.ui.control.Label
        CoMPositionEditFieldX           matlab.ui.control.NumericEditField
        CoMPositionEditFieldY           matlab.ui.control.NumericEditField
        CoMPositionEditFieldZ           matlab.ui.control.NumericEditField
        OperationModePanel              matlab.ui.container.Panel
        OperationModeSwitch             matlab.ui.control.ToggleSwitch
        SpeedSliderLabel                matlab.ui.control.Label
        SpeedSlider                     matlab.ui.control.Slider
        HotkeyMapPanel                  matlab.ui.container.Panel
        Button_14                       matlab.ui.control.Button
        Button_15                       matlab.ui.control.Button
        Button_16                       matlab.ui.control.Button
        Button_17                       matlab.ui.control.Button
        Button_18                       matlab.ui.control.Button
        Button_19                       matlab.ui.control.Button
        Button_20                       matlab.ui.control.Button
        Button_21                       matlab.ui.control.Button
        Button_22                       matlab.ui.control.Button
        Button_23                       matlab.ui.control.Button
        HoverfordetailLabel_2           matlab.ui.control.Label
        EscReturnButton_2               matlab.ui.control.Button
        CameraControlTab                matlab.ui.container.Tab
        SideCameraPanel                 matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldSideRow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldSideCol  matlab.ui.control.NumericEditField
        ROISizepxLabel                  matlab.ui.control.Label
        ROISizeEditFieldSideRow         matlab.ui.control.NumericEditField
        ROISizeEditFieldSideCol         matlab.ui.control.NumericEditField
        ImageDimensionspxLabel          matlab.ui.control.Label
        ImageDimensionsEditFieldSideRow  matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldSideCol  matlab.ui.control.NumericEditField
        CrosshairWidthpxLabel           matlab.ui.control.Label
        CrosshairWidthEditFieldSide     matlab.ui.control.NumericEditField
        VideoDecimationEditFieldLabel   matlab.ui.control.Label
        VideoDecimationEditFieldSide    matlab.ui.control.NumericEditField
        ImageWidthpxLabel               matlab.ui.control.Label
        ImageWidthEditFieldSide         matlab.ui.control.NumericEditField
        SwitchSide                      matlab.ui.control.Switch
        BottomCameraPanel               matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel_2  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldBottomARow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldBottomACol  matlab.ui.control.NumericEditField
        ROISizepxLabel_3                matlab.ui.control.Label
        ROISizeEditFieldBottomARow      matlab.ui.control.NumericEditField
        ROISizeEditFieldBottomACol      matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldBottomCol  matlab.ui.control.NumericEditField
        CrosshairWidthpxLabel_3         matlab.ui.control.Label
        CrosshairWidthEditFieldBottomA  matlab.ui.control.NumericEditField
        VideoDecimationEditField_2Label  matlab.ui.control.Label
        VideoDecimationEditFieldBottom  matlab.ui.control.NumericEditField
        ImageWidthpxLabel_3             matlab.ui.control.Label
        ImageWidthEditFieldBottom       matlab.ui.control.NumericEditField
        BottomCameraALabel              matlab.ui.control.Label
        BottomCameraBLabel              matlab.ui.control.Label
        ROIHoldPositionpxEditFieldBottomBCol  matlab.ui.control.NumericEditField
        ROISizeEditFieldBottomBCol      matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldLabel_4  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldBottomBRow  matlab.ui.control.NumericEditField
        ROISizepxLabel_4                matlab.ui.control.Label
        ROISizeEditFieldBottomBRow      matlab.ui.control.NumericEditField
        CrosshairWidthpxLabel_4         matlab.ui.control.Label
        CrosshairWidthEditFieldBottomB  matlab.ui.control.NumericEditField
        BothCamerasLabel                matlab.ui.control.Label
        ImageDimensionspxLabel_3        matlab.ui.control.Label
        ImageDimensionsEditFieldBottomRow  matlab.ui.control.NumericEditField
        SwitchBottomA                   matlab.ui.control.Switch
        SwitchBottomB                   matlab.ui.control.Switch
        SlantCameraPanel                matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel_3  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldSlantRow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldSlantCol  matlab.ui.control.NumericEditField
        ROISizepxLabel_2                matlab.ui.control.Label
        ROISizeEditFieldSlantRow        matlab.ui.control.NumericEditField
        ROISizeEditFieldSlantCol        matlab.ui.control.NumericEditField
        ImageDimensionspxLabel_2        matlab.ui.control.Label
        ImageDimensionsEditFieldSlantRow  matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldSlantCol  matlab.ui.control.NumericEditField
        CrosshairWidthpxLabel_2         matlab.ui.control.Label
        CrosshairWidthEditFieldSlant    matlab.ui.control.NumericEditField
        VideoDecimationEditField_3Label  matlab.ui.control.Label
        VideoDecimationEditFieldSlant   matlab.ui.control.NumericEditField
        ImageWidthpxLabel_2             matlab.ui.control.Label
        ImageWidthEditFieldSlant        matlab.ui.control.NumericEditField
        SwitchSlant                     matlab.ui.control.Switch
        CameraSelectionPanel            matlab.ui.container.Panel
        BottomCameraSwitchLabel         matlab.ui.control.Label
        BottomCameraSwitch              matlab.ui.control.ToggleSwitch
        CameraSettingsTab               matlab.ui.container.Tab
        DotPropertiesPanel              matlab.ui.container.Panel
        SideDotSeparationDistancecmEditFieldLabel  matlab.ui.control.Label
        SideDotSeparationDistancecmEditField  matlab.ui.control.NumericEditField
        SideDotPositionVectorcmLabel    matlab.ui.control.Label
        SideDotPositionVectorEditFieldX  matlab.ui.control.NumericEditField
        BottomADotSeparationDistancecmEditFieldLabel  matlab.ui.control.Label
        BottomADotSeparationDistancecmEditField  matlab.ui.control.NumericEditField
        BottomADotPositionVectorcmLabel  matlab.ui.control.Label
        BottomADotPositionVectorEditFieldX  matlab.ui.control.NumericEditField
        BottomBDotSeparationDistancecmEditFieldLabel  matlab.ui.control.Label
        BottomBDotSeparationDistancecmEditField  matlab.ui.control.NumericEditField
        BottomBDotPositionVectorcmLabel  matlab.ui.control.Label
        BottomBDotPositionValueEditFieldX  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldY  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldZ  matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldY  matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldZ  matlab.ui.control.NumericEditField
        BottomBDotPositionVectorEditFieldY  matlab.ui.control.NumericEditField
        BottomBDotPositionVectorEditFieldZ  matlab.ui.control.NumericEditField
        CameraSpecificationsPanel       matlab.ui.container.Panel
        CameraFOVdegLabel               matlab.ui.control.Label
        CameraFOVEditFieldHor           matlab.ui.control.NumericEditField
        CameraFOVEditFieldVer           matlab.ui.control.NumericEditField
        ImageErrorSettingsPanel         matlab.ui.container.Panel
        ThresholdLabel                  matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        DebounceTimesEditFieldLabel     matlab.ui.control.Label
        DebounceTimesEditField          matlab.ui.control.NumericEditField
        FaultResetButton                matlab.ui.control.Button
        CameraOrientationPanel          matlab.ui.container.Panel
        SideCameraLabel2                matlab.ui.control.Label
        BottomCameraLabel               matlab.ui.control.Label
        EulerAngledegLabel_2            matlab.ui.control.Label
        EulerAngleEditFieldBottomRoll   matlab.ui.control.NumericEditField
        EulerAngleEditFieldBottomPitch  matlab.ui.control.NumericEditField
        EulerAngleEditFieldBottomYaw    matlab.ui.control.NumericEditField
        PositioncmLabel_2               matlab.ui.control.Label
        PositionEditFieldBottomX        matlab.ui.control.NumericEditField
        SlantCameraLabel2               matlab.ui.control.Label
        EulerAngledegLabel_3            matlab.ui.control.Label
        EulerAngleEditFieldSlantRoll    matlab.ui.control.NumericEditField
        PositioncmLabel_3               matlab.ui.control.Label
        PositionEditFieldSlantX         matlab.ui.control.NumericEditField
        PositionEditFieldSlantY         matlab.ui.control.NumericEditField
        EulerAngleEditFieldSlantPitch   matlab.ui.control.NumericEditField
        PositioncmLabel                 matlab.ui.control.Label
        PositionEditFieldSideX          matlab.ui.control.NumericEditField
        PositionEditFieldSideZ          matlab.ui.control.NumericEditField
        PositionEditFieldBottomY        matlab.ui.control.NumericEditField
        PositionEditFieldBottomZ        matlab.ui.control.NumericEditField
        EulerAngleEditFieldSidePitch    matlab.ui.control.NumericEditField
        PositionEditFieldSideY          matlab.ui.control.NumericEditField
        EulerAngleEditFieldSideYaw      matlab.ui.control.NumericEditField
        EulerAngledegLabel              matlab.ui.control.Label
        EulerAngleEditFieldSideRoll     matlab.ui.control.NumericEditField
        EulerAngleEditFieldSlantYaw     matlab.ui.control.NumericEditField
        PositionEditFieldSlantZ         matlab.ui.control.NumericEditField
        GlassSpecificationPanel         matlab.ui.container.Panel
        GlassThicknesscmEditFieldLabel  matlab.ui.control.Label
        GlassThickness                  matlab.ui.control.NumericEditField
        SideCameratoGlasscmEditFieldLabel  matlab.ui.control.Label
        SideCameratoGlass               matlab.ui.control.NumericEditField
        SlantCameratoGlasscmEditFieldLabel  matlab.ui.control.Label
        SlantCameratoGlass              matlab.ui.control.NumericEditField
        BottomCameratoGlasscmEditFieldLabel  matlab.ui.control.Label
        BottomCameratoGlass             matlab.ui.control.NumericEditField
        CommunicationTab                matlab.ui.container.Tab
        UDPPTPConfigurationPanel        matlab.ui.container.Panel
        PTPOffsetEditFieldLabel         matlab.ui.control.Label
        PTPOffsetEditField              matlab.ui.control.NumericEditField
        SyncIntervalsEditFieldLabel     matlab.ui.control.Label
        SyncIntervalsEditField          matlab.ui.control.NumericEditField
        Machine3Panel                   matlab.ui.container.Panel
        PortNumberEditFieldLabel        matlab.ui.control.Label
        PortNumberEditFieldMachine3     matlab.ui.control.NumericEditField
        MinDelaysLabel                  matlab.ui.control.Label
        MinDelayEditFieldMachine3       matlab.ui.control.NumericEditField
        IPAddressEditFieldLabel         matlab.ui.control.Label
        IPAddressEditFieldMachine3      matlab.ui.control.EditField
        Machine4Panel                   matlab.ui.container.Panel
        PortNumberEditField_2Label      matlab.ui.control.Label
        PortNumberEditFieldMachine4     matlab.ui.control.NumericEditField
        MinDelaysLabel_2                matlab.ui.control.Label
        MinDelayEditFieldMachine4       matlab.ui.control.NumericEditField
        IPAddressEditField_2Label       matlab.ui.control.Label
        IPAddressEditFieldMachine4      matlab.ui.control.EditField
        Machine5Panel                   matlab.ui.container.Panel
        PortNumberEditField_3Label      matlab.ui.control.Label
        PortNumberEditFieldMachine5     matlab.ui.control.NumericEditField
        MinDelaysLabel_3                matlab.ui.control.Label
        MinDelayEditFieldMachine5       matlab.ui.control.NumericEditField
        IPAddressEditField_3Label       matlab.ui.control.Label
        IPAddressEditFieldMachine5      matlab.ui.control.EditField
        Machine2Panel                   matlab.ui.container.Panel
        AnnounceIntervalsEditFieldLabel  matlab.ui.control.Label
        AnnounceIntervalsEditField      matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        % function to set motor speeds
        function setMotorSpeedsOnKeyPress(app,directions)
            speed = app.SpeedSlider.Value;
            % Set the motor speeds in the model
            setEntry('mtr1ManulaSpdCmd_na',directions(1)*speed,'model2_cm/manualOverride','mtr1CmdOvrrd');
            setEntry('mtr2ManulaSpdCmd_na',directions(2)*speed,'model2_cm/manualOverride','mtr2CmdOvrrd');
            setEntry('mtr3ManulaSpdCmd_na',directions(3)*speed,'model2_cm/manualOverride','mtr3CmdOvrrd');
            % Update the GUI
            app.Motor1Slider.Value = directions(1)*speed;
            app.Motor2Slider.Value = directions(2)*speed;
            app.Motor3Slider.Value = directions(3)*speed;
        end
    end
    
    methods (Access = private)
        
        function visibility(app)
            if strcmp(tg2.Status,'running')
                app.SideDotSeparationDistancecmEditField.Enable = 'off';
                app.SideDotPositionVectorEditFieldX.Enable = 'off';
                app.BottomADotSeparationDistancecmEditField.Enable = 'off';
                app.BottomADotPositionVectorEditFieldX.Enable = 'off';
                app.BottomBDotSeparationDistancecmEditField.Enable = 'off';
                app.BottomBDotPositionValueEditFieldX.Enable = 'off';
                app.SideDotPositionVectorEditFieldY.Enable = 'off';
                app.SideDotPositionVectorEditFieldZ.Enable = 'off';
                app.BottomADotPositionVectorEditFieldY.Enable = 'off';
                app.BottomADotPositionVectorEditFieldZ.Enable = 'off';
                app.BottomBDotPositionVectorEditFieldY.Enable = 'off';
                app.BottomBDotPositionVectorEditFieldZ.Enable = 'off';
            else
                app.SideDotSeparationDistancecmEditField.Enable = 'on';
                app.SideDotPositionVectorEditFieldX.Enable = 'on';
                app.BottomADotSeparationDistancecmEditField.Enable = 'on';
                app.BottomADotPositionVectorEditFieldX.Enable = 'on';
                app.BottomBDotSeparationDistancecmEditField.Enable = 'on';
                app.BottomBDotPositionValueEditFieldX.Enable = 'on';
                app.SideDotPositionVectorEditFieldY.Enable = 'on';
                app.SideDotPositionVectorEditFieldZ.Enable = 'on';
                app.BottomADotPositionVectorEditFieldY.Enable = 'on';
                app.BottomADotPositionVectorEditFieldZ.Enable = 'on';
                app.BottomBDotPositionVectorEditFieldY.Enable = 'on';
                app.BottomBDotPositionVectorEditFieldZ.Enable = 'on';
            end
            
            if strcmp(tg3.Status,'running')
                app.ROISizeEditFieldSideRow.Enable = 'off';
                app.ROISizeEditFieldSideCol.Enable = 'off';
            else
                app.ROISizeEditFieldSideRow.Enable = 'on';
                app.ROISizeEditFieldSideCol.Enable = 'on';
            end
            
            if strcmp(tg4.Status,'running')
                app.ROISizeEditFieldBottomARow.Enable = 'off';
                app.ROISizeEditFieldBottomACol.Enable = 'off';
                app.ROISizeEditFieldBottomBCol.Enable = 'off';
                app.ROISizeEditFieldBottomBRow.Enable = 'off';
            else
                app.ROISizeEditFieldBottomARow.Enable = 'on';
                app.ROISizeEditFieldBottomACol.Enable = 'on';
                app.ROISizeEditFieldBottomBCol.Enable = 'on';
                app.ROISizeEditFieldBottomBRow.Enable = 'on';
            end
            
            if strcmp(tg5.Status,'running')
                app.ROISizeEditFieldSlantRow.Enable = 'off';
                app.ROISizeEditFieldSlantCol.Enable = 'off';
            else 
                app.ROISizeEditFieldSlantRow.Enable = 'on';
                app.ROISizeEditFieldSlantCol.Enable = 'on';
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             dict = Simulink.data.dictionary.open('paramsDataDictionary.sldd');
%             sect = getSection(dict,'Design Data');
            
            % Master speed control
            val = getVariable('operationMode_na');       
            switch val.Value
                case 0
                    app.OperationModeSwitch.Value = 'Automatic';
                case 1
                    app.OperationModeSwitch.Value = 'Manual';
            end
            
            % Bottom camera switch
%             val = getVariable('botVidStrmsrcSwtch_na');
            val = str2double(get_param('model4_cm/model4Switch','Value'));
            switch val
                case 1
                    app.BottomCameraSwitch.Value = 'Bottom Camera A';
                case 2
                    app.BottomCameraSwitch.Value = 'Bottom Camera B';
            end
            
            % Side camera ROI hold
            val = get_param('model3_cm/DotFinder','ROIPositionHoldEnable');       
            switch val
                case 'off'
                    app.SwitchSide.Value = 'Release';
                case 'on'
                    app.SwitchSide.Value = 'Hold';
            end
            
            % Bottom camera A ROI hold
            val = get_param('model4_cm/DotFinderA','ROIPositionHoldEnable');       
            switch val
                case 'off'
                    app.SwitchBottomA.Value = 'Release';
                case 'on'
                    app.SwitchBottomA.Value = 'Hold';
            end
            
            % Bottom camera B ROI hold
            val = get_param('model4_cm/DotFinderB','ROIPositionHoldEnable');       
            switch val
                case 'off'
                    app.SwitchBottomB.Value = 'Release';
                case 'on'
                    app.SwitchBottomB.Value = 'Hold';
            end
            
            % Slant camera ROI hold
            val = get_param('model5_cm/DotFinder','ROIPositionHoldEnable');       
            switch val
                case 'off'
                    app.SwitchSlant.Value = 'Release';
                case 'on'
                    app.SwitchSlant.Value = 'Hold';
            end
            
            % Control mode toggle switch (1 for manual, 0 for automatic)
            if app.SpeedSlider.Value == 0
                val = getVariable('manualMotorSpeed_na');
                app.SpeedSlider.Value = val.Value;
                app.SpeedSlider.Limits = findLimits('manualMotorSpeed_na');
            end
            
           
            % Max Motor Voltage
            if app.MaxMotorVoltageVEditField.Value == 0
                val = getVariable('maxOutVltg_vlt');
                app.MaxMotorVoltageVEditField.Value = val.Value;
                app.MaxMotorVoltageVEditField.Limits = findLimits('maxOutVltg_vlt');
            end
            
            % Initial COM Position
            if app.CoMPositionEditFieldX.Value == 0
                val = getVariable('initCoMPosVec_cm');
                app.CoMPositionEditFieldX.Value = val.Value(1);
                app.CoMPositionEditFieldX.Limits = findLimits('initCoMPosVec_cm');
            end
            if app.CoMPositionEditFieldY.Value == 0
                val = getVariable('initCoMPosVec_cm');
                app.CoMPositionEditFieldY.Value = val.Value(2);
                app.CoMPositionEditFieldY.Limits = findLimits('initCoMPosVec_cm');
            end
            if app.CoMPositionEditFieldZ.Value == 0
                val = getVariable('initCoMPosVec_cm');
                app.CoMPositionEditFieldZ.Value = val.Value(3);
                app.CoMPositionEditFieldZ.Limits = findLimits('initCoMPosVec_cm');
            end
            
            % Camera FOV
            if app.CameraFOVEditFieldHor.Value == 0
                val = getVariable('cameraFOV_deg');
                app.CameraFOVEditFieldHor.Value = val.Value(1);
                app.CameraFOVEditFieldHor.Limits = findLimits('cameraFOV_deg');
            end
            if app.CameraFOVEditFieldVer.Value == 0
                val = getVariable('cameraFOV_deg');
                app.CameraFOVEditFieldVer.Value = val.Value(2);
                app.CameraFOVEditFieldVer.Limits = findLimits('cameraFOV_deg');
            end
            
            % Side Camera
            if app.PositionEditFieldSideX.Value == 0
                val = getVariable('sideCamPosVec_cm');
                app.PositionEditFieldSideX.Value = val.Value(1);
                app.PositionEditFieldSideX.Limits = findLimits('sideCamPosVec_cm');
            end
            if app.PositionEditFieldSideY.Value == 0
                val = getVariable('sideCamPosVec_cm');
                app.PositionEditFieldSideY.Value = val.Value(2);
                app.PositionEditFieldSideY.Limits = findLimits('sideCamPosVec_cm');
            end
            if app.PositionEditFieldSideZ.Value == 0
                val = getVariable('sideCamPosVec_cm');
                app.PositionEditFieldSideZ.Value = val.Value(3);
                app.PositionEditFieldSideZ.Limits = findLimits('sideCamPosVec_cm');
            end
            if app.EulerAngleEditFieldSideRoll.Value == 0
                val = getVariable('sideCamEulAng_deg');
                app.EulerAngleEditFieldSideRoll.Value = val.Value(1);
                app.EulerAngleEditFieldSideRoll.Limits = findLimits('sideCamEulAng_deg');
            end
            if app.EulerAngleEditFieldSidePitch.Value == 0
                val = getVariable('sideCamEulAng_deg');
                app.EulerAngleEditFieldSidePitch.Value = val.Value(2);
                app.EulerAngleEditFieldSidePitch.Limits = findLimits('sideCamEulAng_deg');
            end
            if app.EulerAngleEditFieldSideYaw.Value == 0
                val = getVariable('sideCamEulAng_deg');
                app.EulerAngleEditFieldSideYaw.Value = val.Value(3);
                app.EulerAngleEditFieldSideYaw.Limits = findLimits('sideCamEulAng_deg');
            end
            
            % Bottom Camera
            if app.PositionEditFieldBottomX.Value == 0
                val = getVariable('botCamPosVec_cm');
                app.PositionEditFieldBottomX.Value = val.Value(1);
                app.PositionEditFieldBottomX.Limits = findLimits('botCamPosVec_cm');
            end
            if app.PositionEditFieldBottomY.Value == 0
                val = getVariable('botCamPosVec_cm');
                app.PositionEditFieldBottomY.Value = val.Value(2);
                app.PositionEditFieldBottomY.Limits = findLimits('botCamPosVec_cm');
            end
            if app.PositionEditFieldBottomZ.Value == 0
                val = getVariable('botCamPosVec_cm');
                app.PositionEditFieldBottomZ.Value = val.Value(3);
                app.PositionEditFieldBottomZ.Limits = findLimits('botCamPosVec_cm');
            end
            if app.EulerAngleEditFieldBottomRoll.Value == 0
                val = getVariable('botCamEulAng_deg');
                app.EulerAngleEditFieldBottomRoll.Value = val.Value(1);
                app.EulerAngleEditFieldBottomRoll.Limits = findLimits('botCamEulAng_deg');
            end
            if app.EulerAngleEditFieldBottomPitch.Value == 0
                val = getVariable('botCamEulAng_deg');
                app.EulerAngleEditFieldBottomPitch.Value = val.Value(2);
                app.EulerAngleEditFieldBottomPitch.Limits = findLimits('botCamEulAng_deg');
            end
            if app.EulerAngleEditFieldBottomYaw.Value == 0
                val = getVariable('botCamEulAng_deg');
                app.EulerAngleEditFieldBottomYaw.Value = val.Value(3);
                app.EulerAngleEditFieldBottomYaw.Limits = findLimits('botCamEulAng_deg');
            end
            
            % Slant Camera
            if app.PositionEditFieldSlantX.Value == 0
                val = getVariable('slntCamPosVec_cm');
                app.PositionEditFieldSlantX.Value = val.Value(1);
                app.PositionEditFieldSlantX.Limits = findLimits('slntCamPosVec_cm');
            end
            if app.PositionEditFieldSlantY.Value == 0
                val = getVariable('slntCamPosVec_cm');
                app.PositionEditFieldSlantY.Value = val.Value(2);
                app.PositionEditFieldSlantY.Limits = findLimits('slntCamPosVec_cm');
            end
            if app.PositionEditFieldSlantZ.Value == 0
                val = getVariable('slntCamPosVec_cm');
                app.PositionEditFieldSlantZ.Value = val.Value(3);
                app.PositionEditFieldSlantZ.Limits = findLimits('slntCamPosVec_cm');
            end
            if app.EulerAngleEditFieldSlantRoll.Value == 0
                val = getVariable('slntCamEulAng_deg');
                app.EulerAngleEditFieldSlantRoll.Value = val.Value(1);
                app.EulerAngleEditFieldSlantRoll.Limits = findLimits('slntCamEulAng_deg');
            end
            if app.EulerAngleEditFieldSlantPitch.Value == 0
                val = getVariable('slntCamEulAng_deg');
                app.EulerAngleEditFieldSlantPitch.Value = val.Value(2);
                app.EulerAngleEditFieldSlantPitch.Limits = findLimits('slntCamEulAng_deg');
            end
            if app.EulerAngleEditFieldSlantYaw.Value == 0
                val = getVariable('slntCamEulAng_deg');
                app.EulerAngleEditFieldSlantYaw.Value = val.Value(3);
                app.EulerAngleEditFieldSlantYaw.Limits = findLimits('slntCamEulAng_deg');
            end
            
            % Dot Properties
            if app.SideDotSeparationDistancecmEditField.Value == 0
                val = getVariable('sideDotSepDist_cm');
                app.SideDotSeparationDistancecmEditField.Value = val.Value;
                app.SideDotSeparationDistancecmEditField.Limits = findLimits('sideDotSepDist_cm');
            end
            if app.SideDotPositionVectorEditFieldX.Value == 0
                val = getVariable('sideDotPosVec_cm');
                app.SideDotPositionVectorEditFieldX.Value = val.Value(1);
                app.SideDotPositionVectorEditFieldX.Limits = findLimits('sideDotPosVec_cm');
            end
            if app.SideDotPositionVectorEditFieldY.Value == 0
                val = getVariable('sideDotPosVec_cm');
                app.SideDotPositionVectorEditFieldY.Value = val.Value(2);
                app.SideDotPositionVectorEditFieldY.Limits = findLimits('sideDotPosVec_cm');
            end
            if app.SideDotPositionVectorEditFieldZ.Value == 0
                val = getVariable('sideDotPosVec_cm');
                app.SideDotPositionVectorEditFieldZ.Value = val.Value(3);
                app.SideDotPositionVectorEditFieldZ.Limits = findLimits('sideDotPosVec_cm');
            end
            if app.BottomADotSeparationDistancecmEditField.Value == 0
                val = getVariable('botADotSepDist_cm');
                app.BottomADotSeparationDistancecmEditField.Value = val.Value;
                app.BottomADotSeparationDistancecmEditField.Limits = findLimits('botADotSepDist_cm');
            end
            if app.BottomADotPositionVectorEditFieldX.Value == 0
                val = getVariable('botADotPosVec_cm');
                app.BottomADotPositionVectorEditFieldX.Value = val.Value(1);
                app.BottomADotPositionVectorEditFieldX.Limits = findLimits('botADotPosVec_cm');
            end
            if app.BottomADotPositionVectorEditFieldY.Value == 0
                val = getVariable('botADotPosVec_cm');
                app.BottomADotPositionVectorEditFieldY.Value = val.Value(2);
                app.BottomADotPositionVectorEditFieldY.Limits = findLimits('botADotPosVec_cm');
            end
            if app.BottomADotPositionVectorEditFieldZ.Value == 0
                val = getVariable('botADotPosVec_cm');
                app.BottomADotPositionVectorEditFieldZ.Value = val.Value(3);
                app.BottomADotPositionVectorEditFieldZ.Limits = findLimits('botADotPosVec_cm');
            end
            if app.BottomBDotSeparationDistancecmEditField.Value == 0
                val = getVariable('botBDotSepDist_cm');
                app.BottomBDotSeparationDistancecmEditField.Value = val.Value;
                app.BottomBDotSeparationDistancecmEditField.Limits = findLimits('botBDotSepDist_cm');
            end
            if app.BottomBDotPositionValueEditFieldX.Value == 0
                val = getVariable('botBDotPosVec_cm');
                app.BottomBDotPositionValueEditFieldX.Value = val.Value(1);
                app.BottomBDotPositionValueEditFieldX.Limits = findLimits('botBDotPosVec_cm');
            end
            if app.BottomBDotPositionVectorEditFieldY.Value == 0
                val = getVariable('botBDotPosVec_cm');
                app.BottomBDotPositionVectorEditFieldY.Value = val.Value(2);
                app.BottomBDotPositionVectorEditFieldY.Limits = findLimits('botBDotPosVec_cm');
            end
            if app.BottomBDotPositionVectorEditFieldZ.Value == 0
                val = getVariable('botBDotPosVec_cm');
                app.BottomBDotPositionVectorEditFieldZ.Value = val.Value(3);
                app.BottomBDotPositionVectorEditFieldZ.Limits = findLimits('botBDotPosVec_cm');
            end
            
            % Glass Properties
            if app.GlassThickness.Value == 0
                val = getVariable('glassThickness_cm');
                app.GlassThickness.Value = val.Value;
                app.GlassThickness.Limits = findLimits('glassThickness_cm');
            end
            if app.SideCameratoGlass.Value == 0
                val = getVariable('sideDistancetoGlass_cm');
                app.SideCameratoGlass.Value = val.Value;
                app.SideCameratoGlass.Limits = findLimits('sideDistancetoGlass_cm');
            end
            if app.BottomCameratoGlass.Value == 0
                val = getVariable('botDistancetoGlass_cm');
                app.BottomCameratoGlass.Value = val.Value;
                app.BottomCameratoGlass.Limits = findLimits('botDistancetoGlass_cm');
            end
            if app.SlantCameratoGlass.Value == 0
                val = getVariable('slntDistancetoGlass_cm');
                app.SlantCameratoGlass.Value = val.Value;
                app.SlantCameratoGlass.Limits = findLimits('slntDistancetoGlass_cm');
            end
            
            % Image Error
            if app.ThresholdEditField.Value == 0
                val = getVariable('imagProcErrThrsh_na');
                app.ThresholdEditField.Value = val.Value;
                app.ThresholdEditField.Limits = findLimits('imagProcErrThrsh_na');
            end
            if app.DebounceTimesEditField.Value == 0
                val = getVariable('imagProcErrDbncTm_s');
                app.DebounceTimesEditField.Value = val.Value;
                app.DebounceTimesEditField.Limits = findLimits('imagProcErrDbncTm_s');
            end
            
            % Image Side Camera
            if app.ROIHoldPositionpxEditFieldSideRow.Value == 0
                val = getVariable('roiSideHldPos_px');
                app.ROIHoldPositionpxEditFieldSideRow.Value = val.Value(1);
                app.ROIHoldPositionpxEditFieldSideRow.Limits = findLimits('roiSideHldPos_px');
            end
            if app.ROIHoldPositionpxEditFieldSideCol.Value == 0
                val = getVariable('roiSideHldPos_px');
                app.ROIHoldPositionpxEditFieldSideCol.Value = val.Value(2);
                app.ROIHoldPositionpxEditFieldSideCol.Limits = findLimits('roiSideHldPos_px');
            end
            if app.ROISizeEditFieldSideRow.Value == 0
                val = getVariable('roiSideSize_px');
                app.ROISizeEditFieldSideRow.Value = val.Value(1);
                app.ROISizeEditFieldSideRow.Limits = findLimits('roiSideSize_px');
            end
            if app.ROISizeEditFieldSideCol.Value == 0
                val = getVariable('roiSideSize_px');
                app.ROISizeEditFieldSideCol.Value = val.Value(2);
                app.ROISizeEditFieldSideCol.Limits = findLimits('roiSideSize_px');
            end
            if app.ImageDimensionsEditFieldSideRow.Value == 0
                val = getVariable('sideImageSize_px');
                app.ImageDimensionsEditFieldSideRow.Value = val.Value(1);
                app.ImageDimensionsEditFieldSideRow.Limits = findLimits('sideImageSize_px');
            end
            if app.ImageDimensionsEditFieldSideCol.Value == 0
                val = getVariable('sideImageSize_px');
                app.ImageDimensionsEditFieldSideCol.Value = val.Value(2);
                app.ImageDimensionsEditFieldSideCol.Limits = findLimits('sideImageSize_px');
            end
            if app.CrosshairWidthEditFieldSide.Value == 0
                val = getVariable('sideCrsHrWdth_px');
                app.CrosshairWidthEditFieldSide.Value = val.Value;
                app.CrosshairWidthEditFieldSide.Limits = findLimits('sideCrsHrWdth_px');
            end
            if app.VideoDecimationEditFieldSide.Value == 0
                val = getVariable('sideVidStrmDec_na');
                app.VideoDecimationEditFieldSide.Value = val.Value;
                app.VideoDecimationEditFieldSide.Limits = findLimits('sideVidStrmDec_na');
            end
            if app.ImageWidthEditFieldSide.Value == 0
                val = getVariable('sideVidStrmImgWdth_px');
                app.ImageWidthEditFieldSide.Value = val.Value;
                app.ImageWidthEditFieldSide.Limits = findLimits('sideVidStrmImgWdth_px');
            end
            
            % Image Bottom Camera
            if app.ROIHoldPositionpxEditFieldBottomARow.Value == 0
                val = getVariable('roiBotAHldPos_px');
                app.ROIHoldPositionpxEditFieldBottomARow.Value = val.Value(1);
                app.ROIHoldPositionpxEditFieldBottomARow.Limits = findLimits('roiBotAHldPos_px');
            end
            if app.ROIHoldPositionpxEditFieldBottomACol.Value == 0
                val = getVariable('roiBotAHldPos_px');
                app.ROIHoldPositionpxEditFieldBottomACol.Value = val.Value(2);
                app.ROIHoldPositionpxEditFieldBottomACol.Limits = findLimits('roiBotAHldPos_px');
            end
            if app.ROIHoldPositionpxEditFieldBottomBRow.Value == 0
                val = getVariable('roiBotBHldPos_px');
                app.ROIHoldPositionpxEditFieldBottomBRow.Value = val.Value(1);
                app.ROIHoldPositionpxEditFieldBottomBRow.Limits = findLimits('roiBotBHldPos_px');
            end
            if app.ROIHoldPositionpxEditFieldBottomBCol.Value == 0
                val = getVariable('roiBotBHldPos_px');
                app.ROIHoldPositionpxEditFieldBottomBCol.Value = val.Value(2);
                app.ROIHoldPositionpxEditFieldBottomBCol.Limits = findLimits('roiBotBHldPos_px');
            end
            if app.ROISizeEditFieldBottomARow.Value == 0
                val = getVariable('roiBotASize_px');
                app.ROISizeEditFieldBottomARow.Value = val.Value(1);
                app.ROISizeEditFieldBottomARow.Limits = findLimits('roiBotASize_px');
            end
            if app.ROISizeEditFieldBottomACol.Value == 0
                val = getVariable('roiBotASize_px');
                app.ROISizeEditFieldBottomACol.Value = val.Value(2);
                app.ROISizeEditFieldBottomACol.Limits = findLimits('roiBotASize_px');
            end
            if app.ROISizeEditFieldBottomBRow.Value == 0
                val = getVariable('roiBotBSize_px');
                app.ROISizeEditFieldBottomBRow.Value = val.Value(1);
                app.ROISizeEditFieldBottomBRow.Limits = findLimits('roiBotBSize_px');
            end
            if app.ROISizeEditFieldBottomBCol.Value == 0
                val = getVariable('roiBotBSize_px');
                app.ROISizeEditFieldBottomBCol.Value = val.Value(2);
                app.ROISizeEditFieldBottomBCol.Limits = findLimits('roiBotBSize_px');
            end
            if app.ImageDimensionsEditFieldBottomRow.Value == 0
                val = getVariable('botImageSize_px');
                app.ImageDimensionsEditFieldBottomRow.Value = val.Value(1);
                app.ImageDimensionsEditFieldBottomRow.Limits = findLimits('botImageSize_px');
            end
            if app.ImageDimensionsEditFieldBottomCol.Value == 0
                val = getVariable('botImageSize_px');
                app.ImageDimensionsEditFieldBottomCol.Value = val.Value(2);
                app.ImageDimensionsEditFieldBottomCol.Limits = findLimits('botImageSize_px');
            end
            if app.CrosshairWidthEditFieldBottomA.Value == 0
                val = getVariable('botACrsHrWdth_px');
                app.CrosshairWidthEditFieldBottomA.Value = val.Value;
                app.CrosshairWidthEditFieldBottomA.Limits = findLimits('botACrsHrWdth_px');
            end
            if app.CrosshairWidthEditFieldBottomB.Value == 0
                val = getVariable('botBCrsHrWdth_px');
                app.CrosshairWidthEditFieldBottomB.Value = val.Value;
                app.CrosshairWidthEditFieldBottomB.Limits = findLimits('botBCrsHrWdth_px');
            end
            if app.VideoDecimationEditFieldBottom.Value == 0
                val = getVariable('botVidStrmDec_na');
                app.VideoDecimationEditFieldBottom.Value = val.Value;
                app.VideoDecimationEditFieldBottom.Limits = findLimits('botVidStrmDec_na');
            end
            if app.ImageWidthEditFieldBottom.Value == 0
                val = getVariable('botVidStrmImgWdth_px');
                app.ImageWidthEditFieldBottom.Value = val.Value;
                app.ImageWidthEditFieldBottom.Limits = findLimits('botVidStrmImgWdth_px');
            end
            
            % Image Slant Camera
            if app.ROIHoldPositionpxEditFieldSlantRow.Value == 0
                val = getVariable('roiSlntHldPos_px');
                app.ROIHoldPositionpxEditFieldSlantRow.Value = val.Value(1);
                app.ROIHoldPositionpxEditFieldSlantRow.Limits = findLimits('roiSlntHldPos_px');
            end
            if app.ROIHoldPositionpxEditFieldSlantCol.Value == 0
                val = getVariable('roiSlntHldPos_px');
                app.ROIHoldPositionpxEditFieldSlantCol.Value = val.Value(2);
                app.ROIHoldPositionpxEditFieldSlantCol.Limits = findLimits('roiSlntHldPos_px');
            end
            if app.ROISizeEditFieldSlantRow.Value == 0
                val = getVariable('roiSlntSize_px');
                app.ROISizeEditFieldSlantRow.Value = val.Value(1);
                app.ROISizeEditFieldSlantRow.Limits = findLimits('roiSlntSize_px');
            end
            if app.ROISizeEditFieldSlantCol.Value == 0
                val = getVariable('roiSlntSize_px');
                app.ROISizeEditFieldSlantCol.Value = val.Value(2);
                app.ROISizeEditFieldSlantCol.Limits = findLimits('roiSlntSize_px');
            end
            if app.ImageDimensionsEditFieldSlantRow.Value == 0
                val = getVariable('slntImageSize_px');
                app.ImageDimensionsEditFieldSlantRow.Value = val.Value(1);
                app.ImageDimensionsEditFieldSlantRow.Limits = findLimits('slntImageSize_px');
            end
            if app.ImageDimensionsEditFieldSlantCol.Value == 0
                val = getVariable('slntImageSize_px');
                app.ImageDimensionsEditFieldSlantCol.Value =val.Value(2);
                app.ImageDimensionsEditFieldSlantCol.Limits = findLimits('slntImageSize_px');
            end
            if app.CrosshairWidthEditFieldSlant.Value == 0
                val = getVariable('slntCrsHrWdth_px');
                app.CrosshairWidthEditFieldSlant.Value = val.Value;
                app.CrosshairWidthEditFieldSlant.Limits = findLimits('slntCrsHrWdth_px');
            end
            if app.VideoDecimationEditFieldSlant.Value == 0
                val = getVariable('slntVidStrmDec_na');
                app.VideoDecimationEditFieldSlant.Value = val.Value;
                app.VideoDecimationEditFieldSlant.Limits = findLimits('slntVidStrmDec_na');
            end
            if app.ImageWidthEditFieldSlant.Value == 0
                val = getVariable('slntVidStrmImgWdth_px');
                app.ImageWidthEditFieldSlant.Value = val.Value;
                app.ImageWidthEditFieldSlant.Limits = findLimits('slntVidStrmImgWdth_px');
            end
            
            % UDP Setup
            if app.PTPOffsetEditField.Value == 0
                val = getVariable('offsetThreshold_s');
                app.PTPOffsetEditField.Value = val.Value;
                app.PTPOffsetEditField.Limits = findLimits('offsetThreshold_s');
            end
            if app.SyncIntervalsEditField.Value == 0
                val = getVariable('syncInterval_s');
                app.SyncIntervalsEditField.Value = val.Value;
                app.SyncIntervalsEditField.Limits = findLimits('syncInterval_s');
            end
            
            % Machine 2
            if app.AnnounceIntervalsEditField.Value == 0
                val = getVariable('announceInterval_s');
                app.AnnounceIntervalsEditField.Value = val.Value;
                app.AnnounceIntervalsEditField.Limits = findLimits('announceInterval_s');
            end
            
            % Machine 3
            if app.PortNumberEditFieldMachine3.Value == 0
                val = getVariable('sideToPort_na');
                app.PortNumberEditFieldMachine3.Value = val.Value;
                app.PortNumberEditFieldMachine3.Limits = findLimits('sideToPort_na');
            end
            if app.MinDelayEditFieldMachine3.Value == 0
                val = getVariable('sideMinDelay_s');
                app.MinDelayEditFieldMachine3.Value = val.Value;
                app.MinDelayEditFieldMachine3.Limits = findLimits('sideMinDelay_s');
            end
            if isempty(app.IPAddressEditFieldMachine3.Value)
                val = getVariable('sideIPAddress_na');
                val = val.Value;
                str = strjoin(split(num2str(val)),'.');
                app.IPAddressEditFieldMachine3.Value = str;
            end
            
            % Machine 4
            if app.PortNumberEditFieldMachine4.Value == 0
                val = getVariable('bottomToPort_na');
                app.PortNumberEditFieldMachine4.Value = val.Value;
                app.PortNumberEditFieldMachine4.Limits = findLimits('bottomToPort_na');
            end
            if app.MinDelayEditFieldMachine4.Value == 0
                val = getVariable('bottomMinDelay_s');
                app.MinDelayEditFieldMachine4.Value = val.Value;
                app.MinDelayEditFieldMachine4.Limits = findLimits('bottomMinDelay_s');
            end
            if isempty(app.IPAddressEditFieldMachine4.Value)
                val = getVariable('bottomIPAddress_na');
                val = val.Value;
                str = strjoin(split(num2str(val)),'.');
                app.IPAddressEditFieldMachine4.Value = str;
            end
            
            % Machine 5
            if app.PortNumberEditFieldMachine5.Value == 0
                val = getVariable('slantToPort_na');
                app.PortNumberEditFieldMachine5.Value = val.Value;
                app.PortNumberEditFieldMachine5.Limits = findLimits('slantToPort_na');
            end
            if app.MinDelayEditFieldMachine5.Value == 0
                val = getVariable('slantMinDelay_s');
                app.MinDelayEditFieldMachine5.Value = val.Value;
                app.MinDelayEditFieldMachine5.Limits = findLimits('slantMinDelay_s');
            end
            if isempty(app.IPAddressEditFieldMachine5.Value)
                val = getVariable('slantIPAddress_na');
                val = val.Value;
                str = strjoin(split(num2str(val)),'.');
                app.IPAddressEditFieldMachine5.Value = str;
            end
        end

        % Value changed function: Motor1Slider
        function Motor1SliderValueChanged(app, event)
            value = app.Motor1Slider.Value;
            setEntry('mtr1ManulaSpdCmd_na',value,'model2_cm/manualOverride','mtr1CmdOvrrd');
        end

        % Value changed function: Motor2Slider
        function Motor2SliderValueChanged(app, event)
            value = app.Motor2Slider.Value;
            setEntry('mtr2ManulaSpdCmd_na',value,'model2_cm/manualOverride','mtr2CmdOvrrd');
        end

        % Value changed function: Motor3Slider
        function Motor3SliderValueChanged(app, event)
            value = app.Motor3Slider.Value;
            setEntry('mtr3ManulaSpdCmd_na',value,'model2_cm/manualOverride','mtr3CmdOvrrd');
        end

        % Value changed function: MaxMotorVoltageVEditField
        function MaxMotorVoltageVEditFieldValueChanged(app, event)
            value = app.MaxMotorVoltageVEditField.Value;
            setEntry('maxOutVltg_vlt',value,'model2_cm/outputCommands','maxVoltage');
        end

        % Value changed function: CoMPositionEditFieldX
        function CoMPositionEditFieldXValueChanged(app, event)
            value = app.CoMPositionEditFieldX.Value;
            setEntry('initCoMPosVec_cm',value,1,'model2_cm/Find6DOFSnell','initialCoMPositionVec_cm');
        end

        % Value changed function: AllSlider
        function AllSliderValueChanged(app, event)
            value = app.AllSlider.Value;
            setEntry('mtr1ManulaSpdCmd_na',value,'model2_cm/manualOverride','mtr1CmdOvrrd');
            setEntry('mtr2ManulaSpdCmd_na',value,'model2_cm/manualOverride','mtr2CmdOvrrd');
            setEntry('mtr3ManulaSpdCmd_na',value,'model2_cm/manualOverride','mtr3CmdOvrrd');
        end

        % Value changed function: CoMPositionEditFieldY
        function CoMPositionEditFieldYValueChanged(app, event)
            value = app.CoMPositionEditFieldY.Value;
            setEntry('initCoMPosVec_cm',value,2,'model2_cm/Find6DOFSnell','initialCoMPositionVec_cm');
        end

        % Value changed function: CoMPositionEditFieldZ
        function CoMPositionEditFieldZValueChanged(app, event)
            value = app.CoMPositionEditFieldZ.Value;
            setEntry('initCoMPosVec_cm',value,3,'model2_cm/Find6DOFSnell','initialCoMPositionVec_cm');
        end

        % Value changed function: CameraFOVEditFieldHor
        function CameraFOVEditFieldHorValueChanged(app, event)
            value = app.CameraFOVEditFieldHor.Value;
            setEntry('cameraFOV_deg',value,1,'model2_cm/Find6DOFSnell','cameraFOVAngles_deg');
        end

        % Value changed function: CameraFOVEditFieldVer
        function CameraFOVEditFieldVerValueChanged(app, event)
            value = app.CameraFOVEditFieldVer.Value;
            setEntry('cameraFOV_deg',value,2,'model2_cm/Find6DOFSnell','cameraFOVAngles_deg');
        end

        % Value changed function: PositionEditFieldSideX
        function PositionEditFieldSideXValueChanged(app, event)
            value = app.PositionEditFieldSideX.Value;
            setEntry('sideCamPosVec_cm',value,1,'model2_cm/Find6DOFSnell','sideCamPositionVec_cm');
        end

        % Value changed function: PositionEditFieldSideY
        function PositionEditFieldSideYValueChanged(app, event)
            value = app.PositionEditFieldSideY.Value;
            setEntry('sideCamPosVec_cm',value,2,'model2_cm/Find6DOFSnell','sideCamPositionVec_cm');
        end

        % Value changed function: PositionEditFieldSideZ
        function PositionEditFieldSideZValueChanged(app, event)
            value = app.PositionEditFieldSideZ.Value;
            setEntry('sideCamPosVec_cm',value,3,'model2_cm/Find6DOFSnell','sideCamPositionVec_cm');
        end

        % Value changed function: EulerAngleEditFieldSideRoll
        function EulerAngleEditFieldSideRollValueChanged(app, event)
            value = app.EulerAngleEditFieldSideRoll.Value;
            setEntry('sideCamEulAng_deg',value,1,'model2_cm/Find6DOFSnell','sideEulerAngles_deg');
        end

        % Value changed function: EulerAngleEditFieldSidePitch
        function EulerAngleEditFieldSidePitchValueChanged(app, event)
            value = app.EulerAngleEditFieldSidePitch.Value;
            setEntry('sideCamEulAng_deg',value,2,'model2_cm/Find6DOFSnell','sideEulerAngles_deg');
        end

        % Value changed function: EulerAngleEditFieldSideYaw
        function EulerAngleEditFieldSideYawValueChanged(app, event)
            value = app.EulerAngleEditFieldSideYaw.Value;
            setEntry('sideCamEulAng_deg',value,3,'model2_cm/Find6DOFSnell','sideEulerAngles_deg');
        end

        % Value changed function: PositionEditFieldBottomX
        function PositionEditFieldBottomXValueChanged(app, event)
            value = app.PositionEditFieldBottomX.Value;
            setEntry('botCamPosVec_cm',value,1,'model2_cm/Find6DOFSnell','botCamPositionVec_cm');
        end

        % Value changed function: PositionEditFieldBottomY
        function PositionEditFieldBottomYValueChanged(app, event)
            value = app.PositionEditFieldBottomY.Value;
            setEntry('botCamPosVec_cm',value,2,'model2_cm/Find6DOFSnell','botCamPositionVec_cm');
        end

        % Value changed function: PositionEditFieldBottomZ
        function PositionEditFieldBottomZValueChanged(app, event)
            value = app.PositionEditFieldBottomZ.Value;
            setEntry('botCamPosVec_cm',value,3,'model2_cm/Find6DOFSnell','botCamPositionVec_cm');
        end

        % Value changed function: EulerAngleEditFieldBottomRoll
        function EulerAngleEditFieldBottomRollValueChanged(app, event)
            value = app.EulerAngleEditFieldBottomRoll.Value;
            setEntry('botCamEulAng_deg',value,1,'model2_cm/Find6DOFSnell','botEulerAngles_deg');
        end

        % Value changed function: EulerAngleEditFieldBottomPitch
        function EulerAngleEditFieldBottomPitchValueChanged(app, event)
            value = app.EulerAngleEditFieldBottomPitch.Value;
            setEntry('botCamEulAng_deg',value,2,'model2_cm/Find6DOFSnell','botEulerAngles_deg');
        end

        % Value changed function: EulerAngleEditFieldBottomYaw
        function EulerAngleEditFieldBottomYawValueChanged(app, event)
            value = app.EulerAngleEditFieldBottomYaw.Value;
            setEntry('botCamEulAng_deg',value,3,'model2_cm/Find6DOFSnell','botEulerAngles_deg');
        end

        % Value changed function: PositionEditFieldSlantX
        function PositionEditFieldSlantXValueChanged(app, event)
            value = app.PositionEditFieldSlantX.Value;
            setEntry('slntCamPosVec_cm',value,1,'model2_cm/Find6DOFSnell','slantCamPositionVec_cm');
        end

        % Value changed function: PositionEditFieldSlantY
        function PositionEditFieldSlantYValueChanged(app, event)
            value = app.PositionEditFieldSlantY.Value;
            setEntry('slntCamPosVec_cm',value,2,'model2_cm/Find6DOFSnell','slantCamPositionVec_cm');
        end

        % Value changed function: PositionEditFieldSlantZ
        function PositionEditFieldSlantZValueChanged(app, event)
            value = app.PositionEditFieldSlantZ.Value;
            setEntry('slntCamPosVec_cm',value,3,'model2_cm/Find6DOFSnell','slantCamPositionVec_cm');
        end

        % Value changed function: EulerAngleEditFieldSlantRoll
        function EulerAngleEditFieldSlantRollValueChanged(app, event)
            value = app.EulerAngleEditFieldSlantRoll.Value;
            setEntry('slntCamEulAng_deg',value,1,'model2_cm/Find6DOFSnell','slantEulerAngles_deg');
        end

        % Value changed function: EulerAngleEditFieldSlantPitch
        function EulerAngleEditFieldSlantPitchValueChanged(app, event)
            value = app.EulerAngleEditFieldSlantPitch.Value;
            setEntry('slntCamEulAng_deg',value,2,'model2_cm/Find6DOFSnell','slantEulerAngles_deg');
        end

        % Value changed function: EulerAngleEditFieldSlantYaw
        function EulerAngleEditFieldSlantYawValueChanged(app, event)
            value = app.EulerAngleEditFieldSlantYaw.Value;
            setEntry('slntCamEulAng_deg',value,3,'model2_cm/Find6DOFSnell','slantEulerAngles_deg');
        end

        % Value changed function: SideDotSeparationDistancecmEditField
        function SideDotSeparationDistancecmEditFieldValueChanged(app, event)
            value = app.SideDotSeparationDistancecmEditField.Value;
            setEntry('sideDotSepDist_cm',value,'model2_cm/Find6DOFSnell','sideSeparation_cm');
        end

        % Value changed function: SideDotPositionVectorEditFieldX
        function SideDotPositionVectorEditFieldXValueChanged(app, event)
            value = app.SideDotPositionVectorEditFieldX.Value;
            setEntry('sideDotPosVec_cm',value,1,'model2_cm/Find6DOFSnell','sidePositionVecBF_cm');
        end

        % Value changed function: SideDotPositionVectorEditFieldY
        function SideDotPositionVectorEditFieldYValueChanged(app, event)
            value = app.SideDotPositionVectorEditFieldY.Value;
            setEntry('sideDotPosVec_cm',value,2,'model2_cm/FFind6DOFSnell','sidePositionVecBF_cm');
        end

        % Value changed function: SideDotPositionVectorEditFieldZ
        function SideDotPositionVectorEditFieldZValueChanged(app, event)
            value = app.SideDotPositionVectorEditFieldZ.Value;
            setEntry('sideDotPosVec_cm',value,3,'model2_cm/Find6DOFSnell','sidePositionVecBF_cm');
        end

        % Value changed function: 
        % BottomADotSeparationDistancecmEditField
        function BottomADotSeparationDistancecmEditFieldValueChanged(app, event)
            value = app.BottomADotSeparationDistancecmEditField.Value;
            setEntry('botADotSepDist_cm',value,'model2_cm/Find6DOFSnell','bottomASeparation_cm');
        end

        % Value changed function: BottomADotPositionVectorEditFieldX
        function BottomADotPositionVectorEditFieldXValueChanged(app, event)
            value = app.BottomADotPositionVectorEditFieldX.Value;
            setEntry('botADotPosVec_cm',value,1,'model2_cm/FFind6DOFSnell','botAPositionVecBF_cm');
        end

        % Value changed function: BottomADotPositionVectorEditFieldY
        function BottomADotPositionVectorEditFieldYValueChanged(app, event)
            value = app.BottomADotPositionVectorEditFieldY.Value;
            sesetEntry('botADotPosVec_cm',value,2,'model2_cm/Find6DOFSnell','botAPositionVecBF_cm');
        end

        % Value changed function: BottomADotPositionVectorEditFieldZ
        function BottomADotPositionVectorEditFieldZValueChanged(app, event)
            value = app.BottomADotPositionVectorEditFieldZ.Value;
            setEntry('botADotPosVec_cm',value,3,'model2_cm/Find6DOFSnell','botAPositionVecBF_cm');
        end

        % Value changed function: 
        % BottomBDotSeparationDistancecmEditField
        function BottomBDotSeparationDistancecmEditFieldValueChanged(app, event)
            value = app.BottomBDotSeparationDistancecmEditField.Value;
            setEntry('botBDotSepDist_cm',value,'model2_cm/Find6DOFSnell','bottomBSeparation_cm');
        end

        % Value changed function: BottomBDotPositionValueEditFieldX
        function BottomBDotPositionValueEditFieldXValueChanged(app, event)
            value = app.BottomBDotPositionValueEditFieldX.Value;
            setEntry('botBDotPosVec_cm',value,1,'model2_cm/Find6DOFSnell','botBPositionVecBG_cm');
        end

        % Value changed function: BottomBDotPositionVectorEditFieldY
        function BottomBDotPositionVectorEditFieldYValueChanged(app, event)
            value = app.BottomBDotPositionVectorEditFieldY.Value;
            setEntry('botBDotPosVec_cm',value,2,'model2_cm/Find6DOFSnell','botBPositionVecBG_cm');
        end

        % Value changed function: BottomBDotPositionVectorEditFieldZ
        function BottomBDotPositionVectorEditFieldZValueChanged(app, event)
            value = app.BottomBDotPositionVectorEditFieldZ.Value;
            setEntry('botBDotPosVec_cm',value,3,'model2_cm/Find6DOFSnell','botBPositionVecBG_cm');
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            setEntry('imagProcErrThrsh_na',value,'model2_cm/RisingDebounce','threshold');
        end

        % Value changed function: DebounceTimesEditField
        function DebounceTimesEditFieldValueChanged(app, event)
            value = app.DebounceTimesEditField.Value;
            setEntry('imagProcErrDbncTm_s',value,'model2_cm/RisingDebounce','timeDelay_s');
        end

        % Value changed function: ROIHoldPositionpxEditFieldSideRow
        function ROIHoldPositionpxEditFieldSideRowValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldSideRow.Value;
            setEntry('roiSideHldPos_px',value,1,'model3_cm/DotFinder','ROIPositionHoldCoordinates');
        end

        % Value changed function: ROIHoldPositionpxEditFieldSideCol
        function ROIHoldPositionpxEditFieldSideColValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldSideCol.Value;
            setEntry('roiSideHldPos_px',value,2,'model3_cm/DotFinder','ROIPositionHoldCoordinates');
        end

        % Value changed function: ROISizeEditFieldSideRow
        function ROISizeEditFieldSideRowValueChanged(app, event)
            value = app.ROISizeEditFieldSideRow.Value;
            setEntry('roiSideSize_px',value,1,'model3_cm/DotFinder','ROIDimVec_px');
        end

        % Value changed function: ROISizeEditFieldSideCol
        function ROISizeEditFieldSideColValueChanged(app, event)
            value = app.ROISizeEditFieldSideCol.Value;
            setEntry('roiSideSize_px',value,2,'model3_cm/DotFinder','ROIDimVec_px');
        end

        % Value changed function: ImageDimensionsEditFieldSideRow
        function ImageDimensionsEditFieldSideRowValueChanged(app, event)
            value = app.ImageDimensionsEditFieldSideRow.Value;
            setEntry('sideImageSize_px',value,1,'model2_cm','model3_cm/DotFinder','ImageDimensions');
        end

        % Value changed function: ImageDimensionsEditFieldSideCol
        function ImageDimensionsEditFieldSideColValueChanged(app, event)
            value = app.ImageDimensionsEditFieldSideCol.Value;
            setEntry('sideImageSize_px',value,2,'model2_cm','model3_cm/DotFinder','ImageDimensions');
        end

        % Value changed function: CrosshairWidthEditFieldSide
        function CrosshairWidthEditFieldSideValueChanged(app, event)
            value = app.CrosshairWidthEditFieldSide.Value;
            setEntry('sideCrsHrWdth_px',value,'model3_cm/DotFinder','CrosshairPixelWidth_px');
        end

        % Value changed function: VideoDecimationEditFieldSide
        function VideoDecimationEditFieldSideValueChanged(app, event)
            value = app.VideoDecimationEditFieldSide.Value;
            setEntry('sideVidStrmDec_na',value,'model3_cm/VideoStream','decimationFactor');
        end

        % Value changed function: ImageWidthEditFieldSide
        function ImageWidthEditFieldSideValueChanged(app, event)
            value = app.ImageWidthEditFieldSide.Value;
            setEntry('sideVidStrmImgWdth_px',value,'model3_cm/VideoStream','resizeFactor');
        end

        % Value changed function: ROIHoldPositionpxEditFieldBottomARow
        function ROIHoldPositionpxEditFieldBottomARowValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldBottomARow.Value;
            setEntry('roiBotAHldPos_px',value,1,'model4_cm/DotFinderA','ROIPositionHoldCoordinates');
        end

        % Value changed function: ROIHoldPositionpxEditFieldBottomACol
        function ROIHoldPositionpxEditFieldBottomAColValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldBottomACol.Value;
            setEntry('roiBotAHldPos_px',value,2,'model4_cm/DotFinderA','ROIPositionHoldCoordinates');
        end

        % Value changed function: ROIHoldPositionpxEditFieldBottomBRow
        function ROIHoldPositionpxEditFieldBottomBRowValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldBottomBRow.Value;
            setEntry('roiBotBHldPos_px',value,1,'model4_cm/DotFinderB','ROIPositionHoldCoordinates');
        end

        % Value changed function: ROIHoldPositionpxEditFieldBottomBCol
        function ROIHoldPositionpxEditFieldBottomBColValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldBottomBCol.Value;
            setEntry('roiBotBHldPos_px',value,2,'model4_cm/DotFinderB','ROIPositionHoldCoordinates');
        end

        % Value changed function: ROISizeEditFieldBottomARow
        function ROISizeEditFieldBottomARowValueChanged(app, event)
            value = app.ROISizeEditFieldBottomARow.Value;
            setEntry('roiBotASize_px',value,1,'model4_cm/DotFinderA','ROIDimVec_px');
        end

        % Value changed function: ROISizeEditFieldBottomACol
        function ROISizeEditFieldBottomAColValueChanged(app, event)
            value = app.ROISizeEditFieldBottomACol.Value;
            setEntry('roiBotASize_px',value,2,'model4_cm/DotFinderA','ROIDimVec_px');
        end

        % Value changed function: ROISizeEditFieldBottomBRow
        function ROISizeEditFieldBottomBRowValueChanged(app, event)
            value = app.ROISizeEditFieldBottomBRow.Value;
            setEntry('roiBotBSize_px',value,1,'model4_cm/DotFinderB','ROIDimVec_px');
        end

        % Value changed function: ROISizeEditFieldBottomBCol
        function ROISizeEditFieldBottomBColValueChanged(app, event)
            value = app.ROISizeEditFieldBottomBCol.Value;
            setEntry('roiBotBSize_px',value,2,'model4_cm/DotFinderB','ROIDimVec_px');
        end

        % Value changed function: ImageDimensionsEditFieldBottomRow
        function ImageDimensionsEditFieldBottomRowValueChanged(app, event)
            value = app.ImageDimensionsEditFieldBottomRow.Value;
            setEntry('botImageSize_px',value,1,'model4_cm/DotFinderA','ImageDimensions');
        end

        % Value changed function: ImageDimensionsEditFieldBottomCol
        function ImageDimensionsEditFieldBottomColValueChanged(app, event)
            value = app.ImageDimensionsEditFieldBottomCol.Value;
            setEntry('botImageSize_px',value,2,'model4_cm/DotFinderA','ImageDimensions');
        end

        % Value changed function: CrosshairWidthEditFieldBottomA
        function CrosshairWidthEditFieldBottomAValueChanged(app, event)
            value = app.CrosshairWidthEditFieldBottomA.Value;
            setEntry('botACrsHrWdth_px',value,'model4_cm/DotFinderA','CrosshairPixelWidth_px');
        end

        % Value changed function: CrosshairWidthEditFieldBottomB
        function CrosshairWidthEditFieldBottomBValueChanged(app, event)
            value = app.CrosshairWidthEditFieldBottomB.Value;
            setEntry('botBCrsHrWdth_px',value,'model4_cm/DotFinderB','CrosshairPixelWidth_px');
        end

        % Value changed function: VideoDecimationEditFieldBottom
        function VideoDecimationEditFieldBottomValueChanged(app, event)
            value = app.VideoDecimationEditFieldBottom.Value;
            setEntry('botVidStrmDec_na',value,'model4_cm/VideoStream','decimationFactor');
        end

        % Value changed function: ImageWidthEditFieldBottom
        function ImageWidthEditFieldBottomValueChanged(app, event)
            value = app.ImageWidthEditFieldBottom.Value;
            setEntry('botVidStrmImgWdth_px',value,'model4_cm/VideoStream','resizeFactor');
        end

        % Value changed function: ROIHoldPositionpxEditFieldSlantRow
        function ROIHoldPositionpxEditFieldSlantRowValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldSlantRow.Value;
            setEntry('roiSlntHldPos_px',value,1,'model5_cm/DotFinder','ROIPositionHoldCoordinates');
        end

        % Value changed function: ROIHoldPositionpxEditFieldSlantCol
        function ROIHoldPositionpxEditFieldSlantColValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldSlantCol.Value;
            setEntry('roiSlntHldPos_px',value,2,'model5_cm/DotFinder','ROIPositionHoldCoordinates');
        end

        % Value changed function: ROISizeEditFieldSlantRow
        function ROISizeEditFieldSlantRowValueChanged(app, event)
            value = app.ROISizeEditFieldSlantRow.Value;
            setEntry('roiSlntSize_px',value,1,'model5_cm/DotFinder','ROIDimVec_px');
        end

        % Value changed function: ROISizeEditFieldSlantCol
        function ROISizeEditFieldSlantColValueChanged(app, event)
            value = app.ROISizeEditFieldSlantCol.Value;
            setEntry('roiSlntSize_px',value,2,'model5_cm/DotFinder','ROIDimVec_px');
        end

        % Value changed function: ImageDimensionsEditFieldSlantRow
        function ImageDimensionsEditFieldSlantRowValueChanged(app, event)
            value = app.ImageDimensionsEditFieldSlantRow.Value;
            setEntry('slntImageSize_px',value,1,'model5_cm/DotFinder','ImageDimensions');
        end

        % Value changed function: ImageDimensionsEditFieldSlantCol
        function ImageDimensionsEditFieldSlantColValueChanged(app, event)
            value = app.ImageDimensionsEditFieldSlantCol.Value;
            setEntry('slntImageSize_px',value,2,'model5_cm/DotFinder','ImageDimensions');
        end

        % Value changed function: CrosshairWidthEditFieldSlant
        function CrosshairWidthEditFieldSlantValueChanged(app, event)
            value = app.CrosshairWidthEditFieldSlant.Value;
            setEntry('slntCrsHrWdth_px',value,'model5_cm/DotFinder','CrosshairPixelWidth_px');
        end

        % Value changed function: VideoDecimationEditFieldSlant
        function VideoDecimationEditFieldSlantValueChanged(app, event)
            value = app.VideoDecimationEditFieldSlant.Value;
            setEntry('slntVidStrmDec_na',value,'model5_cm/VideoStream','decimationFactor');
        end

        % Value changed function: ImageWidthEditFieldSlant
        function ImageWidthEditFieldSlantValueChanged(app, event)
            value = app.ImageWidthEditFieldSlant.Value;
            setEntry('slntVidStrmImgWdth_px',value,'model5_cm/VideoStream','resizeFactor');
        end

        % Value changed function: PTPOffsetEditField
        function PTPOffsetEditFieldValueChanged(app, event)
            value = app.PTPOffsetEditField.Value;
            setEntry('offsetThreshold_s',value,'model3_cm/imageUDPNode','offset_threshold');
            setEntry('offsetThreshold_s',value,'model4_cm/imageUDPNode','offset_threshold');
            setEntry('offsetThreshold_s',value,'model5_cm/imageUDPNode','offset_threshold');
        end

        % Value changed function: SyncIntervalsEditField
        function SyncIntervalsEditFieldValueChanged(app, event)
            value = app.SyncIntervalsEditField.Value;
            setEntry('syncInterval_s',value,'model2_cm/controllerUDPNode','sync_interval');
            setEntry('syncInterval_s',value,'model3_cm/imageUDPNode','sync_interval');
            setEntry('syncInterval_s',value,'model4_cm/imageUDPNode','sync_interval');
            setEntry('syncInterval_s',value,'model5_cm/imageUDPNode','sync_interval');
        end

        % Value changed function: AnnounceIntervalsEditField
        function AnnounceIntervalsEditFieldValueChanged(app, event)
            value = app.AnnounceIntervalsEditField.Value;
            setEntry('announceInterval_s',value,'model2_cm/controllerUDPNode','announce_interval');
        end

        % Value changed function: PortNumberEditFieldMachine3
        function PortNumberEditFieldMachine3ValueChanged(app, event)
            value = app.PortNumberEditFieldMachine3.Value;
            setEntry('sideToPort_na',value,'model3_cm/imageUDPNode','toPort');
        end

        % Value changed function: MinDelayEditFieldMachine3
        function MinDelayEditFieldMachine3ValueChanged(app, event)
            value = app.MinDelayEditFieldMachine3.Value;
            setEntry('sideMinDelay_s',value,'model3_cm/imageUDPNode','min_pdelay_req_interval');
        end

        % Value changed function: PortNumberEditFieldMachine4
        function PortNumberEditFieldMachine4ValueChanged(app, event)
            value = app.PortNumberEditFieldMachine4.Value;
            setEntry('bottomToPort_na',value,'model4_cm/imageUDPNode','toPort');
        end

        % Value changed function: MinDelayEditFieldMachine4
        function MinDelayEditFieldMachine4ValueChanged(app, event)
            value = app.MinDelayEditFieldMachine4.Value;
            setEntry('bottomMinDelay_s',value,'model4_cm/imageUDPNode','min_pdelay_req_interval');
        end

        % Value changed function: PortNumberEditFieldMachine5
        function PortNumberEditFieldMachine5ValueChanged(app, event)
            value = app.PortNumberEditFieldMachine5.Value;
            setEntry('slantToPort_na',value,'model5_cm/imageUDPNode','toPort');
        end

        % Value changed function: MinDelayEditFieldMachine5
        function MinDelayEditFieldMachine5ValueChanged(app, event)
            value = app.MinDelayEditFieldMachine5.Value;
            setEntry('slantMinDelay_s',value,'model5_cm/imageUDPNode','min_pdelay_req_interval');
        end

        % Value changed function: IPAddressEditFieldMachine3
        function IPAddressEditFieldMachine3ValueChanged(app, event)
            value = app.IPAddressEditFieldMachine3.Value;
            str = str2num(strrep(value,'.',' '));
            setEntry('sideIPAddress_na',str(1),1);
            setEntry('sideIPAddress_na',str(2),2);
            setEntry('sideIPAddress_na',str(3),3);
            setEntry('sideIPAddress_na',str(4),4);
        end

        % Value changed function: IPAddressEditFieldMachine4
        function IPAddressEditFieldMachine4ValueChanged(app, event)
            value = app.IPAddressEditFieldMachine4.Value;
            str = str2num(strrep(value,'.',' '));
            setEntry('bottomIPAddress_na',str(1),1);
            setEntry('bottomIPAddress_na',str(2),2);
            setEntry('bottomIPAddress_na',str(3),3);
            setEntry('bottomIPAddress_na',str(4),4);
        end

        % Value changed function: IPAddressEditFieldMachine5
        function IPAddressEditFieldMachine5ValueChanged(app, event)
            value = app.IPAddressEditFieldMachine5.Value;
            str = str2num(strrep(value,'.',' '));
            setEntry('slantIPAddress_na',str(1),1);
            setEntry('slantIPAddress_na',str(2),2);
            setEntry('slantIPAddress_na',str(3),3);
            setEntry('slantIPAddress_na',str(4),4);
        end

        % Value changed function: BottomCameraSwitch
        function BottomCameraSwitchValueChanged(app, event)
            value = app.BottomCameraSwitch.Value;
            if strcmp(value, 'Bottom Camera A')
                setEntry('botVidStrmsrcSwtch_na',1,'model4_cm/model4Switch','Value');
            elseif strcmp(value, 'Bottom Camera B')
                setEntry('botVidStrmsrcSwtch_na',2,'model4_cm/model4Switch','Value');
            end
        end

        % Value changed function: SwitchSide
        function SwitchSideValueChanged(app, event)
            value = app.SwitchSide.Value;
            if strcmp(value, 'Hold')
                set_param('model3_cm/DotFinder','ROIPositionHoldEnable','on');
            elseif strcmp(value, 'Release')
                set_param('model3_cm/DotFinder','ROIPositionHoldEnable','off');
            end
        end

        % Value changed function: SwitchBottomA
        function SwitchBottomAValueChanged(app, event)
            value = app.SwitchBottomA.Value;
            if strcmp(value, 'Hold')
                set_param('model4_cm/DotFinderA','ROIPositionHoldEnable','on');
            elseif strcmp(value, 'Release')
                set_param('model4_cm/DotFinderA','ROIPositionHoldEnable','off');
            end
        end

        % Value changed function: SwitchBottomB
        function SwitchBottomBValueChanged(app, event)
            value = app.SwitchBottomB.Value;
            if strcmp(value, 'Hold')
                set_param('model4_cm/DotFinderB','ROIPositionHoldEnable','on');
            elseif strcmp(value, 'Release')
                set_param('model4_cm/DotFinderB','ROIPositionHoldEnable','off');
            end
        end

        % Value changed function: SwitchSlant
        function SwitchSlantValueChanged(app, event)
            value = app.SwitchSlant.Value;
            if strcmp(value, 'Hold')
                set_param('model5_cm/DotFinder','ROIPositionHoldEnable','on');
            elseif strcmp(value, 'Release')
                set_param('model5_cm/DotFinder','ROIPositionHoldEnable','off');
            end
        end

        % Value changed function: OperationModeSwitch
        function OperationModeSwitchValueChanged(app, event)
            value = app.OperationModeSwitch.Value;
            if strcmp(value, 'Manual')
                setEntry('operationMode_na',1,'model2_cm/manualOverride','enbl');
            elseif strcmp(value, 'Automatic')
                setEntry('operationMode_na',0,'model2_cm/manualOverride','enbl');
            end
        end

        % Key press function: WaterChannelControl
        function WaterChannelControlKeyPress(app, event)
            key = event.Key;
            switch key
                case {'numpad1','end'} 
                    setMotorSpeedsOnKeyPress(app,[0 -1 0])

                case {'numpad4','leftarrow'} 
                    setMotorSpeedsOnKeyPress(app,[0 1 0])
                    
                case {'numpad2','downarrow'} % Aft tether in
                    setMotorSpeedsOnKeyPress(app,[0 0 -1])
                    
                case {'numpad5','clear'} % Aft tether out
                    setMotorSpeedsOnKeyPress(app,[0 0 1])
                    
                case {'numpad3','pagedown'} % Port tether in
                    setMotorSpeedsOnKeyPress(app,[-1 0 0])
                    
                case {'numpad6','rightarrow'} % Port tether out
                    setMotorSpeedsOnKeyPress(app,[1 0 0])
                    
                case {'numpad7','home'} % Roll to left (port down)
                    setMotorSpeedsOnKeyPress(app, [1 -1 0])
                    
                case {'numpad9','pageup'} % Roll to right (starboard down)
                    setMotorSpeedsOnKeyPress(app, [-1 1 0])
                    
                case {'numpad8','uparrow'} % All tethers in
                    setMotorSpeedsOnKeyPress(app, [-1 -1 -1])
                    app.AllSlider.Value = -app.SpeedSlider.Value;
                    
                case {'divide'} % All tethers out
                    setMotorSpeedsOnKeyPress(app, [1 1 1])
                    app.AllSlider.Value = app.SpeedSlider.Value;
                    
                case {'escape','return'}
                    setMotorSpeedsOnKeyPress(app, [0 0 0])
                    app.OperationModeSwitch.Value = 'Manual';
                    app.AllSlider.Value = 0;
                otherwise
                    % Do nothing
            end
        end

        % Button pushed function: FaultResetButton
        function FaultResetButtonPushed(app, event)
            set_param('model2_cm/faultReset','Value','true')
            pause(0.1)
            set_param('model2_cm/faultReset','Value','false')
        end

        % Value changed function: GlassThickness
        function GlassThicknessValueChanged(app, event)
            value = app.GlassThickness.Value;
            setEntry('glassThickness_cm',value,'model2_cm/Find6DOFSnell','glassThickness_cm');
        end

        % Value changed function: SideCameratoGlass
        function SideCameratoGlassValueChanged(app, event)
            value = app.SideCameratoGlass.Value;
            setEntry('sideDistancetoGlass_cm',value,'model2_cm/Find6DOFSnell','sideCamDistToGlass_cm');
        end

        % Value changed function: BottomCameratoGlass
        function BottomCameratoGlassValueChanged(app, event)
            value = app.BottomCameratoGlass.Value;
            setEntry('botDistancetoGlass_cm',value,'model2_cm/Find6DOFSnell','botCamDistToGlass_cm');
        end

        % Value changed function: SlantCameratoGlass
        function SlantCameratoGlassValueChanged(app, event)
            value = app.SlantCameratoGlass.Value;
            setEntry('slntDistancetoGlass_cm',value,'model2_cm/Find6DOFSnell','slantCamDistToGlass_cm');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaterChannelControl = uifigure;
            app.WaterChannelControl.Name = 'UI Figure';
            app.WaterChannelControl.KeyPressFcn = createCallbackFcn(app, @WaterChannelControlKeyPress, true);
            app.TabGroup = uitabgroup(app.WaterChannelControl);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.MotorControlPanel = uipanel(app.ControlTab);
            app.MotorControlPanel.Title = 'Motor Control';
            app.AllSliderLabel = uilabel(app.MotorControlPanel);
            app.AllSliderLabel.Text = 'All';
            app.AllSlider = uislider(app.MotorControlPanel);
            app.AllSlider.Limits = [-1 1];
            app.AllSlider.ValueChangedFcn = createCallbackFcn(app, @AllSliderValueChanged, true);
            app.Motor3SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor3SliderLabel.Text = 'Motor 3';
            app.Motor3Slider = uislider(app.MotorControlPanel);
            app.Motor3Slider.Limits = [-1 1];
            app.Motor3Slider.ValueChangedFcn = createCallbackFcn(app, @Motor3SliderValueChanged, true);
            app.Motor1SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor1SliderLabel.Text = 'Motor 1';
            app.Motor1Slider = uislider(app.MotorControlPanel);
            app.Motor1Slider.Limits = [-1 1];
            app.Motor1Slider.ValueChangedFcn = createCallbackFcn(app, @Motor1SliderValueChanged, true);
            app.Motor2SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor2SliderLabel.Text = 'Motor 2';
            app.Motor2Slider = uislider(app.MotorControlPanel);
            app.Motor2Slider.Limits = [-1 1];
            app.Motor2Slider.ValueChangedFcn = createCallbackFcn(app, @Motor2SliderValueChanged, true);
            app.MotorConfigurationPanel = uipanel(app.ControlTab);
            app.MotorConfigurationPanel.Title = 'Motor Configuration';
            app.MaxMotorVoltageVEditFieldLabel = uilabel(app.MotorConfigurationPanel);
            app.MaxMotorVoltageVEditFieldLabel.Text = 'Max Motor Voltage (V)';
            app.MaxMotorVoltageVEditField = uieditfield(app.MotorConfigurationPanel, 'numeric');
            app.MaxMotorVoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @MaxMotorVoltageVEditFieldValueChanged, true);
            app.InitialCenterofMassPositionPanel = uipanel(app.ControlTab);
            app.InitialCenterofMassPositionPanel.Title = 'Initial Center of Mass Position';
            app.CoMPositioncmLabel = uilabel(app.InitialCenterofMassPositionPanel);
            app.CoMPositioncmLabel.Text = 'CoM Position (cm)';
            app.CoMPositionEditFieldX = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldX.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldXValueChanged, true);
            app.CoMPositionEditFieldY = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldY.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldYValueChanged, true);
            app.CoMPositionEditFieldZ = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldZValueChanged, true);
            app.OperationModePanel = uipanel(app.ControlTab);
            app.OperationModePanel.Title = 'Operation Mode';
            app.OperationModeSwitch = uiswitch(app.OperationModePanel, 'toggle');
            app.OperationModeSwitch.Items = {'Manual', 'Automatic'};
            app.OperationModeSwitch.ValueChangedFcn = createCallbackFcn(app, @OperationModeSwitchValueChanged, true);
            app.OperationModeSwitch.Value = 'Automatic';
            app.SpeedSliderLabel = uilabel(app.ControlTab);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.ControlTab);
            app.SpeedSlider.Limits = [0 1];
            app.SpeedSlider.MajorTicks = [0 1];
            app.SpeedSlider.MajorTickLabels = {'Slow', 'Fast'};
            app.HotkeyMapPanel = uipanel(app.ControlTab);
            app.HotkeyMapPanel.Title = 'Hotkey Map';
            app.Button_14 = uibutton(app.HotkeyMapPanel, 'push');
            app.Button_14.Enable = 'off';
            app.Button_14.Tooltip = {'Port tether in'};
            app.Button_14.Text = '1';
            app.Button_15 = uibutton(app.HotkeyMapPanel, 'push');
            app.Button_15.Enable = 'off';
            app.Button_15.Tooltip = {'Center tether in'};
            app.Button_15.Text = '2';
            app.Button_16 = uibutton(app.HotkeyMapPanel, 'push');
            app.Button_16.Enable = 'off';
            app.Button_16.Tooltip = {'Starboard tether in'};
            app.Button_16.Text = '3';
            app.Button_17 = uibutton(app.HotkeyMapPanel, 'push');
            app.Button_17.Enable = 'off';
            app.Button_17.Tooltip = {'Port tether out'};
            app.Button_17.Text = '4';
            app.Button_18 = uibutton(app.HotkeyMapPanel, 'push');
            app.Button_18.Enable = 'off';
            app.Button_18.Tooltip = {'Center tether out'};
            app.Button_18.Text = '5';
            app.Button_19 = uibutton(app.HotkeyMapPanel, 'push');
            app.Button_19.Enable = 'off';
            app.Button_19.Tooltip = {'Starboard tether out'};
            app.Button_19.Text = '6';
            app.Button_20 = uibutton(app.HotkeyMapPanel, 'push');
            app.Button_20.Enable = 'off';
            app.Button_20.Tooltip = {'Port in'; ' starboard out:'; 'Roll port side down'};
            app.Button_20.Text = '7';
            app.Button_21 = uibutton(app.HotkeyMapPanel, 'push');
            app.Button_21.Enable = 'off';
            app.Button_21.Tooltip = {'All tethers in'};
            app.Button_21.Text = '8';
            app.Button_22 = uibutton(app.HotkeyMapPanel, 'push');
            app.Button_22.Enable = 'off';
            app.Button_22.Tooltip = {'Port out'; ' starboard in:'; 'Roll starboard side down'};
            app.Button_22.Text = '9';
            app.Button_23 = uibutton(app.HotkeyMapPanel, 'push');
            app.Button_23.Enable = 'off';
            app.Button_23.Tooltip = {'All tethers out'};
            app.Button_23.Text = '/';
            app.HoverfordetailLabel_2 = uilabel(app.HotkeyMapPanel);
            app.HoverfordetailLabel_2.Text = 'Hover for detail';
            app.EscReturnButton_2 = uibutton(app.HotkeyMapPanel, 'push');
            app.EscReturnButton_2.Enable = 'off';
            app.EscReturnButton_2.Tooltip = {'All stop'};
            app.EscReturnButton_2.Text = 'Esc, Return';
            app.CameraControlTab = uitab(app.TabGroup);
            app.CameraControlTab.Title = 'Camera Control';
            app.SideCameraPanel = uipanel(app.CameraControlTab);
            app.SideCameraPanel.Title = 'Side Camera';
            app.ROIHoldPositionpxEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSideRow.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldSideRowValueChanged, true);
            app.ROIHoldPositionpxEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSideCol.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldSideColValueChanged, true);
            app.ROISizepxLabel = uilabel(app.SideCameraPanel);
            app.ROISizepxLabel.Text = 'ROI Size (px)';
            app.ROISizeEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROISizeEditFieldSideRow.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldSideRowValueChanged, true);
            app.ROISizeEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROISizeEditFieldSideCol.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldSideColValueChanged, true);
            app.ImageDimensionspxLabel = uilabel(app.SideCameraPanel);
            app.ImageDimensionspxLabel.Text = 'Image Dimensions (px)';
            app.ImageDimensionsEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSideRow.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldSideRowValueChanged, true);
            app.ImageDimensionsEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSideCol.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldSideColValueChanged, true);
            app.CrosshairWidthpxLabel = uilabel(app.SideCameraPanel);
            app.CrosshairWidthpxLabel.Text = 'Crosshair Width (px)';
            app.CrosshairWidthEditFieldSide = uieditfield(app.SideCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldSide.ValueChangedFcn = createCallbackFcn(app, @CrosshairWidthEditFieldSideValueChanged, true);
            app.VideoDecimationEditFieldLabel = uilabel(app.SideCameraPanel);
            app.VideoDecimationEditFieldLabel.Text = 'Video Decimation';
            app.VideoDecimationEditFieldSide = uieditfield(app.SideCameraPanel, 'numeric');
            app.VideoDecimationEditFieldSide.ValueChangedFcn = createCallbackFcn(app, @VideoDecimationEditFieldSideValueChanged, true);
            app.ImageWidthpxLabel = uilabel(app.SideCameraPanel);
            app.ImageWidthpxLabel.Text = 'Image Width (px)';
            app.ImageWidthEditFieldSide = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageWidthEditFieldSide.ValueChangedFcn = createCallbackFcn(app, @ImageWidthEditFieldSideValueChanged, true);
            app.SwitchSide = uiswitch(app.SideCameraPanel, 'slider');
            app.SwitchSide.Items = {'Hold', 'Release'};
            app.SwitchSide.ValueChangedFcn = createCallbackFcn(app, @SwitchSideValueChanged, true);
            app.SwitchSide.Value = 'Hold';
            app.BottomCameraPanel = uipanel(app.CameraControlTab);
            app.BottomCameraPanel.Title = 'Bottom Camera';
            app.ROIHoldPositionpxEditFieldLabel_2 = uilabel(app.BottomCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel_2.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldBottomARow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomARow.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldBottomARowValueChanged, true);
            app.ROIHoldPositionpxEditFieldBottomACol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomACol.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldBottomAColValueChanged, true);
            app.ROISizepxLabel_3 = uilabel(app.BottomCameraPanel);
            app.ROISizepxLabel_3.Text = 'ROI Size (px)';
            app.ROISizeEditFieldBottomARow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomARow.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldBottomARowValueChanged, true);
            app.ROISizeEditFieldBottomACol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomACol.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldBottomAColValueChanged, true);
            app.ImageDimensionsEditFieldBottomCol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldBottomCol.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldBottomColValueChanged, true);
            app.CrosshairWidthpxLabel_3 = uilabel(app.BottomCameraPanel);
            app.CrosshairWidthpxLabel_3.Text = 'Crosshair Width (px)';
            app.CrosshairWidthEditFieldBottomA = uieditfield(app.BottomCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldBottomA.ValueChangedFcn = createCallbackFcn(app, @CrosshairWidthEditFieldBottomAValueChanged, true);
            app.VideoDecimationEditField_2Label = uilabel(app.BottomCameraPanel);
            app.VideoDecimationEditField_2Label.Text = 'Video Decimation';
            app.VideoDecimationEditFieldBottom = uieditfield(app.BottomCameraPanel, 'numeric');
            app.VideoDecimationEditFieldBottom.ValueChangedFcn = createCallbackFcn(app, @VideoDecimationEditFieldBottomValueChanged, true);
            app.ImageWidthpxLabel_3 = uilabel(app.BottomCameraPanel);
            app.ImageWidthpxLabel_3.Text = 'Image Width (px)';
            app.ImageWidthEditFieldBottom = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ImageWidthEditFieldBottom.ValueChangedFcn = createCallbackFcn(app, @ImageWidthEditFieldBottomValueChanged, true);
            app.BottomCameraALabel = uilabel(app.BottomCameraPanel);
            app.BottomCameraALabel.Text = 'Bottom Camera A';
            app.BottomCameraBLabel = uilabel(app.BottomCameraPanel);
            app.BottomCameraBLabel.Text = 'Bottom Camera B';
            app.ROIHoldPositionpxEditFieldBottomBCol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomBCol.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldBottomBColValueChanged, true);
            app.ROISizeEditFieldBottomBCol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomBCol.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldBottomBColValueChanged, true);
            app.ROIHoldPositionpxEditFieldLabel_4 = uilabel(app.BottomCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel_4.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldBottomBRow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomBRow.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldBottomBRowValueChanged, true);
            app.ROISizepxLabel_4 = uilabel(app.BottomCameraPanel);
            app.ROISizepxLabel_4.Text = 'ROI Size (px)';
            app.ROISizeEditFieldBottomBRow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomBRow.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldBottomBRowValueChanged, true);
            app.CrosshairWidthpxLabel_4 = uilabel(app.BottomCameraPanel);
            app.CrosshairWidthpxLabel_4.Text = 'Crosshair Width (px)';
            app.CrosshairWidthEditFieldBottomB = uieditfield(app.BottomCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldBottomB.ValueChangedFcn = createCallbackFcn(app, @CrosshairWidthEditFieldBottomBValueChanged, true);
            app.BothCamerasLabel = uilabel(app.BottomCameraPanel);
            app.BothCamerasLabel.Text = 'Both Cameras';
            app.ImageDimensionspxLabel_3 = uilabel(app.BottomCameraPanel);
            app.ImageDimensionspxLabel_3.Text = 'Image Dimensions (px)';
            app.ImageDimensionsEditFieldBottomRow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldBottomRow.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldBottomRowValueChanged, true);
            app.SwitchBottomA = uiswitch(app.BottomCameraPanel, 'slider');
            app.SwitchBottomA.Items = {'Hold', 'Release'};
            app.SwitchBottomA.ValueChangedFcn = createCallbackFcn(app, @SwitchBottomAValueChanged, true);
            app.SwitchBottomA.Value = 'Hold';
            app.SwitchBottomB = uiswitch(app.BottomCameraPanel, 'slider');
            app.SwitchBottomB.Items = {'Hold', 'Release'};
            app.SwitchBottomB.ValueChangedFcn = createCallbackFcn(app, @SwitchBottomBValueChanged, true);
            app.SwitchBottomB.Value = 'Hold';
            app.SlantCameraPanel = uipanel(app.CameraControlTab);
            app.SlantCameraPanel.Title = 'Slant Camera';
            app.ROIHoldPositionpxEditFieldLabel_3 = uilabel(app.SlantCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel_3.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldSlantRow = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSlantRow.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldSlantRowValueChanged, true);
            app.ROIHoldPositionpxEditFieldSlantCol = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSlantCol.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldSlantColValueChanged, true);
            app.ROISizepxLabel_2 = uilabel(app.SlantCameraPanel);
            app.ROISizepxLabel_2.Text = 'ROI Size (px)';
            app.ROISizeEditFieldSlantRow = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROISizeEditFieldSlantRow.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldSlantRowValueChanged, true);
            app.ROISizeEditFieldSlantCol = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROISizeEditFieldSlantCol.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldSlantColValueChanged, true);
            app.ImageDimensionspxLabel_2 = uilabel(app.SlantCameraPanel);
            app.ImageDimensionspxLabel_2.Text = 'Image Dimensions (px)';
            app.ImageDimensionsEditFieldSlantRow = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSlantRow.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldSlantRowValueChanged, true);
            app.ImageDimensionsEditFieldSlantCol = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSlantCol.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldSlantColValueChanged, true);
            app.CrosshairWidthpxLabel_2 = uilabel(app.SlantCameraPanel);
            app.CrosshairWidthpxLabel_2.Text = 'Crosshair Width (px)';
            app.CrosshairWidthEditFieldSlant = uieditfield(app.SlantCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldSlant.ValueChangedFcn = createCallbackFcn(app, @CrosshairWidthEditFieldSlantValueChanged, true);
            app.VideoDecimationEditField_3Label = uilabel(app.SlantCameraPanel);
            app.VideoDecimationEditField_3Label.Text = 'Video Decimation';
            app.VideoDecimationEditFieldSlant = uieditfield(app.SlantCameraPanel, 'numeric');
            app.VideoDecimationEditFieldSlant.ValueChangedFcn = createCallbackFcn(app, @VideoDecimationEditFieldSlantValueChanged, true);
            app.ImageWidthpxLabel_2 = uilabel(app.SlantCameraPanel);
            app.ImageWidthpxLabel_2.Text = 'Image Width (px)';
            app.ImageWidthEditFieldSlant = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ImageWidthEditFieldSlant.ValueChangedFcn = createCallbackFcn(app, @ImageWidthEditFieldSlantValueChanged, true);
            app.SwitchSlant = uiswitch(app.SlantCameraPanel, 'slider');
            app.SwitchSlant.Items = {'Hold', 'Release'};
            app.SwitchSlant.ValueChangedFcn = createCallbackFcn(app, @SwitchSlantValueChanged, true);
            app.SwitchSlant.Value = 'Hold';
            app.CameraSelectionPanel = uipanel(app.CameraControlTab);
            app.CameraSelectionPanel.Title = 'Camera Selection';
            app.BottomCameraSwitchLabel = uilabel(app.CameraSelectionPanel);
            app.BottomCameraSwitchLabel.Text = {'Bottom Camera'; 'Switch'};
            app.BottomCameraSwitch = uiswitch(app.CameraSelectionPanel, 'toggle');
            app.BottomCameraSwitch.Items = {'Bottom Camera B', 'Bottom Camera A'};
            app.BottomCameraSwitch.ValueChangedFcn = createCallbackFcn(app, @BottomCameraSwitchValueChanged, true);
            app.BottomCameraSwitch.Value = 'Bottom Camera A';
            app.CameraSettingsTab = uitab(app.TabGroup);
            app.CameraSettingsTab.Title = 'Camera Settings';
            app.DotPropertiesPanel = uipanel(app.CameraSettingsTab);
            app.DotPropertiesPanel.Title = 'Dot Properties';
            app.SideDotSeparationDistancecmEditFieldLabel = uilabel(app.DotPropertiesPanel);
            app.SideDotSeparationDistancecmEditFieldLabel.Text = 'Side Dot Separation Distance (cm)';
            app.SideDotSeparationDistancecmEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotSeparationDistancecmEditField.ValueChangedFcn = createCallbackFcn(app, @SideDotSeparationDistancecmEditFieldValueChanged, true);
            app.SideDotPositionVectorcmLabel = uilabel(app.DotPropertiesPanel);
            app.SideDotPositionVectorcmLabel.Text = 'Side Dot Position Vector (cm)';
            app.SideDotPositionVectorEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldX.ValueChangedFcn = createCallbackFcn(app, @SideDotPositionVectorEditFieldXValueChanged, true);
            app.BottomADotSeparationDistancecmEditFieldLabel = uilabel(app.DotPropertiesPanel);
            app.BottomADotSeparationDistancecmEditFieldLabel.Text = 'Bottom A Dot Separation Distance (cm)';
            app.BottomADotSeparationDistancecmEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotSeparationDistancecmEditField.ValueChangedFcn = createCallbackFcn(app, @BottomADotSeparationDistancecmEditFieldValueChanged, true);
            app.BottomADotPositionVectorcmLabel = uilabel(app.DotPropertiesPanel);
            app.BottomADotPositionVectorcmLabel.Text = 'Bottom A Dot Position Vector (cm)';
            app.BottomADotPositionVectorEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldX.ValueChangedFcn = createCallbackFcn(app, @BottomADotPositionVectorEditFieldXValueChanged, true);
            app.BottomBDotSeparationDistancecmEditFieldLabel = uilabel(app.DotPropertiesPanel);
            app.BottomBDotSeparationDistancecmEditFieldLabel.Text = 'Bottom B Dot Separation Distance (cm)';
            app.BottomBDotSeparationDistancecmEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotSeparationDistancecmEditField.ValueChangedFcn = createCallbackFcn(app, @BottomBDotSeparationDistancecmEditFieldValueChanged, true);
            app.BottomBDotPositionVectorcmLabel = uilabel(app.DotPropertiesPanel);
            app.BottomBDotPositionVectorcmLabel.Text = 'Bottom B Dot Position Vector (cm)';
            app.BottomBDotPositionValueEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionValueEditFieldX.ValueChangedFcn = createCallbackFcn(app, @BottomBDotPositionValueEditFieldXValueChanged, true);
            app.SideDotPositionVectorEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldY.ValueChangedFcn = createCallbackFcn(app, @SideDotPositionVectorEditFieldYValueChanged, true);
            app.SideDotPositionVectorEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @SideDotPositionVectorEditFieldZValueChanged, true);
            app.BottomADotPositionVectorEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldY.ValueChangedFcn = createCallbackFcn(app, @BottomADotPositionVectorEditFieldYValueChanged, true);
            app.BottomADotPositionVectorEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @BottomADotPositionVectorEditFieldZValueChanged, true);
            app.BottomBDotPositionVectorEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionVectorEditFieldY.ValueChangedFcn = createCallbackFcn(app, @BottomBDotPositionVectorEditFieldYValueChanged, true);
            app.BottomBDotPositionVectorEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionVectorEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @BottomBDotPositionVectorEditFieldZValueChanged, true);
            app.CameraSpecificationsPanel = uipanel(app.CameraSettingsTab);
            app.CameraSpecificationsPanel.Title = 'Camera Specifications';
            app.CameraFOVdegLabel = uilabel(app.CameraSpecificationsPanel);
            app.CameraFOVdegLabel.Text = 'Camera FOV (deg)';
            app.CameraFOVEditFieldHor = uieditfield(app.CameraSpecificationsPanel, 'numeric');
            app.CameraFOVEditFieldHor.ValueChangedFcn = createCallbackFcn(app, @CameraFOVEditFieldHorValueChanged, true);
            app.CameraFOVEditFieldVer = uieditfield(app.CameraSpecificationsPanel, 'numeric');
            app.CameraFOVEditFieldVer.ValueChangedFcn = createCallbackFcn(app, @CameraFOVEditFieldVerValueChanged, true);
            app.ImageErrorSettingsPanel = uipanel(app.CameraSettingsTab);
            app.ImageErrorSettingsPanel.Title = 'Image Error Settings';
            app.ThresholdLabel = uilabel(app.ImageErrorSettingsPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ImageErrorSettingsPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.DebounceTimesEditFieldLabel = uilabel(app.ImageErrorSettingsPanel);
            app.DebounceTimesEditFieldLabel.Text = 'Debounce Time (s)';
            app.DebounceTimesEditField = uieditfield(app.ImageErrorSettingsPanel, 'numeric');
            app.DebounceTimesEditField.ValueChangedFcn = createCallbackFcn(app, @DebounceTimesEditFieldValueChanged, true);
            app.FaultResetButton = uibutton(app.ImageErrorSettingsPanel, 'push');
            app.FaultResetButton.ButtonPushedFcn = createCallbackFcn(app, @FaultResetButtonPushed, true);
            app.FaultResetButton.Text = 'Fault Reset';
            app.CameraOrientationPanel = uipanel(app.CameraSettingsTab);
            app.CameraOrientationPanel.Title = 'Camera Orientation';
            app.SideCameraLabel2 = uilabel(app.CameraOrientationPanel);
            app.SideCameraLabel2.Text = 'Side Camera';
            app.BottomCameraLabel = uilabel(app.CameraOrientationPanel);
            app.BottomCameraLabel.Text = 'Bottom Camera';
            app.EulerAngledegLabel_2 = uilabel(app.CameraOrientationPanel);
            app.EulerAngledegLabel_2.Text = 'Euler Angle (deg)';
            app.EulerAngleEditFieldBottomRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomRoll.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldBottomRollValueChanged, true);
            app.EulerAngleEditFieldBottomPitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomPitch.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldBottomPitchValueChanged, true);
            app.EulerAngleEditFieldBottomYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomYaw.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldBottomYawValueChanged, true);
            app.PositioncmLabel_2 = uilabel(app.CameraOrientationPanel);
            app.PositioncmLabel_2.Text = 'Position (cm)';
            app.PositionEditFieldBottomX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomX.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldBottomXValueChanged, true);
            app.SlantCameraLabel2 = uilabel(app.CameraOrientationPanel);
            app.SlantCameraLabel2.Text = 'Slant Camera';
            app.EulerAngledegLabel_3 = uilabel(app.CameraOrientationPanel);
            app.EulerAngledegLabel_3.Text = 'Euler Angle (deg)';
            app.EulerAngleEditFieldSlantRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantRoll.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSlantRollValueChanged, true);
            app.PositioncmLabel_3 = uilabel(app.CameraOrientationPanel);
            app.PositioncmLabel_3.Text = 'Position (cm)';
            app.PositionEditFieldSlantX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantX.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSlantXValueChanged, true);
            app.PositionEditFieldSlantY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantY.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSlantYValueChanged, true);
            app.EulerAngleEditFieldSlantPitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantPitch.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSlantPitchValueChanged, true);
            app.PositioncmLabel = uilabel(app.CameraOrientationPanel);
            app.PositioncmLabel.Text = 'Position (cm)';
            app.PositionEditFieldSideX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideX.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSideXValueChanged, true);
            app.PositionEditFieldSideZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideZ.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSideZValueChanged, true);
            app.PositionEditFieldBottomY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomY.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldBottomYValueChanged, true);
            app.PositionEditFieldBottomZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomZ.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldBottomZValueChanged, true);
            app.EulerAngleEditFieldSidePitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSidePitch.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSidePitchValueChanged, true);
            app.PositionEditFieldSideY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideY.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSideYValueChanged, true);
            app.EulerAngleEditFieldSideYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSideYaw.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSideYawValueChanged, true);
            app.EulerAngledegLabel = uilabel(app.CameraOrientationPanel);
            app.EulerAngledegLabel.Text = 'Euler Angle (deg)';
            app.EulerAngleEditFieldSideRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSideRoll.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSideRollValueChanged, true);
            app.EulerAngleEditFieldSlantYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantYaw.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSlantYawValueChanged, true);
            app.PositionEditFieldSlantZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantZ.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSlantZValueChanged, true);
            app.GlassSpecificationPanel = uipanel(app.CameraSettingsTab);
            app.GlassSpecificationPanel.Title = 'Glass Specification';
            app.GlassThicknesscmEditFieldLabel = uilabel(app.GlassSpecificationPanel);
            app.GlassThicknesscmEditFieldLabel.Text = 'Glass Thickness (cm)';
            app.GlassThickness = uieditfield(app.GlassSpecificationPanel, 'numeric');
            app.GlassThickness.ValueChangedFcn = createCallbackFcn(app, @GlassThicknessValueChanged, true);
            app.SideCameratoGlasscmEditFieldLabel = uilabel(app.GlassSpecificationPanel);
            app.SideCameratoGlasscmEditFieldLabel.Text = 'Side Camera to Glass (cm)';
            app.SideCameratoGlass = uieditfield(app.GlassSpecificationPanel, 'numeric');
            app.SideCameratoGlass.ValueChangedFcn = createCallbackFcn(app, @SideCameratoGlassValueChanged, true);
            app.SlantCameratoGlasscmEditFieldLabel = uilabel(app.GlassSpecificationPanel);
            app.SlantCameratoGlasscmEditFieldLabel.Text = 'Slant Camera to Glass (cm)';
            app.SlantCameratoGlass = uieditfield(app.GlassSpecificationPanel, 'numeric');
            app.SlantCameratoGlass.ValueChangedFcn = createCallbackFcn(app, @SlantCameratoGlassValueChanged, true);
            app.BottomCameratoGlasscmEditFieldLabel = uilabel(app.GlassSpecificationPanel);
            app.BottomCameratoGlasscmEditFieldLabel.Text = 'Bottom Camera to Glass (cm)';
            app.BottomCameratoGlass = uieditfield(app.GlassSpecificationPanel, 'numeric');
            app.BottomCameratoGlass.ValueChangedFcn = createCallbackFcn(app, @BottomCameratoGlassValueChanged, true);
            app.CommunicationTab = uitab(app.TabGroup);
            app.CommunicationTab.Title = 'Communication';
            app.UDPPTPConfigurationPanel = uipanel(app.CommunicationTab);
            app.UDPPTPConfigurationPanel.Title = 'UDP/PTP Configuration';
            app.PTPOffsetEditFieldLabel = uilabel(app.UDPPTPConfigurationPanel);
            app.PTPOffsetEditFieldLabel.Text = 'PTP Offset';
            app.PTPOffsetEditField = uieditfield(app.UDPPTPConfigurationPanel, 'numeric');
            app.PTPOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @PTPOffsetEditFieldValueChanged, true);
            app.SyncIntervalsEditFieldLabel = uilabel(app.UDPPTPConfigurationPanel);
            app.SyncIntervalsEditFieldLabel.Text = 'Sync Interval (s)';
            app.SyncIntervalsEditField = uieditfield(app.UDPPTPConfigurationPanel, 'numeric');
            app.SyncIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @SyncIntervalsEditFieldValueChanged, true);
            app.Machine3Panel = uipanel(app.CommunicationTab);
            app.Machine3Panel.Title = 'Machine 3';
            app.PortNumberEditFieldLabel = uilabel(app.Machine3Panel);
            app.PortNumberEditFieldLabel.Text = 'Port Number';
            app.PortNumberEditFieldMachine3 = uieditfield(app.Machine3Panel, 'numeric');
            app.PortNumberEditFieldMachine3.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditFieldMachine3ValueChanged, true);
            app.MinDelaysLabel = uilabel(app.Machine3Panel);
            app.MinDelaysLabel.Text = 'Min Delay (s)';
            app.MinDelayEditFieldMachine3 = uieditfield(app.Machine3Panel, 'numeric');
            app.MinDelayEditFieldMachine3.ValueChangedFcn = createCallbackFcn(app, @MinDelayEditFieldMachine3ValueChanged, true);
            app.IPAddressEditFieldLabel = uilabel(app.Machine3Panel);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditFieldMachine3 = uieditfield(app.Machine3Panel, 'text');
            app.IPAddressEditFieldMachine3.ValueChangedFcn = createCallbackFcn(app, @IPAddressEditFieldMachine3ValueChanged, true);
            app.Machine4Panel = uipanel(app.CommunicationTab);
            app.Machine4Panel.Title = 'Machine 4';
            app.PortNumberEditField_2Label = uilabel(app.Machine4Panel);
            app.PortNumberEditField_2Label.Text = 'Port Number';
            app.PortNumberEditFieldMachine4 = uieditfield(app.Machine4Panel, 'numeric');
            app.PortNumberEditFieldMachine4.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditFieldMachine4ValueChanged, true);
            app.MinDelaysLabel_2 = uilabel(app.Machine4Panel);
            app.MinDelaysLabel_2.Text = 'Min Delay (s)';
            app.MinDelayEditFieldMachine4 = uieditfield(app.Machine4Panel, 'numeric');
            app.MinDelayEditFieldMachine4.ValueChangedFcn = createCallbackFcn(app, @MinDelayEditFieldMachine4ValueChanged, true);
            app.IPAddressEditField_2Label = uilabel(app.Machine4Panel);
            app.IPAddressEditField_2Label.Text = 'IP Address';
            app.IPAddressEditFieldMachine4 = uieditfield(app.Machine4Panel, 'text');
            app.IPAddressEditFieldMachine4.ValueChangedFcn = createCallbackFcn(app, @IPAddressEditFieldMachine4ValueChanged, true);
            app.Machine5Panel = uipanel(app.CommunicationTab);
            app.Machine5Panel.Title = 'Machine 5';
            app.PortNumberEditField_3Label = uilabel(app.Machine5Panel);
            app.PortNumberEditField_3Label.Text = 'Port Number';
            app.PortNumberEditFieldMachine5 = uieditfield(app.Machine5Panel, 'numeric');
            app.PortNumberEditFieldMachine5.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditFieldMachine5ValueChanged, true);
            app.MinDelaysLabel_3 = uilabel(app.Machine5Panel);
            app.MinDelaysLabel_3.Text = 'Min Delay (s)';
            app.MinDelayEditFieldMachine5 = uieditfield(app.Machine5Panel, 'numeric');
            app.MinDelayEditFieldMachine5.ValueChangedFcn = createCallbackFcn(app, @MinDelayEditFieldMachine5ValueChanged, true);
            app.IPAddressEditField_3Label = uilabel(app.Machine5Panel);
            app.IPAddressEditField_3Label.Text = 'IP Address';
            app.IPAddressEditFieldMachine5 = uieditfield(app.Machine5Panel, 'text');
            app.IPAddressEditFieldMachine5.ValueChangedFcn = createCallbackFcn(app, @IPAddressEditFieldMachine5ValueChanged, true);
            app.Machine2Panel = uipanel(app.CommunicationTab);
            app.Machine2Panel.Title = 'Machine 2';
            app.AnnounceIntervalsEditFieldLabel = uilabel(app.Machine2Panel);
            app.AnnounceIntervalsEditFieldLabel.Text = 'Announce Interval (s)';
            app.AnnounceIntervalsEditField = uieditfield(app.Machine2Panel, 'numeric');
            app.AnnounceIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @AnnounceIntervalsEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = waterChannelConfigGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaterChannelControl)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaterChannelControl)
        end
    end
end